classdef matFLUX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DBSCAN                          matlab.ui.Figure
        SettingsMenu                    matlab.ui.container.Menu
        CropDataMenu                    matlab.ui.container.Menu
        ColorsMenu                      matlab.ui.container.Menu
        CustomSizeMenu                  matlab.ui.container.Menu
        ScriptLocation                  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        Button                          matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ExclNum                         matlab.ui.control.Spinner
        EnableExclusion                 matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        FieldofViewTab                  matlab.ui.container.Tab
        MainChannelFieldofViewLabel     matlab.ui.control.Label
        ShowClustersOnlyCheckbox        matlab.ui.control.StateButton
        UIAxes2                         matlab.ui.control.UIAxes
        SingleClusterAnalysisTab        matlab.ui.container.Tab
        SingleAnalysisofClustersfromMainChannelLabel  matlab.ui.control.Label
        AlphaRadiusLabel                matlab.ui.control.Label
        AlphaRadiusSlider               matlab.ui.control.Slider
        AlphaShapeCheckBox              matlab.ui.control.CheckBox
        ShowHeatMap                     matlab.ui.control.Button
        EllipsoidFitCheckBox            matlab.ui.control.CheckBox
        ListBox                         matlab.ui.control.ListBox
        UIAxes5                         matlab.ui.control.UIAxes
        AlignmentTab                    matlab.ui.container.Tab
        RMSE_alignment                  matlab.ui.control.Label
        SelecttheParentFolderofZarrFileFoldersLabel  matlab.ui.control.Label
        ZarrLabel                       matlab.ui.control.Label
        AlignmentandCorrectionFactorLabel  matlab.ui.control.Label
        ReferenceStainingDropDown       matlab.ui.control.DropDown
        ReferenceStainingDropDownLabel  matlab.ui.control.Label
        LoadBeadDataButton              matlab.ui.control.Button
        DeviationAxes                   matlab.ui.control.UIAxes
        LocalizationAxes                matlab.ui.control.UIAxes
        FieldofViewMultiTab             matlab.ui.container.Tab
        DBSCANparametersareappliedtoallchannelsLabel  matlab.ui.control.Label
        FieldofViewforMainDataandAdditionalChannelsLabel  matlab.ui.control.Label
        LoadChannelButton               matlab.ui.control.Button
        ChannelDetailsPanel             matlab.ui.container.Panel
        ChannelNameLabel                matlab.ui.control.Label
        ChannelFileLabel                matlab.ui.control.Label
        DBSCANCheckBox                  matlab.ui.control.CheckBox
        ChannelEnableCheckbox           matlab.ui.control.CheckBox
        zLabel                          matlab.ui.control.Label
        yLabel                          matlab.ui.control.Label
        xLabel                          matlab.ui.control.Label
        XCorrectionField                matlab.ui.control.NumericEditField
        YCorrectionField                matlab.ui.control.NumericEditField
        ZCorrectionField                matlab.ui.control.NumericEditField
        ChannelListBox                  matlab.ui.control.ListBox
        ExportasmattifButton            matlab.ui.control.Button
        UIAxes7                         matlab.ui.control.UIAxes
        ClusterAnalysisMultiTab         matlab.ui.container.Tab
        ChannelsonoffLabel              matlab.ui.control.Label
        AnalyzesingleclustersinmultiplecolorsLabel  matlab.ui.control.Label
        SingleClusterAnalysisMultiChannelLabel  matlab.ui.control.Label
        Ch5CheckBox                     matlab.ui.control.CheckBox
        Ch4CheckBox                     matlab.ui.control.CheckBox
        Ch3CheckBox                     matlab.ui.control.CheckBox
        Ch2CheckBox                     matlab.ui.control.CheckBox
        AlphaRadiusLabel_2              matlab.ui.control.Label
        AlphaRadiusSlider_2             matlab.ui.control.Slider
        EllipsoidFitCheckBox_2          matlab.ui.control.CheckBox
        AlphaShapeCheckBox_2            matlab.ui.control.CheckBox
        ListBox_2                       matlab.ui.control.ListBox
        UIAxes5_2                       matlab.ui.control.UIAxes
        ConcentricalAnalysisTab         matlab.ui.container.Tab
        HistogramofMainDataandLineProfilesfromaddChannelsLabel  matlab.ui.control.Label
        ConcentricalAnalysisLabel       matlab.ui.control.Label
        NormalizeIndividuallyCheckBox   matlab.ui.control.CheckBox
        ListBox2                        matlab.ui.control.ListBox
        ButtonGroup1                    matlab.ui.container.ButtonGroup
        trcButton1                      matlab.ui.control.ToggleButton
        locButton1                      matlab.ui.control.ToggleButton
        MeanDistLabel                   matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        PolarPlotTab                    matlab.ui.container.Tab
        PolarPlotofMainDataandAdditionalChannelsLabel  matlab.ui.control.Label
        MeanDistLabel2                  matlab.ui.control.Label
        PolarPlotLabel                  matlab.ui.control.Label
        ColorCheck                      matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        ListBox2_2                      matlab.ui.control.ListBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        trcButton                       matlab.ui.control.ToggleButton
        locButton                       matlab.ui.control.ToggleButton
        NumbersTab                      matlab.ui.container.Tab
        SingleClustersinNumbersLabel    matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        NumbersmultiTab                 matlab.ui.container.Tab
        SingleClustersinNumbersmultichannelLabel  matlab.ui.control.Label
        SaveNumMultCSVButton            matlab.ui.control.Button
        UITableMulti                    matlab.ui.control.Table
        AcqParametersTab                matlab.ui.container.Tab
        MINFLUXAcquisitionParametersLabel  matlab.ui.control.Label
        LocalizationsovertimeLabel      matlab.ui.control.Label
        LocalizationsperTraceLabel      matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        LocalizationPrecisionTab        matlab.ui.container.Tab
        LocalizationPrecisionofLoadedDataLabel  matlab.ui.control.Label
        SaveLocPrecPDFButton            matlab.ui.control.Button
        FileNameLabel1                  matlab.ui.control.Label
        MinPtsSpinner                   matlab.ui.control.Spinner
        MinPtsLabel                     matlab.ui.control.Label
        EpsilonSpinner                  matlab.ui.control.Spinner
        EpsilonLabel                    matlab.ui.control.Label
        LoadNewButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        LoadedData1
        Data1
        Clusters1
        IsNoise1
        filtered_data
        filename %
        Minflux
        ClusterColors
        LoadedData2
        Data2
        LoadedData3
        Data3
        epsilon
        minPts
        ClusterVolumes
        ClusterSurfaces
        allCenteredPoints
        Data2_off
        Data3_off
        XLimit_UIA2 double
        YLimit_UIA2 double
        ZLimit_UIA2 double
        PRM_traces_filt
        Pax
        Clusters2
        IsNoise2
        Clusters3
        IsNoise3
        Data1_off
        Data4
        Data4_off
        LoadedData4
        Data5
        Data5_off
        LoadedData5
        IsNoise4
        IsNoise5
        Clusters4
        Clusters5
        Zarr % Description
        BeadAlignmentData
        validatedBeads
        tid1
        tim1
        tid2
        tim2
        tid3
        tim3
        tid4
        tim4
        tid5
        tim5
        trace_data1
        trace_data2
        trace_data3
        trace_data4
        trace_data5
        trace_clusters1
        trace_clusters2
        trace_clusters3
        trace_clusters4
        trace_clusters5
        ClusterRDFs % Description
        ClusterRadii % Description
        filename1
        filename2
        filename3
        filename4
        filename5
        filepath1
        filepath2
        filepath3
        filepath4
        filepath5
        figureHandle3D  % Handle for 3D heatmap figure
        figureHandle2D
        AveragedParticleData
        ChannelCorrections
        ChannelFileNames
        ChannelEnableStates
        DBSCANCache struct = struct('Channel_1', struct(), 'Channel_2', struct(), 'Channel_3', struct(), 'Channel_4', struct(), 'Channel_5', struct())
        GlobalXLim
        GlobalYLim
        GlobalZLim
        GlobalXTicks
        GlobalYTicks
        GlobalZTicks
        ChannelColors cell = {'k', 'cyan', 'red', 'magenta', 'green'}
        ChannelColorButtons
        BoxSizeX = 500;
        BoxSizeY = 500;
        BoxSizeZ = 500;
        UseCustomColors logical = false
        CorrectionData
        currentcolormap = 'parula';
        ShowWelcomeMessage (1,1) logical = true;
        CustomZarrScriptPath
        CentroidDistances
        CrossChannelCentroidAnalysis
        ColoringMode char = 'Default'           % Current coloring mode: 'Default', 'Z-Position', 'Localizations', 'Traces'
        ZColors double                          % RGB colors for Z-position based coloring (Nx3 matrix)
        LocalizationColors double               % RGB colors for localization count based coloring (Nx3 matrix)
        TraceColors double                      % RGB colors for trace count based coloring (Nx3 matrix)
        ColoringModeIndex double = 1
        CurrentColoringType char = 'Default'
        ZMin
        ZMax
        TrcMin
        TrcMax
        LocMin
        LocMax
        CorrectionSummary
        CropEnabled logical = false
        CropLimits struct = struct('XMin', -inf, 'XMax', inf, 'YMin', -inf, 'YMax', inf, 'ZMin', -inf, 'ZMax', inf)
        OriginalData1  % Store original uncropped data
        OriginalData2
        OriginalData3
        OriginalData4
        OriginalData5
        Originaltid1   % Store original trace IDs
        Originaltid2
        Originaltid3
        Originaltid4
        Originaltid5
        Originaltim1   % Store original timestamps
        Originaltim2
        Originaltim3
        Originaltim4
        Originaltim5
        MaxAnalysisDistance = 120;
    end

    methods (Access = private)

        % matFLUX 1.0 — MINFLUX Data Analysis App
        % -----------------------------------------------------------------------------
        % Description:
        %   Interactive MATLAB App for processing MINFLUX localization data.
        %   Key features include:
        %     • DBSCAN clustering with parameter caching
        %     • Single‐cluster and multi‐cluster 3D visualization (scatter, alpha‐shape, ellipsoid)
        %     • Multi‐channel alignment via bead‐based drift correction (Zarr support)
        %     • Heatmap, radial profile, and polar analyses from cluster centroids
        %     • Localization precision estimation and acquisition‐parameter QC
        %     • Export of corrected localizations (.mat) and multi‐channel TIFF volumes
        %     • New and Old data structure
        %
        % Requirements:
        %   • MATLAB R2021a or later
        %   • Statistics and Machine Learning Toolbox (for DBSCAN)
        %   • Python 3 with numpy, scipy and zarr (optional, for Zarr data extraction)%
        %   • extract_zarr.py script
        %   • camera.png icon
        %
        %
        % Author:
        %   Moritz Hacke
        %
        % Version:
        %   1.0  (24 April 2025)
        %
        % Usage:
        %   >> app = matFLUX;
        %   Launches the app; follow on‐screen instructions or see the Help menu.
        %

        function loadDataset(app, datasetNumber)
            [file, path] = uigetfile('*.mat', 'Select a MATLAB data file');
            if isequal(file, 0)
                return;
            end

            fullPath = fullfile(path, file);
            [~, filename, ~] = fileparts(file);

            if isempty(app.(['filename', num2str(datasetNumber)]))
                app.(['filename', num2str(datasetNumber)]) = filename;
            end
            app.(['filepath', num2str(datasetNumber)]) = path;  % Store the file path

            loadedData = load(fullPath);
            app.(['LoadedData', num2str(datasetNumber)]) = loadedData;

            % Detect data structure format and extract data accordingly
            [data, tid, tim, isNewFormat] = app.extractDataFromStructure(loadedData);

            if isempty(data)
                errordlg(['The loaded file does not contain the expected data structure. Please check the file: ', file], 'Data Structure Error');
                return;
            end

            % Apply coordinate transformations
            data(:,2) = 1 * (data(:,2));
            data(:,3) = 1 * (data(:,3) * 0.7);  % Apply z-correction factor

            app.(['Data', num2str(datasetNumber)]) = data;

            % Store tid and tim data
            app.(['tid', num2str(datasetNumber)]) = tid;
            app.(['tim', num2str(datasetNumber)]) = tim;
            if app.CropEnabled
                % Store original data
                app.(['OriginalData', num2str(datasetNumber)]) = data;
                app.(['Originaltid', num2str(datasetNumber)]) = tid;
                app.(['Originaltim', num2str(datasetNumber)]) = tim;

                % Apply cropping
                app.cropSingleDataset(datasetNumber);
            end
            % Apply DBSCAN if checkbox is checked (for datasets 2-5)
            if app.DBSCANCheckBox.Value && datasetNumber > 1
                app.(['Clusters', num2str(datasetNumber)]) = applyDBSCAN(app, data, datasetNumber);
                app.(['IsNoise', num2str(datasetNumber)]) = (app.(['Clusters', num2str(datasetNumber)]) == -1);
            end

            channelName = ['Channel_', num2str(datasetNumber)];
            displayChannelName = ['Channel ', num2str(datasetNumber)];

            % Store filename
            app.ChannelFileNames.(channelName) = filename;

            % Initialize correction values for the new channel
            if ~isfield(app.ChannelCorrections, channelName)
                app.ChannelCorrections.(channelName) = struct('X', 0, 'Y', 0, 'Z', 0);
            end

            % Set the new channel to be enabled by default
            app.ChannelEnableStates.(channelName) = true;

            % Update the ChannelListBox
            if ~ismember(displayChannelName, app.ChannelListBox.Items)
                app.ChannelListBox.Items{end+1} = displayChannelName;
            end

            % Update the ChannelListBox
            updateChannelListBox(app);

            % Select the newly added channel
            app.ChannelListBox.Value = ['Channel ', num2str(datasetNumber)];

            % Update channel details
            updateChannelDetails(app, app.ChannelListBox.Value);

            % Update plots
            if datasetNumber == 1
                updatePlots(app);
            else
                updatePlots2(app);
            end

            % Display format information
            if isNewFormat
                disp(['Loaded new MINFLUX data format from: ', filename]);
            else
                disp(['Loaded legacy MINFLUX data format from: ', filename]);
            end
        end

        function [data, tid, tim, isNewFormat] = extractDataFromStructure(app, loadedData)
            % Initialize outputs
            data = [];
            tid = [];
            tim = [];
            isNewFormat = false;

            % Check for new data structure (direct fields)
            if isfield(loadedData, 'loc') && isfield(loadedData, 'tid') && isfield(loadedData, 'tim')
                % NEW DATA STRUCTURE
                isNewFormat = true;

                % Extract localization data - should be 3xN, convert to Nx3
                if size(loadedData.loc, 1) == 3
                    data = loadedData.loc' * 1e9;  % Convert to nm and transpose
                elseif size(loadedData.loc, 2) == 3
                    data = loadedData.loc * 1e9;   % Convert to nm
                else
                    warning('Unexpected loc field dimensions in new format');
                    return;
                end

                % Extract trace ID
                tid = loadedData.tid;
                if size(tid, 1) > size(tid, 2)
                    tid = tid';  % Ensure row vector
                end

                % Extract time data (already converted from FPGA ticks)
                tim = loadedData.tim;
                if size(tim, 1) > size(tim, 2)
                    tim = tim';  % Ensure row vector
                end

                disp('Detected new MINFLUX data format');

                % Check for old data structure (nested under itr)
            elseif isfield(loadedData, 'itr') && isfield(loadedData.itr, 'loc')
                % OLD DATA STRUCTURE
                isNewFormat = false;

                % Extract localization data from nested structure
                itr = loadedData.itr;
                if size(itr.loc, 2) >= 10 && size(itr.loc, 3) >= 3
                    data = itr.loc(:,10,:) * 1e9;  % Convert to nm
                    data = reshape(data, [], 3);   % Reshape to Nx3
                else
                    warning('Unexpected itr.loc field dimensions in old format');
                    return;
                end

                % Extract trace ID
                if isfield(loadedData, 'tid')
                    tid = loadedData.tid;
                else
                    warning('tid field not found in old format');
                    tid = [];
                end

                % Extract time data
                if isfield(loadedData, 'tim')
                    tim = loadedData.tim;
                elseif isfield(loadedData, 'tic')
                    % Convert from FPGA ticks to time (tic / 40 MHz)
                    tim = double(loadedData.tic) / 40e6;
                    disp('Converted tic field to tim (tic / 40 MHz)');
                else
                    warning('Neither tim nor tic field found in old format');
                    tim = [];
                end

                % Ensure vectors are the right orientation
                if ~isempty(tid) && size(tid, 1) > size(tid, 2)
                    tid = tid';
                end
                if ~isempty(tim) && size(tim, 1) > size(tim, 2)
                    tim = tim';
                end

                disp('Detected legacy MINFLUX data format');

            else
                % UNRECOGNIZED STRUCTURE
                warning('Data structure not recognized - neither new nor old MINFLUX format');

                % Try to provide helpful diagnostic information
                if isfield(loadedData, 'itr')
                    fprintf('Found itr field, but missing itr.loc\n');
                    if isstruct(loadedData.itr)
                        fprintf('itr subfields: %s\n', strjoin(fieldnames(loadedData.itr), ', '));
                    end
                end

                fprintf('Available top-level fields: %s\n', strjoin(fieldnames(loadedData), ', '));
                return;
            end

            % Validate extracted data
            if isempty(data) || size(data, 2) ~= 3
                warning('Failed to extract valid localization data');
                data = [];
                return;
            end

            % Ensure all arrays have compatible lengths
            numLocalizations = size(data, 1);

            if ~isempty(tid) && length(tid) ~= numLocalizations
                warning('tid length (%d) does not match localization count (%d)', length(tid), numLocalizations);
                if length(tid) > numLocalizations
                    tid = tid(1:numLocalizations);
                else
                    tid = [tid, zeros(1, numLocalizations - length(tid))];
                end
            end

            if ~isempty(tim) && length(tim) ~= numLocalizations
                warning('tim length (%d) does not match localization count (%d)', length(tim), numLocalizations);
                if length(tim) > numLocalizations
                    tim = tim(1:numLocalizations);
                else
                    tim = [tim, zeros(1, numLocalizations - length(tim))];
                end
            end

            fprintf('Successfully extracted %d localizations\n', numLocalizations);
        end

        function clusters = applyDBSCAN(app, data, channelNumber)
            if nargin < 3
                channelNumber = 1;
            end

            epsilon = app.EpsilonSpinner.Value;
            minPts = app.MinPtsSpinner.Value;

            % IMPROVEMENT 1: Use simple numerical hash instead of string operations
            % OLD: cacheKey = sprintf('e%.2f_m%d_s%d', epsilon, minPts, size(data, 1));
            dataHash = mod(sum(data(:)) + size(data,1), 1e6); % Simple hash
            cacheKey = [epsilon*1000, minPts*100, dataHash]; % Numerical array key

            channelKey = ['Channel_', num2str(channelNumber)];

            % IMPROVEMENT 2: Check if cache exists and is current
            if isfield(app.DBSCANCache, channelKey)
                cache = app.DBSCANCache.(channelKey);

                % Check if we have this exact configuration cached
                if isfield(cache, 'lastKey') && isequal(cache.lastKey, cacheKey)
                    clusters = cache.lastResult;
                    return; % EARLY EXIT - No computation needed!
                end
            end

            % IMPROVEMENT 3: Only compute if not cached
            disp(['Computing DBSCAN for ', channelKey, ' (eps=', num2str(epsilon), ', minPts=', num2str(minPts), ')']);

            % Show progress for large datasets
            if size(data, 1) > 1000
                d = uiprogressdlg(app.DBSCAN, 'Title', 'Applying DBSCAN', ...
                    'Message', ['Processing ', channelKey, '...'], 'Indeterminate', 'on');
            end

            try
                clusters = dbscan(data, epsilon, minPts);

                % IMPROVEMENT 4: Cache the result efficiently
                if ~isfield(app.DBSCANCache, channelKey)
                    app.DBSCANCache.(channelKey) = struct();
                end

                % Store with simple structure
                app.DBSCANCache.(channelKey).lastKey = cacheKey;
                app.DBSCANCache.(channelKey).lastResult = clusters;
                app.DBSCANCache.(channelKey).timestamp = now();

            catch ME
                if exist('d', 'var'), close(d); end
                rethrow(ME);
            end

            if exist('d', 'var'), close(d); end
        end

        function clearDBSCANCache(app, channelNumber)
            channelKey = ['Channel_', num2str(channelNumber)];
            app.DBSCANCache.(channelKey) = struct();
            disp(['DBSCAN cache cleared for ', channelKey]);
        end


        function updateChannelListBox(app)
            % Get all unique channel names
            channelNames = unique(cellfun(@(x) ['Channel ', num2str(x)], num2cell(1:length(fieldnames(app.ChannelFileNames))), 'UniformOutput', false));

            % Add 'Add Channel' option at the end
            channelNames{end+1} = 'Add Channel';

            % Update the ChannelListBox
            app.ChannelListBox.Items = channelNames;
        end

        function updatePlots(app)
            if isempty(app.Data1)
                return; % EARLY EXIT
            end

            % Cache DBSCAN result - this is the key performance gain
            app.Clusters1 = applyDBSCAN(app, app.Data1, 1);
            app.IsNoise1 = (app.Clusters1 == -1);

            % Pre-compute valid clusters once
            valid_clusters = unique(app.Clusters1(app.Clusters1 ~= -1));

            if isempty(valid_clusters)
                % Handle empty case efficiently
                cla(app.UIAxes); cla(app.UIAxes2);
                app.ListBox.Items = {}; app.ListBox_2.Items = {};
                app.ListBox2.Items = {}; app.ListBox2_2.Items = {};
                return;
            end

            % Apply exclusion filter once
            if app.EnableExclusion.Value
                exclusion_threshold = app.ExclNum.Value;
                % Vectorized operation instead of arrayfun
                cluster_counts = histcounts(app.Clusters1, [valid_clusters; max(valid_clusters)+1]);
                clusters_above_threshold = valid_clusters(cluster_counts >= exclusion_threshold);
            else
                clusters_above_threshold = valid_clusters;
            end

            if isempty(clusters_above_threshold)
                cla(app.UIAxes); cla(app.UIAxes2);
                app.ListBox.Items = {}; app.ListBox_2.Items = {};
                app.ListBox2.Items = {}; app.ListBox2_2.Items = {};
                return;
            end

            % Update UI elements once
            updateUIElements(app, clusters_above_threshold);

            % Generate colors once
            num_clusters = length(clusters_above_threshold);
            cmap = feval(app.currentcolormap, num_clusters);

            % Pre-allocate cluster colors
            cluster_colors = zeros(max(app.Clusters1), 3);
            for k = 1:num_clusters
                if clusters_above_threshold(k) > 0
                    cluster_colors(clusters_above_threshold(k), :) = cmap(k, :);
                end
            end
            app.ClusterColors = cluster_colors;

            % Update plots with pre-computed data
            updateBarPlot(app, app.Clusters1, valid_clusters, clusters_above_threshold, cmap);
            updateScatterPlot(app, app.Clusters1, clusters_above_threshold, cmap);
        end

        function updateScatterPlot(app, idx, clusters_above_threshold, cmap)
            colormap(app.UIAxes2, cmap);

            % Calculate limits based on the full dataset
            maxData = max(app.Data1);
            minData = min(app.Data1);
            padding = 0.1 * (maxData - minData);
            app.XLimit_UIA2 = [minData(1) - padding(1), maxData(1) + padding(1)];
            app.YLimit_UIA2 = [minData(2) - padding(2), maxData(2) + padding(2)];
            app.ZLimit_UIA2 = [minData(3) - padding(3), maxData(3) + padding(3)];

            % Calculate offsets for x and y
            x_offset = minData(1);
            y_offset = minData(2);

            if app.ShowClustersOnlyCheckbox.Value
                non_noise_indices = idx ~= -1 & ismember(idx, clusters_above_threshold);
                app.filtered_data = app.Data1(non_noise_indices, :);
                filtered_idx = idx(non_noise_indices);
            else
                app.filtered_data = app.Data1;
                filtered_idx = idx;
            end

            % Adjust the data
            adjusted_data = app.filtered_data;
            adjusted_data(:, 1) = adjusted_data(:, 1) - x_offset;
            adjusted_data(:, 2) = adjusted_data(:, 2) - y_offset;

            % ENHANCED COLOR HANDLING
            if app.UseCustomColors
                % Check what type of custom coloring is active
                coloringMode = getColoringMode(app);

                switch coloringMode
                    case 'Z-Position'
                        if ~isempty(app.ZColors) && length(app.ZColors) == size(app.Data1, 1)
                            % Use Z-position colors, filtered to match the displayed data
                            if app.ShowClustersOnlyCheckbox.Value
                                scatter_color = app.ZColors(non_noise_indices);  % These are colormap indices
                            else
                                scatter_color = app.ZColors;  % These are colormap indices
                            end
                        else
                            % Fallback: create Z-colors on the fly
                            zCoords = app.filtered_data(:, 3);
                            if max(zCoords) > min(zCoords)
                                normalizedZ = (zCoords - min(zCoords)) / (max(zCoords) - min(zCoords));
                            else
                                normalizedZ = ones(size(zCoords));
                            end
                            scatter_color = normalizedZ;  % Use normalized values as colormap indices
                        end

                    case 'Localizations'
                        % Create RGB colors for localizations
                        scatter_color = createLocalizationColors(app, filtered_idx, clusters_above_threshold, cmap);

                    case 'Traces'
                        % Create RGB colors for traces
                        scatter_color = createTraceColors(app, filtered_idx, clusters_above_threshold, cmap);

                    otherwise % 'Default' or custom channel colors
                        scatter_color = app.getColor(app.ChannelColors{1});
                        scatter_color = repmat(scatter_color, size(adjusted_data, 1), 1);
                end
            else
                % Use standard cluster-based coloring
                scatter_colors = zeros(size(filtered_idx, 1), 3);
                for i = 1:length(clusters_above_threshold)
                    scatter_colors(filtered_idx == clusters_above_threshold(i), :) = repmat(cmap(i, :), sum(filtered_idx == clusters_above_threshold(i)), 1);
                end
                scatter_colors(~ismember(filtered_idx, clusters_above_threshold), :) = repmat([0.7, 0.7, 0.7], sum(~ismember(filtered_idx, clusters_above_threshold)), 1);
                scatter_color = scatter_colors;
            end

            % Plot with appropriate coloring
            if strcmp(getColoringMode(app), 'Z-Position') && isnumeric(scatter_color) && size(scatter_color, 2) == 1
                % scatter_color contains normalized Z values (0-1) - use as colormap indices
                scatter3(app.UIAxes2, adjusted_data(:, 1), adjusted_data(:, 2), adjusted_data(:, 3), 20, scatter_color, 'filled');
                % Set color limits to ensure proper colormap mapping
                clim(app.UIAxes2, [0, 1]);
            else
                % For other coloring modes, use RGB colors directly
                scatter3(app.UIAxes2, adjusted_data(:, 1), adjusted_data(:, 2), adjusted_data(:, 3), 20, scatter_color, 'filled');
            end

            axis(app.UIAxes2, 'equal');

            % Set consistent limits
            xlim(app.UIAxes2, [0, app.XLimit_UIA2(2) - x_offset]);
            ylim(app.UIAxes2, [0, app.YLimit_UIA2(2) - y_offset]);
            zlim(app.UIAxes2, app.ZLimit_UIA2);

            xlabel(app.UIAxes2, 'X (nm)', 'FontSize', 16, 'FontWeight','bold');
            ylabel(app.UIAxes2, 'Y (nm)', 'FontSize', 16, 'FontWeight','bold');
            zlabel(app.UIAxes2, 'Z (nm)', 'FontSize', 16, 'FontWeight','bold');
            app.UIAxes2.XAxis.FontSize = 16;
            app.UIAxes2.YAxis.FontSize = 16;
            app.UIAxes2.ZAxis.FontSize = 16;
            title(app.UIAxes2, '', 'FontSize', 12, 'FontWeight', 'bold');

            % Set tick marks to start from 0 with 500 nm spacing
            xticks(app.UIAxes2, 0:500:ceil(app.XLimit_UIA2(2) - x_offset));
            yticks(app.UIAxes2, 0:500:ceil(app.YLimit_UIA2(2) - y_offset));

            grid(app.UIAxes2, "on");
        end

        function colors = createLocalizationColors(app, filtered_idx, clusters_above_threshold, cmap)
            % Create colors based on localization count per cluster
            colors = zeros(length(filtered_idx), 3);

            % Count localizations per cluster
            locCounts = arrayfun(@(x) sum(app.Clusters1 == x), clusters_above_threshold);

            if ~isempty(locCounts) && max(locCounts) > min(locCounts)
                % Normalize counts to [0,1] range
                normalizedCounts = (locCounts - min(locCounts)) / (max(locCounts) - min(locCounts));

                % Get the current colormap
                currentColormap = feval(app.currentcolormap, 256);

                % Assign colors based on normalized counts
                for i = 1:length(clusters_above_threshold)
                    clusterIdx = clusters_above_threshold(i);
                    pointIndices = filtered_idx == clusterIdx;

                    % Map normalized count to colormap index
                    colormapIndex = round(normalizedCounts(i) * 255) + 1;
                    colormapIndex = max(1, min(256, colormapIndex));

                    colors(pointIndices, :) = repmat(currentColormap(colormapIndex, :), sum(pointIndices), 1);
                end
            else
                % If all clusters have same count, use first color
                currentColormap = feval(app.currentcolormap, 256);
                for i = 1:length(clusters_above_threshold)
                    clusterIdx = clusters_above_threshold(i);
                    pointIndices = filtered_idx == clusterIdx;
                    colors(pointIndices, :) = repmat(currentColormap(1, :), sum(pointIndices), 1);
                end
            end

            % Handle noise points (gray)
            noiseIndices = ~ismember(filtered_idx, clusters_above_threshold);
            colors(noiseIndices, :) = repmat([0.7, 0.7, 0.7], sum(noiseIndices), 1);
        end
        function coloringMode = getColoringMode(app)
            try
                % Try multiple storage locations in order of preference
                if isprop(app, 'ColoringMode') && ~isempty(app.ColoringMode)
                    coloringMode = app.ColoringMode;
                elseif ~isempty(app.DBSCAN.UserData) && isfield(app.DBSCAN.UserData, 'ColoringMode') && ~isempty(app.DBSCAN.UserData.ColoringMode)
                    coloringMode = app.DBSCAN.UserData.ColoringMode;
                elseif isprop(app, 'CurrentColoringType') && ~isempty(app.CurrentColoringType)
                    coloringMode = app.CurrentColoringType;
                elseif ~isempty(app.DBSCAN.UserData) && isfield(app.DBSCAN.UserData, 'CurrentColoringType') && ~isempty(app.DBSCAN.UserData.CurrentColoringType)
                    coloringMode = app.DBSCAN.UserData.CurrentColoringType;
                else
                    % Check if custom colors are being used to infer the mode
                    if app.UseCustomColors
                        % Try to infer from the data that's present
                        if ~isempty(app.ZColors) && length(app.ZColors) == size(app.Data1, 1)
                            coloringMode = 'Z-Position';
                        elseif ~isempty(app.LocalizationColors) && length(app.LocalizationColors) == size(app.Data1, 1)
                            coloringMode = 'Localizations';
                        elseif ~isempty(app.TraceColors) && length(app.TraceColors) == size(app.Data1, 1)
                            coloringMode = 'Traces';
                        else
                            coloringMode = 'Default';
                        end
                    else
                        coloringMode = 'Default';
                    end
                end
            catch
                coloringMode = 'Default';
            end

            % Ensure the mode is valid
            validModes = {'Default', 'Z-Position', 'Localizations', 'Traces'};
            if ~ismember(coloringMode, validModes)
                coloringMode = 'Default';
            end
        end
        function colors = createTraceColors(app, filtered_idx, clusters_above_threshold, cmap)
            % Create colors based on trace count per cluster
            colors = zeros(length(filtered_idx), 3);

            % Ensure trace data is available
            if isempty(app.trace_clusters1)
                app.calculateTraceData();
            end

            if ~isempty(app.trace_clusters1)
                % Count traces per cluster
                traceCounts = arrayfun(@(x) sum(app.trace_clusters1 == x), clusters_above_threshold);

                if ~isempty(traceCounts) && max(traceCounts) > min(traceCounts)
                    % Normalize counts to [0,1] range
                    normalizedCounts = (traceCounts - min(traceCounts)) / (max(traceCounts) - min(traceCounts));

                    % Get the current colormap
                    currentColormap = feval(app.currentcolormap, 256);

                    % Assign colors based on normalized counts
                    for i = 1:length(clusters_above_threshold)
                        clusterIdx = clusters_above_threshold(i);
                        pointIndices = filtered_idx == clusterIdx;

                        % Map normalized count to colormap index
                        colormapIndex = round(normalizedCounts(i) * 255) + 1;
                        colormapIndex = max(1, min(256, colormapIndex));

                        colors(pointIndices, :) = repmat(currentColormap(colormapIndex, :), sum(pointIndices), 1);
                    end
                else
                    % If all clusters have same count, use first color
                    currentColormap = feval(app.currentcolormap, 256);
                    for i = 1:length(clusters_above_threshold)
                        clusterIdx = clusters_above_threshold(i);
                        pointIndices = filtered_idx == clusterIdx;
                        colors(pointIndices, :) = repmat(currentColormap(1, :), sum(pointIndices), 1);
                    end
                end
            end

            % Handle noise points (gray)
            noiseIndices = ~ismember(filtered_idx, clusters_above_threshold);
            colors(noiseIndices, :) = repmat([0.7, 0.7, 0.7], sum(noiseIndices), 1);
        end

        function updateUIElements(app, clusters_above_threshold)
            cluster_items = cellstr(num2str(clusters_above_threshold));
            app.ListBox.Items = cluster_items;
            app.ListBox_2.Items = cluster_items;

            app.ListBox2.Items = cluster_items;
            app.ListBox2_2.Items = cluster_items;

            if ~isempty(cluster_items)
                app.ListBox.Value = cluster_items{1};
                app.ListBox_2.Value = cluster_items{1};
                app.ListBox2.Value = cluster_items{1};
                app.ListBox2_2.Value = cluster_items{1};
            end
        end

        function updateBarPlot(app, idx, valid_clusters, clusters_above_threshold, cmap)
            counts = histcounts(idx(idx ~= -1), [valid_clusters; max(valid_clusters) + 1]);
            bar_handle = bar(app.UIAxes, valid_clusters, counts, 'FaceColor', 'flat');
            title(app.UIAxes, 'Points per Cluster');

            % If any non-Default coloring mode is active, draw bars in black
            if ~strcmp(getColoringMode(app), 'Default')
                % black for included clusters, gray for excluded
                for k = 1:length(valid_clusters)
                    if ismember(valid_clusters(k), clusters_above_threshold)
                        bar_handle.CData(k, :) = [0, 0, 0];       % black
                    else
                        bar_handle.CData(k, :) = [0.7, 0.7, 0.7]; % gray
                    end
                end
                return;  % stop here so no colormap-based coloring is applied
            end

            % Default mode: keep existing colormap-based coloring
            for k = 1:length(valid_clusters)
                if ismember(valid_clusters(k), clusters_above_threshold)
                    bar_handle.CData(k, :) = cmap(find(clusters_above_threshold == valid_clusters(k), 1), :);
                else
                    bar_handle.CData(k, :) = [0.7, 0.7, 0.7];
                end
            end
        end


        function [sigmaX, sigmaY, sigmaZ, sigmaR, ste_r, ste_z] = calculateLocalizationPrecision(app, data, tid, tim)
            [~, ~, uid] = unique(tid, 'stable');
            c.n = accumarray(uid, 1);
            N = numel(c.n);
            c.t = accumarray(uid, tim) ./ c.n;
            c.pos = zeros(N, 3);
            c.std_xyz = zeros(N, 3);
            for i = 1:3
                c.pos(:, i) = accumarray(uid, data(:, i)) ./ c.n;
                c.std_xyz(:, i) = accumarray(uid, data(:, i), [], @std);
            end

            % Filter points observed more than once
            s = c.std_xyz(c.n > 1, :);
            n = c.n(c.n > 1);

            % Calculate combined standard deviation for the radial component
            sr = sqrt((s(:, 1).^2 + s(:, 2).^2)/2);

            % Calculate standard error for the radial component
            ste_r = sr ./ sqrt(n);

            % Calculate standard error for the z component
            ste_z = s(:, 3) ./ sqrt(n);

            % Assign output variables
            sigmaX = s(:, 1);
            sigmaY = s(:, 2);
            sigmaZ = s(:, 3);
            sigmaR = sr;

            % Store the results in the app property
            c.ste_rz = [ste_r, ste_z];
            app.Minflux.combined = c;
            app.Minflux.combined.n = n;
        end

        function updateLocalizationPrecisionTab(app)
            % Clear existing axes
            delete(findobj(app.LocalizationPrecisionTab, 'Type', 'UIAxes'));

            % Determine which stainings are loaded
            loadedStainings = {'Data1'};
            if ~isempty(app.Data2), loadedStainings{end+1} = 'Data2'; end
            if ~isempty(app.Data3), loadedStainings{end+1} = 'Data3'; end
            if ~isempty(app.Data4), loadedStainings{end+1} = 'Data4'; end
            if ~isempty(app.Data5), loadedStainings{end+1} = 'Data5'; end

            numStainings = length(loadedStainings);

            % Set up common parameters
            T = 5; % Minimum number of localizations
            g = 0:1:15; % Limit of x-range for histogram
            labels = {'x', 'y', 'z', 'r'};

            % Fixed size for each plot
            plotWidth = 125;
            plotHeight = 125;

            % Spacing between plots
            hSpacing = 5;
            vSpacing = 10;

            % Font sizes
            titleFontSize = 7;
            labelFontSize = 6;
            tickFontSize = 6;

            % Calculate content size
            contentWidth = 4 * (plotWidth + hSpacing) + hSpacing;
            contentHeight = max(numStainings * (plotHeight + vSpacing) + vSpacing + 50, app.LocalizationPrecisionTab.Position(4));

            % Create a uipanel to hold all the plots, with no visible border
            contentPanel = uipanel(app.LocalizationPrecisionTab, ...
                'Position', [1 1 contentWidth contentHeight], ...
                'Units', 'pixels', ...
                'BorderType', 'none', ...
                'BackgroundColor', app.LocalizationPrecisionTab.BackgroundColor);

            % Position the Save PNG button at the top of the content panel
            % Preserve the original size of the button
            originalButtonPosition = app.SaveLocPrecPDFButton.Position;
            app.SaveLocPrecPDFButton.Parent = contentPanel;
            app.SaveLocPrecPDFButton.Position = [20, contentHeight - originalButtonPosition(4) - 10, originalButtonPosition(3), originalButtonPosition(4)];

            % Start position for plots (below the button)
            yStartPosition = contentHeight - originalButtonPosition(4) - 20;

            for i = 1:numStainings
                staining = app.(loadedStainings{i});

                % Get the correct tid and tim for this dataset
                if i == 1
                    tid = app.tid1;
                    tim = app.tim1;
                    labelField = 'FileNameLabel1';
                else
                    tid = app.(['tid', num2str(i)]);
                    tim = app.(['tim', num2str(i)]);
                    %labelField = ['FileNameLabel', num2str(i)];
                end

                % Get the appropriate name for the plot
                labelText = app.(labelField).Text;
                if startsWith(labelText, 'File:')
                    [~, plotName, ~] = fileparts(labelText(7:end));
                elseif contains(labelText, '(') && contains(labelText, ')')
                    plotName = extractBetween(labelText, '(', ')');
                    plotName = plotName{1};
                else
                    plotName = labelText;
                end

                % Calculate localization precision
                [sigmaX, sigmaY, sigmaZ, sigmaR, ~, ~] = app.calculateLocalizationPrecision(staining, tid, tim);

                sigmas = [sigmaX, sigmaY, sigmaZ, sigmaR];
                n = app.Minflux.combined.n;
                validIndices = n >= T;
                validIndices = validIndices(1:size(sigmas,1));
                sigmas = sigmas(validIndices, :);

                med = median(sigmas, 1);

                % Create axes and plot
                for j = 1:4
                    xPos = (j-1) * (plotWidth + hSpacing) + hSpacing;
                    yPos = yStartPosition - i * (plotHeight + vSpacing);

                    ax = uiaxes(contentPanel, 'Position', [xPos, yPos, plotWidth, plotHeight], 'Units', 'pixels');

                    h = histogram(ax, sigmas(:, j), g, 'facecolor', 'none', 'Edgecolor', 'k');
                    hold(ax, 'on');

                    ylim(ax, [0, max(h.Values) * 1.1]);
                    plot(ax, med(j) * [1, 1], ylim(ax), 'LineWidth', 1, 'Color', 'red');
                    xlim(ax, g([1, end]));

                    xlabel(ax, ['\sigma_', labels{j}, ' (nm)'], 'FontSize', labelFontSize);
                    ylabel(ax, 'Counts', 'FontSize', labelFontSize);
                    title(ax, sprintf('\\sigma_{%s} = %.2f nm\n(%s)', labels{j}, med(j), plotName), 'FontSize', titleFontSize);

                    % Set font size for tick labels
                    ax.XAxis.FontSize = tickFontSize;
                    ax.YAxis.FontSize = tickFontSize;

                    hold(ax, 'off');
                end
            end

            % Set the tab's content size to enable scrolling
            app.LocalizationPrecisionTab.UserData = struct('ContentSize', [contentWidth contentHeight]);
        end

        function cleanedData = removeOutliers(app, data)
            meanVal = mean(data);
            stdVal = std(data);
            cleanedData = data(abs(data - meanVal) <= 5 * stdVal);
        end

function plotSelectedClusters(app)
    selectedClusters = str2double(app.ListBox.Value);
    cla(app.UIAxes5, 'reset');
    hold(app.UIAxes5, 'on');
    allCenteredPoints = [];
    allColors = []; % Store colors for all points

    for i = 1:numel(selectedClusters)
        clusterIndex = selectedClusters(i);
        clusterPoints = app.Data1(app.Clusters1 == clusterIndex & app.IsNoise1 == 0, :);
        centroid = mean(clusterPoints, 1);
        centeredPoints = bsxfun(@minus, clusterPoints, centroid);
        allCenteredPoints = [allCenteredPoints; centeredPoints];

        % ENHANCED COLOR HANDLING
        if app.UseCustomColors
            coloringMode = getColoringMode(app);
            switch coloringMode
                case 'Z-Position'
                    if ~isempty(app.ZColors) && length(app.ZColors) == size(app.Data1, 1)
                        clusterPointIndices = find(app.Clusters1 == clusterIndex & app.IsNoise1 == 0);
                        if ~isempty(clusterPointIndices)
                            color = app.ZColors(clusterPointIndices);
                        else
                            color = 0.5 * ones(size(centeredPoints, 1), 1); % Default middle value
                        end
                    else
                        % Create Z-indices for this cluster on the fly
                        zCoords = clusterPoints(:, 3);
                        if max(zCoords) > min(zCoords)
                            normalizedZ = (zCoords - min(zCoords)) / (max(zCoords) - min(zCoords));
                        else
                            normalizedZ = 0.5 * ones(size(zCoords));
                        end
                        color = normalizedZ;
                    end

                case 'Localizations'
                    if ~isempty(app.LocalizationColors) && length(app.LocalizationColors) == size(app.Data1, 1)
                        clusterPointIndices = find(app.Clusters1 == clusterIndex & app.IsNoise1 == 0);
                        if ~isempty(clusterPointIndices)
                            color = app.LocalizationColors(clusterPointIndices);
                        else
                            color = 0.5 * ones(size(centeredPoints, 1), 1);
                        end
                    else
                        % Use cluster's normalized localization count
                        validClusters = unique(app.Clusters1(app.Clusters1 > 0));
                        locCounts = arrayfun(@(x) sum(app.Clusters1 == x), validClusters);
                        if length(locCounts) > 1
                            clusterLocCount = sum(app.Clusters1 == clusterIndex);
                            normalizedCount = (clusterLocCount - min(locCounts)) / (max(locCounts) - min(locCounts));
                        else
                            normalizedCount = 0.5;
                        end
                        color = normalizedCount * ones(size(centeredPoints, 1), 1);
                    end

                case 'Traces'
                    if ~isempty(app.TraceColors) && length(app.TraceColors) == size(app.Data1, 1)
                        clusterPointIndices = find(app.Clusters1 == clusterIndex & app.IsNoise1 == 0);
                        if ~isempty(clusterPointIndices)
                            color = app.TraceColors(clusterPointIndices);
                        else
                            color = 0.5 * ones(size(centeredPoints, 1), 1);
                        end
                    else
                        % Use cluster's normalized trace count
                        if ~isempty(app.trace_clusters1)
                            validClusters = unique(app.trace_clusters1(app.trace_clusters1 > 0));
                            traceCounts = arrayfun(@(x) sum(app.trace_clusters1 == x), validClusters);
                            if length(traceCounts) > 1
                                clusterTraceCount = sum(app.trace_clusters1 == clusterIndex);
                                normalizedCount = (clusterTraceCount - min(traceCounts)) / (max(traceCounts) - min(traceCounts));
                            else
                                normalizedCount = 0.5;
                            end
                            color = normalizedCount * ones(size(centeredPoints, 1), 1);
                        else
                            color = 0.5 * ones(size(centeredPoints, 1), 1);
                        end
                    end

                otherwise % 'Default'
                    % Convert to RGB color
                    rgbColor = app.getColor(app.ChannelColors{1});
                    color = repmat(rgbColor, size(centeredPoints, 1), 1);
            end
        else
            % Use standard cluster colors (RGB)
            rgbColor = app.ClusterColors(clusterIndex, :);
            color = repmat(rgbColor, size(centeredPoints, 1), 1);
        end
        
        % Store colors for all points
        allColors = [allColors; color];
    end

    app.allCenteredPoints = allCenteredPoints;

    % Plot all points at once with their colors
    if app.UseCustomColors && ismember(getColoringMode(app), {'Z-Position', 'Localizations', 'Traces'})
        % Apply colormap and set color limits before plotting
        colormap(app.UIAxes5, feval(app.currentcolormap, 256));
        
        % Set appropriate color limits
        coloringMode = getColoringMode(app);
        switch coloringMode
            case 'Z-Position'
                caxis(app.UIAxes5, [app.ZMin, app.ZMax]);
                % Convert normalized colors to actual Z values for proper colormap scaling
                actualColors = allColors * (app.ZMax - app.ZMin) + app.ZMin;
            case 'Localizations'
                caxis(app.UIAxes5, [app.LocMin, app.LocMax]);
                actualColors = allColors * (app.LocMax - app.LocMin) + app.LocMin;
            case 'Traces'
                caxis(app.UIAxes5, [app.TrcMin, app.TrcMax]);
                actualColors = allColors * (app.TrcMax - app.TrcMin) + app.TrcMin;
            otherwise
                actualColors = allColors;
        end
        
        % Use the actual color values for the scatter plot
        scatter3(app.UIAxes5, allCenteredPoints(:, 1), allCenteredPoints(:, 2), allCenteredPoints(:, 3), 20, actualColors(:,1), 'filled', 'MarkerFaceAlpha', 0.3);
    else
        % For RGB modes or default mode, use the color directly
        scatter3(app.UIAxes5, allCenteredPoints(:, 1), allCenteredPoints(:, 2), allCenteredPoints(:, 3), 20, allColors, 'filled', 'MarkerFaceAlpha', 0.3);
    end

    % Combined Alpha shape visualization for all selected clusters
    if app.AlphaShapeCheckBox.Value && size(allCenteredPoints, 1) >= 4
        alphaRadius = app.AlphaRadiusSlider.Value;
        shp = alphaShape(allCenteredPoints, alphaRadius);
        % If only one cluster is selected, use its color for the alpha shape
        if numel(selectedClusters) == 1
            if app.UseCustomColors && strcmp(getColoringMode(app), 'Z-Position')
                alphaShapeColor = [0.5, 0.5, 0.5]; % Use gray for Z-position mode
            else
                alphaShapeColor = app.ClusterColors(selectedClusters, :);
            end
        else
            alphaShapeColor = 'k';
        end
        plot(shp, 'FaceColor', alphaShapeColor, 'FaceAlpha', 0.1, 'EdgeAlpha', 0.2, 'Parent', app.UIAxes5);
    end

    if app.EllipsoidFitCheckBox.Value && ~isempty(allCenteredPoints)
        X = allCenteredPoints(:, 1);
        Y = allCenteredPoints(:, 2);
        Z = allCenteredPoints(:, 3);
        A = [2*X, 2*Y, 2*Z, ones(size(X))];
        b = X.^2 + Y.^2 + Z.^2;
        lambda = 0.1; % Regularization parameter
        params = (A' * A + lambda * eye(size(A, 2))) \ (A' * b);
        x0 = params(1) / 2;
        y0 = params(2) / 2;
        z0 = params(3) / 2;
        r = sqrt(params(4) + x0^2 + y0^2 + z0^2);
        [x, y, z] = sphere(10);
        x = r * x + x0;
        y = r * y + y0;
        z = r * z + z0;
        surf(app.UIAxes5, x, y, z, 'FaceColor', 'none', 'FaceAlpha', 0.3, 'EdgeColor', 'r', 'LineWidth', 1);
        textPosition = [x0 + r + 0.6*r, y0, z0];
        textStr = sprintf('Radius: %.2f nm', r);
        text(app.UIAxes5, textPosition(1), textPosition(2), textPosition(3), textStr, 'FontSize', 16, 'Color', 'r');
    end

    scatter3(app.UIAxes5, 0, 0, 0, 100, 'red', 'filled');
    
    % Set axis limits based on the custom box sizes
    xlim(app.UIAxes5, [-app.BoxSizeX/2, app.BoxSizeX/2]);
    ylim(app.UIAxes5, [-app.BoxSizeY/2, app.BoxSizeY/2]);
    zlim(app.UIAxes5, [-app.BoxSizeZ/2, app.BoxSizeZ/2]);

    xlabel(app.UIAxes5, 'X (nm)');
    ylabel(app.UIAxes5, 'Y (nm)');
    zlabel(app.UIAxes5, 'Z (nm)');
    title(app.UIAxes5, sprintf('DBSCAN (single) - Box Size: %dx%dx%d nm', app.BoxSizeX, app.BoxSizeY, app.BoxSizeZ));

    view(app.UIAxes5, 3);
    grid(app.UIAxes5, 'on');
    axis(app.UIAxes5, 'equal');
    rotate3d(app.UIAxes5, 'on');

    % Adjust data aspect ratio to maintain relative scaling
    daspect(app.UIAxes5, [1 1 1]);
    
    % Apply colorbar based on current coloring mode
    if app.UseCustomColors
        coloringMode = getColoringMode(app);
        switch coloringMode
            case 'Z-Position'
                app.setColorScaleAndColorbar(app.UIAxes5, 'Z (nm)', app.ZMin, app.ZMax);

            case 'Localizations'
                app.setColorScaleAndColorbar(app.UIAxes5, 'Localizations', app.LocMin, app.LocMax);
            case 'Traces'
                app.setColorScaleAndColorbar(app.UIAxes5, 'Traces', app.TrcMin, app.TrcMax);
            otherwise
                % Default mode - hide colorbar
                app.hideColorbar(app.UIAxes5);
        end
    else
        % Default mode - hide colorbar
        app.hideColorbar(app.UIAxes5);
    end
end

       function plotSelectedClusters2(app)
    selectedClusters = str2double(app.ListBox_2.Value);
    cla(app.UIAxes5_2, 'reset');
    hold(app.UIAxes5_2, 'on');
    allCenteredPoints = [];
    allColors = []; % Store colors for all points

    % Error checking
    if isempty(selectedClusters)
        disp('No clusters selected');
        return;
    end

    % Plot main cluster data
    for i = 1:numel(selectedClusters)
        clusterIndex = selectedClusters(i);
        clusterPoints = app.Data1(app.Clusters1 == clusterIndex & ~app.IsNoise1, :);

        if isempty(clusterPoints)
            continue;
        end

        centroid = mean(clusterPoints, 1);
        centeredPoints = bsxfun(@minus, clusterPoints, centroid);

        % Add points to the collection
        allCenteredPoints = [allCenteredPoints; centeredPoints];

        % ENHANCED COLOR HANDLING - same as plotSelectedClusters
        if app.UseCustomColors
            coloringMode = getColoringMode(app);
            switch coloringMode
                case 'Z-Position'
                    if ~isempty(app.ZColors) && length(app.ZColors) == size(app.Data1, 1)
                        clusterPointIndices = find(app.Clusters1 == clusterIndex & ~app.IsNoise1);
                        if ~isempty(clusterPointIndices)
                            color = app.ZColors(clusterPointIndices);
                        else
                            color = 0.5 * ones(size(centeredPoints, 1), 1); % Default middle value
                        end
                    else
                        % Create Z-indices for this cluster on the fly
                        zCoords = clusterPoints(:, 3);
                        if max(zCoords) > min(zCoords)
                            normalizedZ = (zCoords - min(zCoords)) / (max(zCoords) - min(zCoords));
                        else
                            normalizedZ = 0.5 * ones(size(zCoords));
                        end
                        color = normalizedZ;
                    end

                case 'Localizations'
                    if ~isempty(app.LocalizationColors) && length(app.LocalizationColors) == size(app.Data1, 1)
                        clusterPointIndices = find(app.Clusters1 == clusterIndex & ~app.IsNoise1);
                        if ~isempty(clusterPointIndices)
                            color = app.LocalizationColors(clusterPointIndices);
                        else
                            color = 0.5 * ones(size(centeredPoints, 1), 1);
                        end
                    else
                        % Use cluster's normalized localization count
                        validClusters = unique(app.Clusters1(app.Clusters1 > 0));
                        locCounts = arrayfun(@(x) sum(app.Clusters1 == x), validClusters);
                        if length(locCounts) > 1
                            clusterLocCount = sum(app.Clusters1 == clusterIndex);
                            normalizedCount = (clusterLocCount - min(locCounts)) / (max(locCounts) - min(locCounts));
                        else
                            normalizedCount = 0.5;
                        end
                        color = normalizedCount * ones(size(centeredPoints, 1), 1);
                    end

                case 'Traces'
                    if ~isempty(app.TraceColors) && length(app.TraceColors) == size(app.Data1, 1)
                        clusterPointIndices = find(app.Clusters1 == clusterIndex & ~app.IsNoise1);
                        if ~isempty(clusterPointIndices)
                            color = app.TraceColors(clusterPointIndices);
                        else
                            color = 0.5 * ones(size(centeredPoints, 1), 1);
                        end
                    else
                        % Use cluster's normalized trace count
                        if ~isempty(app.trace_clusters1)
                            validClusters = unique(app.trace_clusters1(app.trace_clusters1 > 0));
                            traceCounts = arrayfun(@(x) sum(app.trace_clusters1 == x), validClusters);
                            if length(traceCounts) > 1
                                clusterTraceCount = sum(app.trace_clusters1 == clusterIndex);
                                normalizedCount = (clusterTraceCount - min(traceCounts)) / (max(traceCounts) - min(traceCounts));
                            else
                                normalizedCount = 0.5;
                            end
                            color = normalizedCount * ones(size(centeredPoints, 1), 1);
                        else
                            color = 0.5 * ones(size(centeredPoints, 1), 1);
                        end
                    end

                otherwise
                    % Convert to RGB color
                    rgbColor = app.getColor(app.ChannelColors{1});
                    color = repmat(rgbColor, size(centeredPoints, 1), 1);
            end
        else
            % Use standard cluster colors (RGB)
            rgbColor = app.ClusterColors(clusterIndex, :);
            color = repmat(rgbColor, size(centeredPoints, 1), 1);
        end
        
        % Store colors for all points
        allColors = [allColors; color];
    end

    % Store the centered points for later use
    app.allCenteredPoints = allCenteredPoints;

    % Plot all main channel points at once with their colors
    if ~isempty(allCenteredPoints)
        if app.UseCustomColors && ismember(getColoringMode(app), {'Z-Position', 'Localizations', 'Traces'})
            % Apply colormap and set color limits before plotting
            colormap(app.UIAxes5_2, feval(app.currentcolormap, 256));
            
            % Set appropriate color limits
            coloringMode = getColoringMode(app);
            switch coloringMode
                case 'Z-Position'
                    caxis(app.UIAxes5_2, [app.ZMin, app.ZMax]);
                    % Convert normalized colors to actual Z values for proper colormap scaling
                    actualColors = allColors * (app.ZMax - app.ZMin) + app.ZMin;
                case 'Localizations'
                    caxis(app.UIAxes5_2, [app.LocMin, app.LocMax]);
                    actualColors = allColors * (app.LocMax - app.LocMin) + app.LocMin;
                case 'Traces'
                    caxis(app.UIAxes5_2, [app.TrcMin, app.TrcMax]);
                    actualColors = allColors * (app.TrcMax - app.TrcMin) + app.TrcMin;
                otherwise
                    actualColors = allColors;
            end
            
            % Use the actual color values for the scatter plot
            scatter3(app.UIAxes5_2, allCenteredPoints(:, 1), allCenteredPoints(:, 2), allCenteredPoints(:, 3), 20, actualColors(:,1), 'filled', 'MarkerFaceAlpha', 0.3);
        else
            % For RGB modes or default mode, use the color directly
            scatter3(app.UIAxes5_2, allCenteredPoints(:, 1), allCenteredPoints(:, 2), allCenteredPoints(:, 3), 20, allColors, 'filled', 'MarkerFaceAlpha', 0.3);
        end
    end

    % Alpha shape visualization
    if app.AlphaShapeCheckBox_2.Value && size(allCenteredPoints, 1) >= 4
        alphaRadius = app.AlphaRadiusSlider_2.Value;
        shp = alphaShape(allCenteredPoints, alphaRadius);

        % Determine appropriate color based on selection
        if numel(selectedClusters) == 1
            if app.UseCustomColors && strcmp(getColoringMode(app), 'Z-Position')
                alphaShapeColor = [0.5, 0.5, 0.5];
            else
                alphaShapeColor = app.ClusterColors(selectedClusters, :);
            end
        else
            alphaShapeColor = 'k';
        end

        plot(shp, 'FaceColor', alphaShapeColor, 'FaceAlpha', 0.1, 'EdgeAlpha', 0.2, 'Parent', app.UIAxes5_2);
    end

    % Ellipsoid Fit implementation
    if app.EllipsoidFitCheckBox_2.Value && ~isempty(allCenteredPoints)
        X = allCenteredPoints(:, 1);
        Y = allCenteredPoints(:, 2);
        Z = allCenteredPoints(:, 3);
        A = [2*X, 2*Y, 2*Z, ones(size(X))];
        b = X.^2 + Y.^2 + Z.^2;
        lambda = 0.1; % Regularization parameter
        params = (A' * A + lambda * eye(size(A, 2))) \ (A' * b);
        x0 = params(1) / 2;
        y0 = params(2) / 2;
        z0 = params(3) / 2;
        r = sqrt(params(4) + x0^2 + y0^2 + z0^2);
        [x, y, z] = sphere(10);
        x = r * x + x0;
        y = r * y + y0;
        z = r * z + z0;
        surf(app.UIAxes5_2, x, y, z, 'FaceColor', 'none', 'FaceAlpha', 0.3, 'EdgeColor', 'r', 'LineWidth', 1);
        textPosition = [x0 + r + 0.6*r, y0, z0];
        textStr = sprintf('Radius: %.2f nm', r);
        text(app.UIAxes5_2, textPosition(1), textPosition(2), textPosition(3), textStr, 'FontSize', 16, 'Color', 'r');
    end

    % Plot additional channels
    channelData = {app.Data2_off, app.Data3_off, app.Data4_off, app.Data5_off};
    checkBoxes = {app.Ch2CheckBox, app.Ch3CheckBox, app.Ch4CheckBox, app.Ch5CheckBox};
    colors = {'cyan', 'red', 'magenta', 'green'};

    % Get the centroid from the main channel
    if ~isempty(allCenteredPoints)
        if numel(selectedClusters) > 0
            % Get all points from selected clusters
            allClusterPoints = [];
            for i = 1:numel(selectedClusters)
                clusterIndex = selectedClusters(i);
                allClusterPoints = [allClusterPoints; app.Data1(app.Clusters1 == clusterIndex & ~app.IsNoise1, :)];
            end
            centroid = mean(allClusterPoints, 1);
        end

        % Plot each additional channel if enabled
        for i = 1:4
            if checkBoxes{i}.Value && ~isempty(channelData{i})
                data_nm = channelData{i};
                centered_data = data_nm - centroid;
                in_bbox = all(abs(centered_data) <= [app.BoxSizeX, app.BoxSizeY, app.BoxSizeZ]/2, 2);
                scatter3(app.UIAxes5_2, centered_data(in_bbox, 1), centered_data(in_bbox, 2), centered_data(in_bbox, 3), 20, colors{i}, 'filled', 'MarkerFaceAlpha', 0.7);
            end
        end
    end

    % Add a reference point at the center
    scatter3(app.UIAxes5_2, 0, 0, 0, 100, 'red', 'filled');

    % Set axis limits based on the custom box sizes
    xlim(app.UIAxes5_2, [-app.BoxSizeX/2, app.BoxSizeX/2]);
    ylim(app.UIAxes5_2, [-app.BoxSizeY/2, app.BoxSizeY/2]);
    zlim(app.UIAxes5_2, [-app.BoxSizeZ/2, app.BoxSizeZ/2]);

    % Add labels and title
    xlabel(app.UIAxes5_2, 'X (nm)');
    ylabel(app.UIAxes5_2, 'Y (nm)');
    zlabel(app.UIAxes5_2, 'Z (nm)');
    title(app.UIAxes5_2, sprintf('DBSCAN (multi) - Box Size: %dx%dx%d nm', app.BoxSizeX, app.BoxSizeY, app.BoxSizeZ));

    % Set view and axis properties
    view(app.UIAxes5_2, 3);
    grid(app.UIAxes5_2, 'on');
    axis(app.UIAxes5_2, 'equal');
    rotate3d(app.UIAxes5_2, 'on');

    % Adjust data aspect ratio to maintain relative scaling
    daspect(app.UIAxes5_2, [1 1 1]);

    % ADD COLORBAR SUPPORT FOR UIAxes5_2
    % Apply colorbar based on current coloring mode
    if app.UseCustomColors
        coloringMode = getColoringMode(app);
        switch coloringMode
            case 'Z-Position'
                app.setColorScaleAndColorbar(app.UIAxes5_2, 'Z (nm)', app.ZMin, app.ZMax);
            case 'Localizations'
                app.setColorScaleAndColorbar(app.UIAxes5_2, 'Localizations', app.LocMin, app.LocMax);
            case 'Traces'
                app.setColorScaleAndColorbar(app.UIAxes5_2, 'Traces', app.TrcMin, app.TrcMax);
            otherwise
                % Default mode - hide colorbar
                app.hideColorbar(app.UIAxes5_2);
        end
    else
        % Default mode - hide colorbar
        app.hideColorbar(app.UIAxes5_2);
    end

    hold(app.UIAxes5_2, 'off');
end

        function [trace_data, trace_cluster_assignments] = calculateTracesForChannel(app, data, tid, clusters, isNoise)
            if isempty(data) || isempty(tid) || isempty(clusters) || isempty(isNoise)
                trace_data = [];
                trace_cluster_assignments = [];
                return;
            end

            % Ensure all arrays have the same length
            minLength = min([size(data,1), length(tid), length(clusters), length(isNoise)]);
            validIndices = ~isNoise(1:minLength);
            validData = data(1:minLength, :);
            validTid = tid(1:minLength);
            validClusters = clusters(1:minLength);

            [uniqueTids, ~, idx] = unique(validTid);
            trace_data = zeros(length(uniqueTids), size(validData, 2));
            trace_cluster_assignments = zeros(length(uniqueTids), 1);

            for i = 1:length(uniqueTids)
                traceIndices = validTid == uniqueTids(i);
                trace_data(i, :) = mean(validData(traceIndices, :), 1);
                trace_cluster_assignments(i) = mode(validClusters(traceIndices));
            end
        end

        function calculateTraceData(app)
            for i = 1:5
                dataField = ['Data', num2str(i)];
                if i > 1
                    dataField = [dataField, '_off'];
                end
                tidField = ['tid', num2str(i)];
                clusterField = ['Clusters', num2str(i)];
                isNoiseField = ['IsNoise', num2str(i)];
                traceDataField = ['trace_data', num2str(i)];
                traceClusterField = ['trace_clusters', num2str(i)];

                if ~isempty(app.(dataField)) && ~isempty(app.(tidField))
                    [app.(traceDataField), app.(traceClusterField)] = app.calculateTracesForChannel(app.(dataField), app.(tidField), app.(clusterField), app.(isNoiseField));
                else
                    app.(traceDataField) = [];
                    app.(traceClusterField) = [];
                    warning(['Channel ', num2str(i), ' data or tid is empty']);
                end
            end

            % Print summary
            disp('Trace data calculation complete:');
            for i = 1:5
                traceDataField = ['trace_data', num2str(i)];
                disp(['Channel ', num2str(i), ': ', num2str(size(app.(traceDataField), 1)), ' traces']);
            end
        end


       function Measurement(app)
    selectedClusters = str2double(app.ListBox2.Value);

    cla(app.UIAxes6, 'reset');
    hold(app.UIAxes6, 'on');

    % Use the MaxAnalysisDistance property instead of hardcoded 120
    maxDist = app.MaxAnalysisDistance;
    
    % Dynamic bin size based on max distance for optimal resolution
    if maxDist <= 150
        binSize = 5;    % 5 nm bins for small ranges
    elseif maxDist <= 300
        binSize = 10;   % 10 nm bins for medium ranges
    elseif maxDist <= 600
        binSize = 20;   % 20 nm bins for large ranges
    else
        binSize = 25;   % 25 nm bins for very large ranges
    end
    
    % Create dynamic bin edges
    bin_edges = 0:binSize:maxDist;
    
    fprintf('Line profile analysis: 0 to %d nm with %d nm bins (%d bins total)\n', ...
        maxDist, binSize, length(bin_edges)-1);

    allDistances = [];
    allCounts = [];
    allCounts2 = []; % To collect all second channel counts
    allCounts3 = []; % To collect all third channel counts
    allCounts4 = []; % To collect all fourth channel counts
    allCounts5 = []; % To collect all fifth channel counts

    maxCountOverall = -inf;

    % Ensure trace data is calculated
    app.calculateTraceData();

    % Determine which data to use based on the ButtonGroup1 selection
    if app.trcButton1.Value
        mainData = app.trace_data1;
        data2 = app.trace_data2;
        data3 = app.trace_data3;
        data4 = app.trace_data4;
        data5 = app.trace_data5;
        clusterAssignments = app.trace_clusters1;
        isNoise = clusterAssignments == 0;  % Assuming 0 represents noise in trace clusters
    else
        mainData = app.Data1;
        data2 = app.Data2_off;
        data3 = app.Data3_off;
        data4 = app.Data4_off;
        data5 = app.Data5_off;
        clusterAssignments = app.Clusters1;
        isNoise = app.IsNoise1;
    end

    % First loop to find the overall maximum count
    for i = 1:numel(selectedClusters)
        clusterIndex = selectedClusters(i);
        clusterPoints = mainData(clusterAssignments == clusterIndex & ~isNoise, :);

        centroid = mean(clusterPoints, 1);
        centeredPoints = clusterPoints - centroid;

        distances = sqrt(sum(centeredPoints.^2, 2));
        counts = histcounts(distances, bin_edges);

        currentMaxCount = max(counts);
        if currentMaxCount > maxCountOverall
            maxCountOverall = currentMaxCount;
        end
    end

    % Second loop to plot the data using the overall maximum count for normalization
    for i = 1:numel(selectedClusters)
        clusterIndex = selectedClusters(i);
        clusterPoints = mainData(clusterAssignments == clusterIndex & ~isNoise, :);

        centroid = mean(clusterPoints, 1);
        centeredPoints = clusterPoints - centroid;

        color = app.ClusterColors(clusterIndex, :);

        distances = sqrt(sum(centeredPoints.^2, 2));
        allDistances = [allDistances; distances];

        counts = histcounts(distances, bin_edges);
        allCounts(i, :) = counts;

        presence_count = histcounts(distances, bin_edges);
        presence_count_norm = presence_count / maxCountOverall * 100;

        b = bar(app.UIAxes6, bin_edges(1:end-1), presence_count_norm, 'histc');
        b.FaceColor = color;  % Set the color of the bar
        b.DisplayName = ['Cluster ', num2str(clusterIndex)];

        % Collect second channel data
        if ~isempty(data2)
            distances2 = sqrt(sum((data2 - centroid).^2, 2));
            vicinity_threshold = 500; % Define the vicinity threshold
            vicinity_indices = distances2 <= vicinity_threshold;
            distances2_vicinity = distances2(vicinity_indices);

            if ~isempty(distances2_vicinity)
                counts2 = histcounts(distances2_vicinity, bin_edges);
                allCounts2 = [allCounts2; counts2];
            end
        end

        % Collect third channel data
        if ~isempty(data3)
            distances3 = sqrt(sum((data3 - centroid).^2, 2));
            vicinity_threshold = 500; % Define the vicinity threshold
            vicinity_indices = distances3 <= vicinity_threshold;
            distances3_vicinity = distances3(vicinity_indices);

            if ~isempty(distances3_vicinity)
                counts3 = histcounts(distances3_vicinity, bin_edges);
                allCounts3 = [allCounts3; counts3];
            end
        end

        % Collect fourth channel data
        if ~isempty(data4)
            distances4 = sqrt(sum((data4 - centroid).^2, 2));
            vicinity_threshold = 500; % Define the vicinity threshold
            vicinity_indices = distances4 <= vicinity_threshold;
            distances4_vicinity = distances4(vicinity_indices);

            if ~isempty(distances4_vicinity)
                counts4 = histcounts(distances4_vicinity, bin_edges);
                allCounts4 = [allCounts4; counts4];
            end
        end

        % Collect fifth channel data
        if ~isempty(data5)
            distances5 = sqrt(sum((data5 - centroid).^2, 2));
            vicinity_threshold = 500; % Define the vicinity threshold
            vicinity_indices = distances5 <= vicinity_threshold;
            distances5_vicinity = distances5(vicinity_indices);

            if ~isempty(distances5_vicinity)
                counts5 = histcounts(distances5_vicinity, bin_edges);
                allCounts5 = [allCounts5; counts5];
            end
        end
    end

    if numel(selectedClusters) >= 2
        % Calculate mean and standard error of all distances
        meanDistance = mean(allDistances);
        standardError = std(allDistances) / sqrt(length(allDistances));
        app.MeanDistLabel.Text = sprintf('Mean Distance: %.2f ± %.2f', meanDistance, standardError);

        % Plot the mean line
        yLimits = ylim(app.UIAxes6);
        plot(app.UIAxes6, [meanDistance, meanDistance], yLimits*1.2, 'r--', 'LineWidth', 2, 'DisplayName', 'Mean Distance');

        % Calculate and plot mean and standard error for multiple clusters
        meanCounts = mean(allCounts, 1);
        seCounts = std(allCounts, 0, 1) / sqrt(size(allCounts, 1));
        maxCount = max(meanCounts);
        meanCountsNorm = meanCounts / maxCountOverall * 100;
        seCountsNorm = seCounts / maxCountOverall * 100;

        binCenters = (bin_edges(1:end-1) + bin_edges(2:end)) / 2;
        fitresult = fit(binCenters', meanCountsNorm', 'smoothingspline');

        yyaxis(app.UIAxes6, 'left');
        plot(app.UIAxes6, binCenters, fitresult(binCenters), 'k-', 'LineWidth', 2, 'DisplayName', 'Mean Counts(norm.)');
        fill(app.UIAxes6, [binCenters, fliplr(binCenters)], [fitresult(binCenters)' + seCountsNorm, fliplr(fitresult(binCenters)' - seCountsNorm)], 'k', 'FaceAlpha', 0.2, 'EdgeColor', 'none');

    elseif isscalar(selectedClusters)
        % Calculate the distance of maximal counts
        counts = histcounts(allDistances, bin_edges);
        [~, maxCountIndex] = max(counts);
        distanceOfMaxCounts = bin_edges(maxCountIndex);

        app.MeanDistLabel.Text = sprintf('Distance of Max Counts: %.2f', distanceOfMaxCounts);
    end

    % Protein localization plotting
    channelData = {allCounts2, allCounts3, allCounts4, allCounts5};
    channelColors = {'c', 'r', 'm', 'b'};
    channelNames = {app.filename2, app.filename3, app.filename4, app.filename5};

    yyaxis(app.UIAxes6, 'right');
    hold(app.UIAxes6, 'on');

    % Find the maximum count across all channels for global normalization
    maxOverallCount = 0;
    if ~app.NormalizeIndividuallyCheckBox.Value
        for j = 1:length(channelData)
            if ~isempty(channelData{j})
                meanCounts = mean(channelData{j}, 1);
                validMeanCounts = meanCounts(~isnan(meanCounts));
                maxOverallCount = max(maxOverallCount, max(validMeanCounts));
            end
        end
    end

    for j = 1:length(channelData)
        if ~isempty(channelData{j})
            meanCounts = mean(channelData{j}, 1);
            seCounts = std(channelData{j}, 0, 1) / sqrt(size(channelData{j}, 1));

            binCenters = (bin_edges(1:end-1) + bin_edges(2:end)) / 2;

            % Remove NaN values
            validIndices = ~isnan(meanCounts) & ~isnan(binCenters);
            validMeanCounts = meanCounts(validIndices);
            validBinCenters = binCenters(validIndices);
            validSECounts = seCounts(validIndices);

            if length(validMeanCounts) > 3  % Ensure we have enough points for plotting
                % Determine normalization factor based on checkbox
                if app.NormalizeIndividuallyCheckBox.Value
                    normFactor = max(validMeanCounts);
                else
                    normFactor = maxOverallCount;
                end

                if normFactor > 0  % Avoid division by zero
                    normalizedMeanCounts = validMeanCounts / normFactor * 100;
                    normalizedSECounts = validSECounts / normFactor * 100;

                    % Plot the normalized data
                    plot(app.UIAxes6, validBinCenters, normalizedMeanCounts, [channelColors{j}, '-'], 'LineWidth', 2, 'DisplayName', [channelNames{j}, ' Channel']);

                    % Plot the standard error as a shaded area
                    upperBound = min(normalizedMeanCounts + normalizedSECounts, 100);
                    lowerBound = max(normalizedMeanCounts - normalizedSECounts, 0);
                    fill(app.UIAxes6, [validBinCenters, fliplr(validBinCenters)], ...
                        [upperBound, fliplr(lowerBound)], ...
                        channelColors{j}, 'FaceAlpha', 0.2, 'EdgeColor', 'none', 'DisplayName', [channelNames{j}, ' Channel SE']);
                else
                    warning('All zero counts for channel %s. Skipping this channel.', channelNames{j});
                end
            else
                warning('Not enough valid data points for channel %s. Skipping this channel.', channelNames{j});
            end
        end
    end

    % Set the y-axis limits for the right axis (protein localization)
    yyaxis(app.UIAxes6, 'right');
    ylim(app.UIAxes6, [0, 100]);  % Set to percentage scale
    if app.NormalizeIndividuallyCheckBox.Value
        ylabel(app.UIAxes6, 'Normalized Protein Localization (% of channel max)');
    else
        ylabel(app.UIAxes6, 'Normalized Protein Localization (% of overall max)');
    end

    % Switch back to left axis for main data
    yyaxis(app.UIAxes6, 'left');
    ylabel(app.UIAxes6, 'Mean Counts');

    % Adjust y-axis limits on the left
    yLimitsLeft = ylim(app.UIAxes6);
    ylim(app.UIAxes6, [0, yLimitsLeft(2) * 1.1]);

    % Set x-axis limits using dynamic maxDist
    xlim(app.UIAxes6, [0, maxDist]);

    % Final plot adjustments
    hold(app.UIAxes6, 'off');
    xlabel(app.UIAxes6, 'Distance from Centroid (nm)');
    title(app.UIAxes6, sprintf('Distance of Localizations to Centroid (0-%d nm)', maxDist));
    legend(app.UIAxes6, 'show', 'Location', 'eastoutside');
    grid(app.UIAxes6, 'on');
end

        function updatePlots2(app)
            cla(app.UIAxes7);
            hold(app.UIAxes7, 'on');  % Set hold on at the beginning
            legendEntries = {};

            for i = 1:length(app.ChannelListBox.Items)
                displayChannelName = app.ChannelListBox.Items{i};

                % Skip the 'New Channel' item
                if strcmp(displayChannelName, 'New Channel')
                    continue;
                end

                channelNumber = str2double(regexp(displayChannelName, '\d+', 'match'));
                channelName = ['Channel_', num2str(channelNumber)];

                % Check if the channel exists and is enabled
                if isfield(app.ChannelEnableStates, channelName) && app.ChannelEnableStates.(channelName)
                    data = app.(['Data', num2str(channelNumber)]);


                    if ~isempty(data)
                        if app.DBSCANCheckBox.Value
                            idx = applyDBSCAN(app, data, channelNumber);

                            % Apply size exclusion
                            if app.EnableExclusion.Value
                                exclusionThreshold = app.ExclNum.Value;
                                validClusters = unique(idx(idx > 0));
                                validClusters = validClusters(arrayfun(@(x) sum(idx == x), validClusters) >= exclusionThreshold);
                                isValid = ismember(idx, validClusters);
                            else
                                isValid = idx > 0;
                            end

                            data = data(isValid, :);
                        end

                        % Apply stored corrections
                        if isfield(app.ChannelCorrections, channelName)
                            corrections = app.ChannelCorrections.(channelName);
                            data(:,1) = data(:,1) + corrections.X;
                            data(:,2) = data(:,2) + corrections.Y;
                            data(:,3) = data(:,3) + corrections.Z;
                        end

                        app.(['Data', num2str(channelNumber), '_off']) = data;

                        % Use updateScatterMulti
                        if ~isempty(data)
                            if app.UseCustomColors
                                color = app.ChannelColors{channelNumber};
                            else
                                color = app.getChannelColor(channelNumber);
                            end
                            updateScatterMulti(app, app.UIAxes7, data, color);
                            legendEntries{end+1} = displayChannelName;
                        end
                    end
                end
            end
            updateCheckboxVisibility(app);
            % Set axis properties after all channels are plotted
            title(app.UIAxes7, 'Multicolor MINFLUX Data', 'FontSize', 16, 'FontWeight', 'bold');
            xlabel(app.UIAxes7, 'X (nm)', 'FontSize', 16, 'FontWeight', 'bold');
            ylabel(app.UIAxes7, 'Y (nm)', 'FontSize', 16, 'FontWeight', 'bold');
            zlabel(app.UIAxes7, 'Z (nm)', 'FontSize', 16, 'FontWeight', 'bold');
            axis(app.UIAxes7, 'equal');
            view(app.UIAxes7, 3);
            grid(app.UIAxes7, 'on');

            app.UIAxes7.XAxis.FontSize = 16;
            app.UIAxes7.YAxis.FontSize = 16;
            app.UIAxes7.ZAxis.FontSize = 16;

            % After all channels are plotted, set the final axis limits
            xlim(app.UIAxes7, [0, app.GlobalXLim(2) - app.GlobalXLim(1)]);
            ylim(app.UIAxes7, [0, app.GlobalYLim(2) - app.GlobalYLim(1)]);
            zlim(app.UIAxes7, app.GlobalZLim);

            xticks(app.UIAxes7, app.GlobalXTicks - app.GlobalXLim(1));
            yticks(app.UIAxes7, app.GlobalYTicks - app.GlobalYLim(1));
            zticks(app.UIAxes7, app.GlobalZTicks);

            legend(app.UIAxes7, legendEntries, 'Location', 'northeast');

            rotate3d(app.UIAxes7, 'on');

            hold(app.UIAxes7, 'off');  % Turn hold off at the end
        end
        function updateCheckboxVisibility(app)
            checkboxes = {app.Ch2CheckBox, app.Ch3CheckBox, app.Ch4CheckBox, app.Ch5CheckBox};

            for i = 1:4
                channelNumber = i + 1;  % This maps to channels 2, 3, 4, 5
                channelName = ['Channel_', num2str(channelNumber)];

                if isfield(app.ChannelFileNames, channelName) && ~isempty(app.ChannelFileNames.(channelName))
                    checkboxes{i}.Visible = 'on';
                else
                    checkboxes{i}.Visible = 'off';
                end
            end
        end

        function updateScatterMulti(app, ax, data, color)
            % Function to round up to the nearest multiple of 100
            roundUpTo100 = @(x) ceil(x / 100) * 100;
            roundDownTo100 = @(x) floor(x / 100) * 100;

            % Calculate limits based on the full dataset
            maxData = max(app.Data1);
            minData = min(app.Data1);

            % Add padding (5% of the range or at least 100 nm)
            padding = max(roundUpTo100(0.05 * (maxData - minData)), 100);

            % Calculate padded and rounded limits
            XLimit = [roundDownTo100(minData(1)) - padding(1), roundUpTo100(maxData(1)) + padding(1)];
            YLimit = [roundDownTo100(minData(2)) - padding(2), roundUpTo100(maxData(2)) + padding(2)];
            ZLimit = [roundDownTo100(minData(3)) - padding(3), roundUpTo100(maxData(3)) + padding(3)];

            % Calculate offsets for x and y to start from 0
            x_offset = XLimit(1);
            y_offset = YLimit(1);

            % Adjust the data
            adjusted_data = data;
            adjusted_data(:, 1) = adjusted_data(:, 1) - x_offset;
            adjusted_data(:, 2) = adjusted_data(:, 2) - y_offset;

            % Check if scatter plot already exists for this color
            existingScatter = findobj(ax, 'Type', 'scatter', 'CData', color);

            if isempty(existingScatter)
                % Create new scatter plot
                scatter3(ax, adjusted_data(:, 1), adjusted_data(:, 2), adjusted_data(:, 3), 20, color, 'filled');
            else
                % Update existing scatter plot
                set(existingScatter, 'XData', adjusted_data(:, 1), 'YData', adjusted_data(:, 2), 'ZData', adjusted_data(:, 3));
                % Update color data
                set(existingScatter, 'CData', repmat(color, size(adjusted_data, 1), 1));
            end

            % Update axis limits
            currentXLim = xlim(ax);
            currentYLim = ylim(ax);
            currentZLim = zlim(ax);

            newXLim = [0, max(currentXLim(2), XLimit(2) - x_offset)];
            newYLim = [0, max(currentYLim(2), YLimit(2) - y_offset)];
            newZLim = [min(currentZLim(1), ZLimit(1)), max(currentZLim(2), ZLimit(2))];

            % Ensure minimum size of 1000x1000x1000 nm
            newXLim(2) = max(newXLim(2), 1000);
            newYLim(2) = max(newYLim(2), 1000);
            newZLim = [min(newZLim(1), -500), max(newZLim(2), 500)];

            % Round limits to nice values
            newXLim(2) = roundUpTo100(newXLim(2));
            newYLim(2) = roundUpTo100(newYLim(2));
            newZLim(1) = roundDownTo100(newZLim(1));
            newZLim(2) = roundUpTo100(newZLim(2));

            xlim(ax, newXLim);
            ylim(ax, newYLim);
            zlim(ax, newZLim);

            % Set tick marks with 500 nm spacing
            xticks(ax, 0:500:newXLim(2));
            yticks(ax, 0:500:newYLim(2));
            zticks(ax, newZLim(1):500:newZLim(2));

            % Update app properties for global limits
            app.GlobalXLim = [x_offset, newXLim(2) + x_offset];
            app.GlobalYLim = [y_offset, newYLim(2) + y_offset];
            app.GlobalZLim = newZLim;
            app.GlobalXTicks = get(ax, 'XTick') + x_offset;
            app.GlobalYTicks = get(ax, 'YTick') + y_offset;
            app.GlobalZTicks = get(ax, 'ZTick');
        end

        function rgb = getChannelColor(app, channelNumber)
            % Defensive fetch
            if channelNumber <= numel(app.ChannelColors) && ~isempty(app.ChannelColors{channelNumber})
                colorNameOrRGB = app.ChannelColors{channelNumber};
            else
                colorNameOrRGB = 'k'; % fallback
            end

            % 1) If it's already an RGB triplet, normalize and return
            if isnumeric(colorNameOrRGB) && numel(colorNameOrRGB) == 3
                rgb = double(colorNameOrRGB(:)).';        % row vector
                rgb = max(0, min(1, rgb));                % clamp to [0,1]
                return;
            end

            % 2) If it’s a string/char, map it to RGB
            if isstring(colorNameOrRGB) || ischar(colorNameOrRGB)
                c = lower(char(colorNameOrRGB));
                switch c
                    case {'k','black'},   rgb = [0 0 0];
                    case {'w','white'},   rgb = [1 1 1];
                    case {'r','red'},     rgb = [1 0 0];
                    case {'g','green'},   rgb = [0 1 0];
                    case {'b','blue'},    rgb = [0 0 1];
                    case {'c','cyan'},    rgb = [0 1 1];
                    case {'m','magenta'}, rgb = [1 0 1];
                    case {'y','yellow'},  rgb = [1 1 0];


                    otherwise
                        % last-resort: use your existing mapper (handles names like 'spring', etc.)
                        try
                            rgb = app.getColor(c); % you already call this elsewhere.
                        catch
                            rgb = [0 0 0]; % safe fallback
                        end
                end
                return;
            end

            % 3) Anything else → fallback to black
            rgb = [0 0 0];
        end

        function dataOut = applyOffset(app, dataIn, channelNum)
            xField = ['X', num2str(channelNum), 'Field'];
            yField = ['Y', num2str(channelNum), 'Field'];
            zField = ['Z', num2str(channelNum), 'Field'];

            xOffset = app.(xField).Value;
            yOffset = app.(yField).Value;
            zOffset = app.(zField).Value;

            dataOut = dataIn;
            dataOut(:,1) = dataOut(:,1) + xOffset;
            dataOut(:,2) = dataOut(:,2) + yOffset;
            dataOut(:,3) = dataOut(:,3) + zOffset;
        end


        function [clusterVolumes, clusterSurfaces] = calculateClusterVolumes(app)
            % Determine valid clusters
            validClusters = unique(app.Clusters(app.Clusters ~= 0));

            % Apply exclusion threshold if enabled
            if app.EnableExclusion.Value
                exclusionThreshold = app.ExclNum.Value;
                validClusters = validClusters(arrayfun(@(x) sum(app.Clusters == x), validClusters) >= exclusionThreshold);
            end

            numValidClusters = numel(validClusters);
            clusterVolumes = zeros(numValidClusters, 1);
            clusterSurfaces = zeros(numValidClusters, 1);

            for i = 1:numValidClusters
                clusterIndex = validClusters(i);
                clusterPoints = app.Data(app.Clusters == clusterIndex & app.IsNoise == 0, :);

                if size(clusterPoints, 1) > 3
                    [K, volume] = convhull(clusterPoints(:, 1), clusterPoints(:, 2), clusterPoints(:, 3));
                    clusterVolumes(i) = volume;
                    clusterSurfaces(i) = calculateSurfaceArea(app, clusterPoints, K);
                else
                    clusterVolumes(i) = NaN;
                    clusterSurfaces(i) = NaN;
                end
            end

            % Store the computed volumes and surfaces in the app properties
            app.ClusterVolumes = clusterVolumes;
            app.ClusterSurfaces = clusterSurfaces;

            % Update the UI to display cluster volumes
            volumeStrings = arrayfun(@(x, y) sprintf('Cluster %d: %.5f', x, y), validClusters, clusterVolumes, 'UniformOutput', false);
            app.Volumeinnm3ListBox.Items = volumeStrings;
        end


        function plot3DHeatMap(app, data)
            % Ensure data is not empty and is formatted correctly
            if isempty(data)
                uialert(app.DBSCAN, 'No data to display.', 'Data Error');
                return;
            end

            % Define the grid dimensions and prepare the 3D matrix
            gridSize = 100;  % Higher grid size for better resolution
            V = zeros(gridSize, gridSize, gridSize);

            % Normalize and scale the data points to fit within the grid
            minData = min(data, [], 1);
            maxData = max(data, [], 1);
            dataScaled = bsxfun(@minus, data, minData);
            dataScaled = bsxfun(@rdivide, dataScaled, maxData - minData);
            dataScaled = bsxfun(@times, dataScaled, gridSize - 1) + 1;

            % Voxel accumulation
            indices = floor(dataScaled);
            indices(indices < 1) = 1;
            indices(indices > gridSize) = gridSize;
            for idx = 1:size(data, 1)
                linearIdx = sub2ind(size(V), indices(idx, 1), indices(idx, 2), indices(idx, 3));
                V(linearIdx) = V(linearIdx) + 1;
            end
            % Apply Gaussian smoothing
            V = imgaussfilt3(V, 2);  % Apply Gaussian smoothing with sigma = 2

            % Create a new figure for 3D heatmap if it doesn't exist, or bring it to front if it does
            if isempty(app.figureHandle3D) || ~isvalid(app.figureHandle3D)
                app.figureHandle3D = figure('Name', '3D Heat Map');
            else
                figure(app.figureHandle3D);
            end

            % Visualize with volshow
            h = volshow(V, 'Colormap', hot(256));

            % Adjust alpha map for opacity
            gradient = linspace(0, 1, 256);  % Create a linear gradient
            h.Alphamap = gradient;  % Use a quadratic gradient for smoother transparency

            title('Enhanced 3D Heat Map Visualization');

            % Background color set on the figure
            app.figureHandle3D.Color = 'w';
            h.RenderingStyle = 'VolumeRendering';

            % Call plotHeatMap to create/update 2D projection
            plotHeatMap(app);
        end

        function plotHeatMap(app)
            if isempty(app.allCenteredPoints)
                uialert(app.DBSCAN, 'No data to display.', 'Data Error');
                return;
            end

            xyProjection = app.allCenteredPoints(:, 1:2);
            [counts, centers] = hist3(xyProjection, 'Edges', ...
                {linspace(min(xyProjection(:,1)), max(xyProjection(:,1)), 50), ...
                linspace(min(xyProjection(:,2)), max(xyProjection(:,2)), 50)});

            % Create a new figure for 2D projection if it doesn't exist, or bring it to front if it does
            if isempty(app.figureHandle2D) || ~isvalid(app.figureHandle2D)
                app.figureHandle2D = figure('Name', '2D Heat Map Projection');
            else
                figure(app.figureHandle2D);
            end

            % Clear the current axes and plot the new data
            clf(app.figureHandle2D);
            ax = axes('Parent', app.figureHandle2D);
            imagesc(ax, centers{1}, centers{2}, counts');
            colormap(ax, 'hot');
            colorbar('peer', ax);
            title(ax, 'Heat Map of XY Projection');
            xlabel(ax, 'X Coordinate');
            ylabel(ax, 'Y Coordinate');
            axis(ax, 'equal');
        end

        function PolarMeasurement(app, ~)
    selectedClusters = str2double(app.ListBox2_2.Value);

    delete(app.Panel.Children);

    pax = polaraxes(app.Panel);
    hold(pax, 'on'); % Hold the axes to plot multiple clusters

    % Use dynamic radial limit based on MaxAnalysisDistance
    maxRadialDist = app.MaxAnalysisDistance;
    rlim(pax, [0 maxRadialDist]);
    
    fprintf('Polar plot analysis: radial range 0 to %d nm\n', maxRadialDist);

    allDistances = [];
    allAzimuths = [];

    % Determine which data to use based on the ButtonGroup selection
    if app.trcButton.Value
        app.calculateTraceData();  % Calculate trace data if not already done
        mainData = app.trace_data1;
        data2 = app.trace_data2;
        data3 = app.trace_data3;
        data4 = app.trace_data4;
        data5 = app.trace_data5;
        clusterAssignments = app.trace_clusters1;
        isNoise = clusterAssignments == 0;  % Assuming 0 represents noise in trace clusters
    else
        mainData = app.Data1;
        data2 = app.Data2_off;
        data3 = app.Data3_off;
        data4 = app.Data4_off;
        data5 = app.Data5_off;
        clusterAssignments = app.Clusters1;
        isNoise = app.IsNoise1;
    end

    % Storage for cross-channel centroid analysis
    clusterCentroidAnalysis = struct();

    for i = 1:numel(selectedClusters)
        clusterIndex = selectedClusters(i);
        clusterPoints = mainData(clusterAssignments == clusterIndex & ~isNoise, :);

        centroid = mean(clusterPoints, 1);
        centeredPoints = clusterPoints - centroid;

        % Initialize centroid storage for this cluster
        clusterCentroidAnalysis(i).clusterIndex = clusterIndex;
        clusterCentroidAnalysis(i).channelCentroids = [];
        clusterCentroidAnalysis(i).channelNames = {};
        clusterCentroidAnalysis(i).channelPointCounts = [];

        % Store main channel centroid
        clusterCentroidAnalysis(i).channelCentroids = [clusterCentroidAnalysis(i).channelCentroids; centroid];
        clusterCentroidAnalysis(i).channelNames{end+1} = 'Main Channel';
        clusterCentroidAnalysis(i).channelPointCounts(end+1) = size(clusterPoints, 1);

        if app.ColorCheck.Value
            color = [0.3, 0.3, 0.3];
            color2 = [0, 0.4470, 0.7410];
            color3 = [0.9020, 0.6235, 0];
            color4 = [0, 0.6196, 0.4510];
            color5 = [0.8000, 0.4745, 0.6549];
        else
            color = app.ClusterColors(clusterIndex, :);
            color2 = 'cyan';
            color3 = 'red';
            color4 = 'magenta';
            color5 = 'green';
        end

        distances = sqrt(sum(centeredPoints.^2, 2));
        azimuth = atan2(centeredPoints(:,2), centeredPoints(:,1));  % Azimuth angle in radians

        allDistances = [allDistances; distances];

        % Plot the polar scatter for the first channel
        plotline = polarscatter(pax, azimuth, distances, 'o', 'MarkerEdgeColor', color, 'DisplayName', ['Cluster ', num2str(clusterIndex)]);
        plotline.LineWidth = 1;

        % Update radial tick marks dynamically based on maxRadialDist
        if maxRadialDist <= 150
            tickSpacing = 25;
        elseif maxRadialDist <= 300
            tickSpacing = 50;
        elseif maxRadialDist <= 600
            tickSpacing = 100;
        else
            tickSpacing = 150;
        end
        
        rticks(pax, 0:tickSpacing:maxRadialDist);
        rticklabels(pax, cellstr(num2str((0:tickSpacing:maxRadialDist)', '%d')));

        % Optionally, set the theta axis labels to degrees
        thetaticks(pax, 0:45:315);
        thetaticklabels(pax, {'0°', '45°', '90°', '135°', '180°', '225°', '270°', '315°'});

        % Collect and plot second channel data if available
        if ~isempty(data2)
            distances2 = sqrt(sum((data2 - centroid).^2, 2));
            azimuth2 = atan2(data2(:,2) - centroid(2), data2(:,1) - centroid(1));
            vicinity_threshold = 500; % Define the vicinity threshold
            vicinity_indices = distances2 <= vicinity_threshold;

            distances2_vicinity = distances2(vicinity_indices);
            azimuth2_vicinity = azimuth2(vicinity_indices);
            data2_vicinity = data2(vicinity_indices, :);

            % Calculate centroid for channel 2 data within vicinity
            if ~isempty(data2_vicinity)
                centroid2 = mean(data2_vicinity, 1);
                clusterCentroidAnalysis(i).channelCentroids = [clusterCentroidAnalysis(i).channelCentroids; centroid2];
                clusterCentroidAnalysis(i).channelNames{end+1} = app.filename2;
                clusterCentroidAnalysis(i).channelPointCounts(end+1) = size(data2_vicinity, 1);
            end

            % Plot the polar scatter for the second channel
            plotline2 = polarscatter(pax, azimuth2_vicinity, distances2_vicinity, 'o', 'MarkerEdgeColor', color2, 'DisplayName', app.filename2);
            plotline2.LineWidth = 1;
        end

        % Collect and plot third channel data if available
        if ~isempty(data3)
            distances3 = sqrt(sum((data3 - centroid).^2, 2));
            azimuth3 = atan2(data3(:,2) - centroid(2), data3(:,1) - centroid(1));
            vicinity_threshold = 500; % Define the vicinity threshold
            vicinity_indices = distances3 <= vicinity_threshold;

            distances3_vicinity = distances3(vicinity_indices);
            azimuth3_vicinity = azimuth3(vicinity_indices);
            data3_vicinity = data3(vicinity_indices, :);

            % Calculate centroid for channel 3 data within vicinity
            if ~isempty(data3_vicinity)
                centroid3 = mean(data3_vicinity, 1);
                clusterCentroidAnalysis(i).channelCentroids = [clusterCentroidAnalysis(i).channelCentroids; centroid3];
                clusterCentroidAnalysis(i).channelNames{end+1} = app.filename3;
                clusterCentroidAnalysis(i).channelPointCounts(end+1) = size(data3_vicinity, 1);
            end

            % Plot the polar scatter for the third channel
            plotline3 = polarscatter(pax, azimuth3_vicinity, distances3_vicinity, 'o', 'MarkerEdgeColor', color3, 'DisplayName', app.filename3);
            plotline3.LineWidth = 1;
        end

        % Collect and plot fourth channel data if available
        if ~isempty(data4)
            distances4 = sqrt(sum((data4 - centroid).^2, 2));
            azimuth4 = atan2(data4(:,2) - centroid(2), data4(:,1) - centroid(1));
            vicinity_threshold = 500; % Define the vicinity threshold
            vicinity_indices = distances4 <= vicinity_threshold;

            distances4_vicinity = distances4(vicinity_indices);
            azimuth4_vicinity = azimuth4(vicinity_indices);
            data4_vicinity = data4(vicinity_indices, :);

            % Calculate centroid for channel 4 data within vicinity
            if ~isempty(data4_vicinity)
                centroid4 = mean(data4_vicinity, 1);
                clusterCentroidAnalysis(i).channelCentroids = [clusterCentroidAnalysis(i).channelCentroids; centroid4];
                clusterCentroidAnalysis(i).channelNames{end+1} = app.filename4;
                clusterCentroidAnalysis(i).channelPointCounts(end+1) = size(data4_vicinity, 1);
            end

            % Plot the polar scatter for the fourth channel
            plotline4 = polarscatter(pax, azimuth4_vicinity, distances4_vicinity, 'o', 'MarkerEdgeColor', color4, 'DisplayName', app.filename4);
            plotline4.LineWidth = 1;
        end

        % Collect and plot fifth channel data if available
        if ~isempty(data5)
            distances5 = sqrt(sum((data5 - centroid).^2, 2));
            azimuth5 = atan2(data5(:,2) - centroid(2), data5(:,1) - centroid(1));
            vicinity_threshold = 500; % Define the vicinity threshold
            vicinity_indices = distances5 <= vicinity_threshold;

            distances5_vicinity = distances5(vicinity_indices);
            azimuth5_vicinity = azimuth5(vicinity_indices);
            data5_vicinity = data5(vicinity_indices, :);

            % Calculate centroid for channel 5 data within vicinity
            if ~isempty(data5_vicinity)
                centroid5 = mean(data5_vicinity, 1);
                clusterCentroidAnalysis(i).channelCentroids = [clusterCentroidAnalysis(i).channelCentroids; centroid5];
                clusterCentroidAnalysis(i).channelNames{end+1} = app.filename5;
                clusterCentroidAnalysis(i).channelPointCounts(end+1) = size(data5_vicinity, 1);
            end

            % Plot the polar scatter for the fifth channel
            plotline5 = polarscatter(pax, azimuth5_vicinity, distances5_vicinity, 'o', 'MarkerEdgeColor', color5, 'DisplayName', app.filename5);
            plotline5.LineWidth = 1;
        end
    end

    % Analyze cross-channel centroid distances
    fprintf('\n=== CROSS-CHANNEL CENTROID ANALYSIS ===\n');

    for i = 1:length(clusterCentroidAnalysis)
        clusterData = clusterCentroidAnalysis(i);
        numChannels = size(clusterData.channelCentroids, 1);

        fprintf('\nCluster %d Analysis:\n', clusterData.clusterIndex);
        fprintf('Channels detected: %d\n', numChannels);

        if numChannels > 1
            % Calculate pairwise distances between channel centroids
            centroidDistances = pdist(clusterData.channelCentroids);
            centroidDistanceMatrix = squareform(centroidDistances);

            % Statistics
            meanCentroidDistance = mean(centroidDistances);
            stdCentroidDistance = std(centroidDistances);
            maxCentroidDistance = max(centroidDistances);

            fprintf('Cross-channel centroid distances:\n');
            fprintf('  Mean: %.2f ± %.2f nm\n', meanCentroidDistance, stdCentroidDistance);
            fprintf('  Maximum: %.2f nm\n', maxCentroidDistance);

            % Detailed pairwise distances
            fprintf('  Pairwise distances:\n');
            for j = 1:numChannels
                for k = j+1:numChannels
                    distance = centroidDistanceMatrix(j, k);
                    fprintf('    %s ↔ %s: %.2f nm (%d vs %d points)\n', ...
                        clusterData.channelNames{j}, clusterData.channelNames{k}, ...
                        distance, clusterData.channelPointCounts(j), clusterData.channelPointCounts(k));
                end
            end

            % Store results in cluster data
            clusterCentroidAnalysis(i).crossChannelDistances = centroidDistances;
            clusterCentroidAnalysis(i).crossChannelDistanceMatrix = centroidDistanceMatrix;
            clusterCentroidAnalysis(i).meanCrossChannelDistance = meanCentroidDistance;
            clusterCentroidAnalysis(i).stdCrossChannelDistance = stdCentroidDistance;
            clusterCentroidAnalysis(i).maxCrossChannelDistance = maxCentroidDistance;

        else
            fprintf('  Only one channel detected - no cross-channel analysis possible.\n');
            clusterCentroidAnalysis(i).crossChannelDistances = [];
            clusterCentroidAnalysis(i).meanCrossChannelDistance = NaN;
        end
    end

    % Update display with cross-channel information
    if isscalar(clusterCentroidAnalysis) && size(clusterCentroidAnalysis(1).channelCentroids, 1) > 1
        % Single cluster with multiple channels
        meanDistance = mean(allDistances);
        standardError = std(allDistances) / sqrt(length(allDistances));
        crossChannelMean = clusterCentroidAnalysis(1).meanCrossChannelDistance;
        crossChannelStd = clusterCentroidAnalysis(1).stdCrossChannelDistance;

        app.MeanDistLabel2.Text = sprintf(['Mean Distance: %.2f ± %.2f nm\n' ...
            'Cross-channel centroid: %.2f ± %.2f nm'], ...
            meanDistance, standardError, ...
            crossChannelMean, crossChannelStd);
    elseif length(clusterCentroidAnalysis) > 1
        % Multiple clusters
        allCrossChannelDistances = [];
        for i = 1:length(clusterCentroidAnalysis)
            if ~isempty(clusterCentroidAnalysis(i).crossChannelDistances)
                allCrossChannelDistances = [allCrossChannelDistances; clusterCentroidAnalysis(i).crossChannelDistances'];
            end
        end

        meanDistance = mean(allDistances);
        standardError = std(allDistances) / sqrt(length(allDistances));

        if ~isempty(allCrossChannelDistances)
            overallCrossChannelMean = mean(allCrossChannelDistances);
            overallCrossChannelStd = std(allCrossChannelDistances);
            app.MeanDistLabel2.Text = sprintf(['Mean Distance: %.2f ± %.2f nm\n' ...
                'Avg cross-channel: %.2f ± %.2f nm'], ...
                meanDistance, standardError, ...
                overallCrossChannelMean, overallCrossChannelStd);
        else
            app.MeanDistLabel2.Text = sprintf('Mean Distance: %.2f ± %.2f nm\nNo multi-channel data', ...
                meanDistance, standardError);
        end
    else
        % Single cluster, single channel
        meanDistance = mean(allDistances);
        standardError = std(allDistances) / sqrt(length(allDistances));
        app.MeanDistLabel2.Text = sprintf('Mean Distance: %.2f ± %.2f nm\nSingle channel only', ...
            meanDistance, standardError);
    end

    % Store analysis results in app for further use
    app.CrossChannelCentroidAnalysis = clusterCentroidAnalysis;

    % Set title for the polar plot with dynamic range
    title(pax, sprintf('Ring Analysis from Centroid (0-%d nm)', maxRadialDist));
    hold(pax, 'off');
    legend(pax, 'location', 'northwest')
end


        function [x, y, z, t] = extractBeadMovementNormalized(app, localizations)
            x = [];
            y = [];
            z = [];
            t = [];
            for i = 1:numel(localizations)
                pos = localizations(i).pos;
                x = [x; pos(1)];
                y = [y; pos(2)];
                z = [z; pos(3)];
                t = [t; localizations(i).tim]; % Assuming 'tim' records the time stamps
            end
            % Normalize to the first localization
            x = (x - x(1)) * 1e9; % Convert to nm
            y = (y - y(1)) * 1e9; % Convert to nm
            z = (z - z(1)) * 1e9; % Convert to nm
            t = t - t(1); % Normalize time to start at 0
        end

        function visualizeBeadMovement(app, tempText)
            % Create and show loading dialog
            loadingDialog = uiprogressdlg(app.DBSCAN, 'Title', 'Loading...', ...
                'Message', 'Preparing bead movement visualization. This may take a moment...', ...
                'Indeterminate','on');

            try
                stainingFields = fieldnames(app.Zarr);
                colors = lines(length(stainingFields));

                % Calculate max observation time for each staining
                maxObservationTimes = containers.Map();
                for i = 1:length(stainingFields)
                    stainingName = stainingFields{i};
                    beadFields = fieldnames(app.Zarr.(stainingName));
                    maxTime = 0;
                    for j = 1:length(beadFields)
                        beadName = beadFields{j};
                        localizations = app.Zarr.(stainingName).(beadName);
                        [~, ~, ~, t] = app.extractBeadMovementNormalized(localizations);
                        maxTime = max(maxTime, max(t));
                    end
                    maxObservationTimes(stainingName) = maxTime;
                end

                % Count total number of beads
                totalBeads = 0;
                for i = 1:length(stainingFields)
                    stainingName = stainingFields{i};
                    beadFields = fieldnames(app.Zarr.(stainingName));
                    totalBeads = totalBeads + length(beadFields);
                end

                % Calculate grid layout
                cols = 3;  % Fixed 3 columns
                rows = ceil(totalBeads / cols);

                % Calculate axes size
                axesWidth = 200;  % Fixed width for each plot
                axesHeight = 200;  % Fixed height for each plot
                hSpacing = 20;  % Horizontal spacing between plots
                vSpacing = 20;  % Vertical spacing between plots

                % Calculate content size
                contentWidth = cols * (axesWidth + hSpacing) + hSpacing;
                contentHeight = rows * (axesHeight + vSpacing) + vSpacing;

                beadCount = 0;
                for i = 1:length(stainingFields)
                    stainingName = stainingFields{i};
                    beadFields = fieldnames(app.Zarr.(stainingName));

                    for j = 1:length(beadFields)
                        beadCount = beadCount + 1;
                        beadName = beadFields{j};

                        % Calculate position for this axes
                        col = mod(beadCount - 1, cols);
                        row = floor((beadCount - 1) / cols);
                        left = hSpacing + col * (axesWidth + hSpacing);
                        bottom = contentHeight - (row + 1) * (axesHeight + vSpacing);

                        % Create new axes for this bead
                        ax = uiaxes(app.StabilizationTab, 'Position', [left, bottom, axesWidth, axesHeight]);

                        hold(ax, 'on');

                        localizations = app.Zarr.(stainingName).(beadName);
                        [x, y, z, t] = app.extractBeadMovementNormalized(localizations);

                        plot(ax, t, x, 'Color', colors(i,:), 'LineStyle', '-', 'DisplayName', 'X');
                        plot(ax, t, y, 'Color', colors(i,:), 'LineStyle', '--', 'DisplayName', 'Y');
                        plot(ax, t, z, 'Color', colors(i,:), 'LineStyle', ':', 'DisplayName', 'Z');

                        xlabel(ax, 'Time (s)');
                        ylabel(ax, 'Deviation (nm)');
                        title(ax, sprintf('%s - %s', stainingName, beadName), 'Interpreter', 'none');
                        legend(ax, 'show', 'Location', 'northeast');
                        grid(ax, 'on');

                        % Set y-axis limits symmetrically
                        maxDeviation = max(abs([x; y; z]));
                        ylim(ax, [-maxDeviation*1.1, maxDeviation*1.1]);

                        % Set x-axis limit to the maximum observation time for this staining
                        xlim(ax, [0, maxObservationTimes(stainingName)]);

                        hold(ax, 'off');
                    end
                end

                % Set the tab's content size to enable scrolling
                app.StabilizationTab.UserData = struct('ContentSize', [contentWidth contentHeight]);

            catch e
                % Display error message
                errordlg(['An error occurred: ' e.message], 'Error');
            end

            % Close the loading dialog
            close(loadingDialog);

            % Delete the temporary text
            delete(tempText);
        end

        function updateMulticolorTabFromCorrections(app)
            for i = 1:length(app.ReferenceStainingDropDown.Items)
                channelName = ['Channel_', num2str(i)];
                displayChannelName = ['Channel ', num2str(i)];

                % Update ChannelFileNames
                app.ChannelFileNames.(channelName) = app.ReferenceStainingDropDown.Items{i};

                % Update ChannelEnableStates
                app.ChannelEnableStates.(channelName) = (i == 1);  % Only Channel 1 is enabled by default

                % Update ChannelCorrections
                if isfield(app.ChannelCorrections, channelName)
                    corrections = app.ChannelCorrections.(channelName);
                    % Check if the corrections structure has uppercase or lowercase field names
                    if isfield(corrections, 'X')
                        app.ChannelCorrections.(channelName) = struct('X', corrections.X, 'Y', corrections.Y, 'Z', corrections.Z);
                    elseif isfield(corrections, 'x')
                        app.ChannelCorrections.(channelName) = struct('X', corrections.x, 'Y', corrections.y, 'Z', corrections.z);
                    else
                        % If neither exists, initialize with zeros
                        app.ChannelCorrections.(channelName) = struct('X', 0, 'Y', 0, 'Z', 0);
                    end
                else
                    app.ChannelCorrections.(channelName) = struct('X', 0, 'Y', 0, 'Z', 0);
                end
            end

            % Update channel details for the first channel
            app.updateChannelDetails('Channel 1');

            % Update the ChannelListBox
            app.updateChannelListBox();
        end

        function updateAlignment(app)
            if isempty(fieldnames(app.ChannelCorrections))
                return;  % No correction data available
            end

            for i = 1:length(app.ChannelListBox.Items)
                channelName = ['Channel_', num2str(i)];
                dataField = ['Data', num2str(i)];
                dataFieldOff = [dataField, '_off'];

                if isfield(app.ChannelCorrections, channelName)
                    correctionX = app.ChannelCorrections.(channelName).X;
                    correctionY = app.ChannelCorrections.(channelName).Y;
                    correctionZ = app.ChannelCorrections.(channelName).Z;

                    % Apply corrections to the corresponding microscopy data if it exists
                    if ~isempty(app.(dataField))
                        app.(dataFieldOff) = app.(dataField);
                        app.(dataFieldOff)(:,1) = app.(dataFieldOff)(:,1) + correctionX;
                        app.(dataFieldOff)(:,2) = app.(dataFieldOff)(:,2) + correctionY;
                        app.(dataFieldOff)(:,3) = app.(dataFieldOff)(:,3) + correctionZ;
                    end
                end
            end

            % Update plots or other UI elements as needed
            app.updatePlots2();
        end

        function validatedBeads = validateBeads(app, data)
            validatedBeads = struct();
            stainingFields = fieldnames(data);
            for i = 1:numel(stainingFields)
                stainingName = stainingFields{i};
                staining = data.(stainingName);
                durations = [];
                beadFields = fieldnames(staining);
                for j = 1:numel(beadFields)
                    beadName = beadFields{j};
                    localizations = staining.(beadName);
                    timeStamps = [localizations.tim];
                    if isempty(timeStamps)
                        duration = 0;
                    else
                        duration = max(timeStamps) - min(timeStamps);
                    end
                    durations = [durations; duration];
                end

                % Threshold to identify beads with durations close to the maximum
                maxDuration = max(durations);
                threshold = maxDuration * 0.95;
                selectedBeads = beadFields(durations >= threshold);
                validatedBeads.(stainingName) = selectedBeads;
            end
        end

        function beadAlignmentData = storeBeadAlignmentData(app, data, validatedBeads)
            beadAlignmentData = struct();
            stainingFields = fieldnames(data);
            for i = 1:numel(stainingFields)
                stainingName = stainingFields{i};
                beadAlignmentData.(stainingName) = struct();
                validBeadsForStaining = validatedBeads.(stainingName);
                for j = 1:numel(validBeadsForStaining)
                    beadName = validBeadsForStaining{j};
                    localizations = data.(stainingName).(beadName);
                    if ~isempty(localizations)
                        % --- NEW: mean of first up to 5 positions ---
                        N = min(4, numel(localizations));
                        P = zeros(N,3);
                        for k = 1:N
                            P(k,:) = localizations(k).pos;   % in meters
                        end
                        pos = mean(P, 1) * 1e9;             % -> nm
                        pos(3) = pos(3) * 0.7;              % z‑correction factor (existing behaviour)
                        % -------------------------------------------
                        beadAlignmentData.(stainingName).(beadName) = pos;
                    end
                end
            end
        end

        % Modify the calculateCorrections function
        function calculateCorrections(app)
    referenceStainingName = app.ReferenceStainingDropDown.Value;
    stainingFields = app.ReferenceStainingDropDown.Items;
    
    % Initialize variables to collect RMSE summary
    rmseInfo = {};
    totalBeads = 0;
    
    for i = 1:numel(stainingFields)
        stainingName = stainingFields{i};
        channelName = ['Channel_', num2str(i)];

        if ~strcmp(stainingName, referenceStainingName)
            corrections = app.calculateCorrectionForStaining(app.BeadAlignmentData.(referenceStainingName), app.BeadAlignmentData.(stainingName));
            app.ChannelCorrections.(channelName) = corrections;
            
            % Add to RMSE summary
            if corrections.NBeads > 0
                rmseInfo{end+1} = sprintf('Ch%d: RMSE=[%.1f,%.1f,%.1f]nm (%d beads)', ...
                    i, corrections.RMSE(1), corrections.RMSE(2), corrections.RMSE(3), corrections.NBeads);
                totalBeads = totalBeads + corrections.NBeads;
            else
                rmseInfo{end+1} = sprintf('Ch%d: No common beads', i);
            end
        else
            app.ChannelCorrections.(channelName) = struct('X', 0, 'Y', 0, 'Z', 0, 'RMSE', [0 0 0], 'NBeads', 0);
            rmseInfo{end+1} = sprintf('Ch%d: Reference channel', i);
        end

        % Update ChannelFileNames
        app.ChannelFileNames.(channelName) = stainingName;
        
        % Print individual correction info (keep existing behavior)
        if isfield(app.ChannelCorrections.(channelName), 'RMSE')
            fprintf('Channel %d correction: [X %.2f nm, Y %.2f nm, Z %.2f nm], RMSE=[%.2f %.2f %.2f] nm over %d beads\n', ...
                i, app.ChannelCorrections.(channelName).X, app.ChannelCorrections.(channelName).Y, app.ChannelCorrections.(channelName).Z, ...
                app.ChannelCorrections.(channelName).RMSE(1), app.ChannelCorrections.(channelName).RMSE(2), app.ChannelCorrections.(channelName).RMSE(3), ...
                app.ChannelCorrections.(channelName).NBeads);
        end
    end
    
    % Update the RMSE_alignment label with summary information
    if totalBeads > 0
        % Create summary text from rmseInfo
        rmseText = sprintf('Alignment Summary:\n%s', strjoin(rmseInfo, '\n'));
    else
        rmseText = 'No alignment performed - insufficient bead data';
    end
    
    app.RMSE_alignment.Text = rmseText;
end

       function corrections = calculateCorrectionForStaining(app, referenceBeads, currentBeads)
    commonBeadNames = intersect(fieldnames(referenceBeads), fieldnames(currentBeads));
    dx = []; dy = []; dz = [];

    for i = 1:numel(commonBeadNames)
        b = commonBeadNames{i};
        refPos = referenceBeads.(b);     % [nm]
        curPos = currentBeads.(b);       % [nm]
        dx(end+1) = curPos(1) - refPos(1);
        dy(end+1) = curPos(2) - refPos(2);
        dz(end+1) = curPos(3) - refPos(3);
    end

    if isempty(dx)
        corrections = struct('X',0,'Y',0,'Z',0,'RMSE',[NaN NaN NaN],'NBeads',0);
        return;
    end

    % Mean shift (negative applied to align current to reference)
    mx = mean(dx); my = mean(dy); mz = mean(dz);

    % Residuals after applying the mean shift
    rx = dx - mx; ry = dy - my; rz = dz - mz;

    % RMSE of residuals (robustness metric)
    rmseX = sqrt(mean(rx.^2));
    rmseY = sqrt(mean(ry.^2));
    rmseZ = sqrt(mean(rz.^2));

    corrections = struct( ...
        'X', -mx, 'Y', -my, 'Z', -mz, ...       % keep existing sign convention
        'RMSE', [rmseX rmseY rmseZ], ...
        'NBeads', numel(dx));
end


        function visualizeBeadLocalizations(app)
            cla(app.LocalizationAxes);
            cla(app.DeviationAxes);

            hold(app.LocalizationAxes, 'on');
            hold(app.DeviationAxes, 'on');

            stainingFields = fieldnames(app.Zarr);
            referenceStainingName = app.ReferenceStainingDropDown.Value;

            % Reorder stainingFields to put reference staining first
            referenceIndex = find(strcmp(stainingFields, referenceStainingName));
            stainingFields = [stainingFields(referenceIndex); stainingFields(1:referenceIndex-1); stainingFields(referenceIndex+1:end)];

            colors = lines(numel(stainingFields));

            deviationData = struct('x', [], 'y', [], 'z', []);
            xTicks = [];

            for i = 1:numel(stainingFields)
                stainingName = stainingFields{i};
                staining = app.Zarr.(stainingName);
                beadFields = app.validatedBeads.(stainingName);

                allX = []; allY = []; allZ = [];
                for j = 1:numel(beadFields)
                    beadName = beadFields{j};
                    localizations = staining.(beadName);
                    for k = 1:numel(localizations)
                        pos = localizations(k).pos * 1e9;  % Convert to nm
                        allX(end+1) = pos(1);
                        allY(end+1) = pos(2);
                        allZ(end+1) = pos(3);
                    end
                end

                scatter3(app.LocalizationAxes, allX, allY, allZ, 20, colors(i,:), 'filled');

                deviationsX = [];
                deviationsY = [];
                deviationsZ = [];

                for j = 1:numel(beadFields)
                    beadName = beadFields{j};
                    if isfield(app.BeadAlignmentData.(referenceStainingName), beadName) && isfield(app.BeadAlignmentData.(stainingName), beadName)
                        refPos = app.BeadAlignmentData.(referenceStainingName).(beadName);
                        currentPos = app.BeadAlignmentData.(stainingName).(beadName);
                        deviationsX(end+1) = currentPos(1) - refPos(1);
                        deviationsY(end+1) = currentPos(2) - refPos(2);
                        deviationsZ(end+1) = currentPos(3) - refPos(3);
                    end
                end

                if strcmp(stainingName, referenceStainingName)
                    % For reference staining, all deviations are 0
                    deviationData.x = [deviationData.x; [0 0 0]];
                    deviationData.y = [deviationData.y; [0 0 0]];
                    deviationData.z = [deviationData.z; [0 0 0]];
                else
                    deviationData.x = [deviationData.x; [i-1 mean(deviationsX) std(deviationsX)/sqrt(length(deviationsX))]];
                    deviationData.y = [deviationData.y; [i-1 mean(deviationsY) std(deviationsY)/sqrt(length(deviationsY))]];
                    deviationData.z = [deviationData.z; [i-1 mean(deviationsZ) std(deviationsZ)/sqrt(length(deviationsZ))]];
                end
                xTicks(end+1) = i-1;
            end

            xlabel(app.LocalizationAxes, 'X (nm)');
            ylabel(app.LocalizationAxes, 'Y (nm)');
            zlabel(app.LocalizationAxes, 'Z (nm)');
            title(app.LocalizationAxes, 'All Bead Localizations');
            legend(app.LocalizationAxes, stainingFields);
            grid(app.LocalizationAxes, 'on');
            view(app.LocalizationAxes, 3);

            errorbar(app.DeviationAxes, deviationData.x(:,1), deviationData.x(:,2), deviationData.x(:,3), 'r', 'LineWidth', 1);
            errorbar(app.DeviationAxes, deviationData.y(:,1), deviationData.y(:,2), deviationData.y(:,3), 'g', 'LineWidth', 1);
            errorbar(app.DeviationAxes, deviationData.z(:,1), deviationData.z(:,2), deviationData.z(:,3), 'b', 'LineWidth', 1);

            xlabel(app.DeviationAxes, 'Staining');
            ylabel(app.DeviationAxes, 'Deviation (nm)');
            title(app.DeviationAxes, 'Localization Deviation of Verified Beads');
            set(app.DeviationAxes, 'XTick', xTicks, 'XTickLabel', stainingFields);
            xtickangle(app.DeviationAxes, 45);
            legend(app.DeviationAxes, {'X', 'Y', 'Z'});
            grid(app.DeviationAxes, 'on');

            hold(app.LocalizationAxes, 'off');
            hold(app.DeviationAxes, 'off');
        end


        function updateMulticolorPlot(app)
            cla(app.UIAxes7);
            hold(app.UIAxes7, 'on');

            dataNames = fieldnames(app.MicroscopyData);
            colors = lines(length(dataNames));

            for i = 1:length(dataNames)
                dataName = dataNames{i};
                data = app.MicroscopyData.(dataName);
                scatter3(app.UIAxes7, data(:,1), data(:,2), data(:,3), 20, colors(i,:), 'filled');
            end

            hold(app.UIAxes7, 'off');
            legend(app.UIAxes7, dataNames);
            title(app.UIAxes7, 'Multicolor MINFLUX Data');
            xlabel(app.UIAxes7, 'X (nm)');
            ylabel(app.UIAxes7, 'Y (nm)');
            zlabel(app.UIAxes7, 'Z (nm)');
            view(app.UIAxes7, 3);
        end

        function ExportButtonPushed(app, event)
            % Improved export function with better error handling

            % Check if there's data to export
            if isempty(app.Data1)
                uialert(app.DBSCAN, 'No data loaded to export.', 'No Data');
                return;
            end

            % Prepare data for export
            dataFields = {'Data1', 'Data2_off', 'Data3_off', 'Data4_off', 'Data5_off'};
            channels = {'_first_channel.tif', '_second_channel.tif', '_third_channel.tif', '_fourth_channel.tif', '_fifth_channel.tif'};

            % Get original file paths
            originalFilePaths = cell(1, 5);
            for i = 1:5
                channelName = ['Channel_', num2str(i)];
                if isfield(app.ChannelFileNames, channelName)
                    % Get the original file path
                    if i == 1
                        originalFilePaths{i} = fullfile(app.filepath1, [app.filename1, '.mat']);
                    else
                        % For additional channels, we need to reconstruct the path
                        originalFilePaths{i} = app.ChannelFileNames.(channelName);
                    end
                end
            end

            exportData = cell(1, 5);

            % Ask user which formats to export
            choice = questdlg('Choose export format(s):', 'Export Options', ...
                'TIFF only', 'MAT only', 'Both', 'Both');

            if isempty(choice)
                return; % User cancelled
            end

            % Map choice to internal format
            switch choice
                case 'TIFF only'
                    exportOptions = 'TIFF';
                case 'MAT only'
                    exportOptions = 'MAT';
                case 'Both'
                    exportOptions = 'Both';
            end

            % Ask user for save directory
            saveDir = uigetdir(pwd, 'Select Directory to Save Exported Files');
            if isequal(saveDir, 0)
                return; % User cancelled
            end

            % For TIFF export, ask if user wants to customize filenames
            customTiffNames = false;
            if strcmp(exportOptions, 'TIFF') || strcmp(exportOptions, 'Both')
                answer = questdlg('Do you want to customize TIFF filenames?', 'TIFF Export Options', ...
                    'Use default names', 'Choose custom names', 'Use default names');
                customTiffNames = strcmp(answer, 'Choose custom names');
            end

            % Show progress dialog
            d = uiprogressdlg(app.DBSCAN, 'Title', 'Exporting Data', ...
                'Message', 'Preparing data for export...', 'Value', 0);

            try
                for i = 1:length(dataFields)
                    channelName = ['Channel_', num2str(i)];
                    if ~isempty(app.(dataFields{i})) && (i == 1 || (isfield(app.ChannelEnableStates, channelName) && app.ChannelEnableStates.(channelName)))

                        d.Message = sprintf('Processing Channel %d...', i);
                        d.Value = (i-1) / length(dataFields) * 0.5;

                        data = app.(dataFields{i});

                        % Determine if DBSCAN should be applied
                        applyDBSCAN = app.DBSCANCheckBox.Value && i > 1;

                        % Apply DBSCAN if the checkbox is checked (only for non-reference data)
                        if applyDBSCAN
                            epsilon = app.EpsilonSpinner.Value;
                            minPts = app.MinPtsSpinner.Value;
                            [idx] = dbscan(data, epsilon, minPts);
                            isNoise = (idx == -1);
                            data = data(~isNoise, :);
                        end

                        % Store data for TIFF export (convert nm to meters)
                        exportData{i} = data * 1e-9;

                        % Export as MAT if selected
                        if strcmp(exportOptions, 'MAT') || strcmp(exportOptions, 'Both')
                            if ~isempty(originalFilePaths{i})
                                app.saveCorrectedDataImproved(originalFilePaths{i}, data, applyDBSCAN, i, saveDir);
                            else
                                % Create a new MAT file if no original path exists
                                defaultName = sprintf('channel_%d_corrected.mat', i);
                                app.saveNewMatFile(fullfile(saveDir, defaultName), data, i);
                            end
                        end
                    end
                end

                % Export as TIFF if selected
                if strcmp(exportOptions, 'TIFF') || strcmp(exportOptions, 'Both')
                    d.Message = 'Creating TIFF files...';
                    d.Value = 0.6;
                    app.exportAsTIFFImproved(exportData, channels, d, saveDir, customTiffNames);
                end

                close(d);

                % Inform user that export is complete with the save location
                msgbox(sprintf('Export completed successfully!\n\nFiles have been saved to:\n%s', saveDir), 'Export Complete');

            catch ME
                close(d);
                errordlg(['Export failed: ' ME.message], 'Export Error');
                rethrow(ME);
            end
        end

        function saveCorrectedDataImproved(app, originalFilePath, data, isDBSCANEnabled, channelNum, saveDir)
            % Improved function to save corrected data

            try
                % Check if original file exists
                if ~exist(originalFilePath, 'file')
                    warning('Original file not found: %s', originalFilePath);
                    % Try to find the file in the current directory
                    [~, name, ext] = fileparts(originalFilePath);
                    if exist([name, ext], 'file')
                        originalFilePath = [name, ext];
                    else
                        error('Cannot locate original file for channel %d', channelNum);
                    end
                end

                % Extract filename and create new filename in the specified directory
                [~, name, ext] = fileparts(originalFilePath);

                % Create new filename based on whether DBSCAN was applied
                if isDBSCANEnabled
                    correctedFilename = fullfile(saveDir, [name, '_drift_corrected_DBSCAN', ext]);
                else
                    correctedFilename = fullfile(saveDir, [name, '_drift_corrected', ext]);
                end

                % Load the existing data structure
                S = load(originalFilePath);

                % Handle different data structures
                if isfield(S, 'loc') && isfield(S, 'tid') && isfield(S, 'tim')
                    % New format - direct fields
                    S_new = S;
                    S_new.loc = data' * 1e-9; % Convert back to meters and transpose

                    % Only undo the Z scaling factor (0.7), keep drift corrections
                    S_new.loc(3,:) = S_new.loc(3,:) / 0.7;

                elseif isfield(S, 'itr') && isfield(S.itr, 'loc')
                    % Old format - nested structure
                    S_new = S;

                    % Ensure data is in the correct format (n x 3)
                    if size(data, 2) ~= 3
                        data = reshape(data, [], 3);
                    end

                    if isDBSCANEnabled
                        % DBSCAN was applied, create filtered structure
                        S_new.itr.loc = zeros(size(data, 1), size(S.itr.loc, 2), size(S.itr.loc, 3));

                        % Copy structure for filtered points
                        originalData = squeeze(S.itr.loc(:,10,:)) * 1e9; % Convert to nm for comparison

                        for i = 1:size(data, 1)
                            % Find closest match in original data
                            distances = sum((originalData - data(i,:)).^2, 2);
                            [~, idx] = min(distances);
                            S_new.itr.loc(i,:,:) = S.itr.loc(idx,:,:);
                        end
                    else
                        % No DBSCAN filtering
                        S_new = S;
                    end

                    % Update the corrected positions (convert back to meters)
                    % The data already contains drift corrections, so we preserve them
                    S_new.itr.loc(:,10,1) = data(:,1) * 1e-9;
                    S_new.itr.loc(:,10,2) = data(:,2) * 1e-9;
                    S_new.itr.loc(:,10,3) = (data(:,3) / 0.7) * 1e-9; % Only undo Z scaling, keep drift corrections

                else
                    error('Unrecognized data structure in file: %s', originalFilePath);
                end

                % Save the corrected data
                save(correctedFilename, '-struct', 'S_new', '-v7.3');
                fprintf('Saved corrected data to: %s\n', correctedFilename);

            catch ME
                warning('Failed to save corrected data for channel %d: %s', channelNum, ME.message);
                rethrow(ME);
            end
        end

        function saveNewMatFile(app, filename, data, channelNum)
            % Save data in new format structure

            try
                % Create new structure with corrected data
                S = struct();
                S.loc = data' * 1e-9; % Convert to meters and transpose (data already contains corrections)
                S.loc(3,:) = S.loc(3,:) / 0.7; % Only undo Z scaling factor, keep drift corrections

                % Create dummy tid and tim if not available
                numPoints = size(data, 1);
                S.tid = 1:numPoints;
                S.tim = (1:numPoints) * 0.001; % 1ms intervals

                save(filename, '-struct', 'S', '-v7.3');
                fprintf('Saved new MAT file: %s\n', filename);

            catch ME
                warning('Failed to save new MAT file for channel %d: %s', channelNum, ME.message);
                rethrow(ME);
            end
        end

        function exportAsTIFFImproved(app, datasets, channels, progressDialog, saveDir, customNames)
            % Improved TIFF export function with fallback methods

            % Remove empty datasets
            validDatasets = {};
            validChannels = {};
            for i = 1:length(datasets)
                if ~isempty(datasets{i}) && size(datasets{i}, 1) > 0
                    validDatasets{end+1} = datasets{i};
                    validChannels{end+1} = channels{i};
                end
            end

            if isempty(validDatasets)
                error('No valid datasets to export');
            end

            % Combine all data to determine global boundaries
            allData = vertcat(validDatasets{:});

            if isempty(allData)
                error('All datasets are empty');
            end

            % Define saturation level
            sat = 0.01; % 1% saturation

            % Calculate boundaries using quantiles
            Rx = quantile(allData(:,1), [sat, 1-sat]);
            Ry = quantile(allData(:,2), [sat, 1-sat]);
            Rz = quantile(allData(:,3), [sat, 1-sat]);

            % Ensure minimum size (200nm)
            minSize = 200e-9;
            if diff(Rx) < minSize
                center = mean(Rx);
                Rx = [center - minSize/2, center + minSize/2];
            end
            if diff(Ry) < minSize
                center = mean(Ry);
                Ry = [center - minSize/2, center + minSize/2];
            end
            if diff(Rz) < minSize && diff(Rz) > 1e-12
                center = mean(Rz);
                Rz = [center - minSize/2, center + minSize/2];
            end

            % Voxel size (7nm)
            voxelSize = 7e-9;

            % Calculate grid dimensions
            Nx = max(50, ceil(diff(Rx) / voxelSize)); % Minimum 50 voxels
            Ny = max(50, ceil(diff(Ry) / voxelSize));
            Nz = max(10, ceil(diff(Rz) / voxelSize)); % Minimum 10 voxels for Z

            % Limit maximum size to prevent memory issues
            maxSize = 1000;
            Nx = min(Nx, maxSize);
            Ny = min(Ny, maxSize);
            Nz = min(Nz, maxSize);

            % Gaussian parameters
            sigma = 5e-9; % 5nm FWHM
            sigmaVoxels = sigma / voxelSize;

            % Gaussian kernel size (limit to reasonable size)
            kernelSize = min(10, ceil(3 * sigmaVoxels));

            fprintf('Exporting %d channels to TIFF...\n', length(validDatasets));
            fprintf('Grid size: %d x %d x %d\n', Nx, Ny, Nz);
            fprintf('Boundaries: X=[%.1f,%.1f]nm, Y=[%.1f,%.1f]nm, Z=[%.1f,%.1f]nm\n', ...
                Rx*1e9, Ry*1e9, Rz*1e9);

            for datasetIdx = 1:length(validDatasets)
                if ~isempty(progressDialog) && isvalid(progressDialog)
                    progressDialog.Value = 0.6 + (datasetIdx-1)/length(validDatasets) * 0.4;
                    progressDialog.Message = sprintf('Creating TIFF %d/%d...', datasetIdx, length(validDatasets));
                end

                currentData = validDatasets{datasetIdx};

                % Skip if no data
                if isempty(currentData) || size(currentData, 1) == 0
                    fprintf('Skipping empty dataset %d\n', datasetIdx);
                    continue;
                end

                % Try Gaussian rendering, fall back to simple binning if it fails
                try
                    volume = app.renderGaussianVolume(currentData, Rx, Ry, Rz, Nx, Ny, Nz, voxelSize, sigmaVoxels, kernelSize);
                catch ME
                    fprintf('Gaussian rendering failed for dataset %d: %s\n', datasetIdx, ME.message);
                    fprintf('Falling back to simple binning...\n');
                    volume = app.renderBinnedVolume(currentData, Rx, Ry, Rz, Nx, Ny, Nz, voxelSize);
                end

                % Normalize and convert to uint16
                maxVal = max(volume(:));
                if maxVal > 0
                    volume = volume / maxVal; % Normalize to [0,1]
                else
                    fprintf('Warning: Volume is empty for channel %d\n', datasetIdx);
                end
                volume_uint16 = uint16(volume * 65535);

                % Determine output filename
                if customNames
                    % Ask user for custom filename
                    defaultName = sprintf('channel_%d.tif', datasetIdx);
                    [fileName, ~] = uiputfile('*.tif', sprintf('Save TIFF for Channel %d', datasetIdx), defaultName);
                    if isequal(fileName, 0)
                        fprintf('Skipping channel %d (user cancelled)\n', datasetIdx);
                        continue;
                    end
                    outputFilename = fullfile(saveDir, fileName);
                else
                    % Use default filename
                    outputFilename = fullfile(saveDir, ['exported', validChannels{datasetIdx}]);
                end

                app.saveTiffStack(volume_uint16, outputFilename);

                fprintf('Saved: %s\n', outputFilename);
            end
        end

        function volume = renderGaussianVolume(app, data, Rx, Ry, Rz, Nx, Ny, Nz, voxelSize, sigmaVoxels, kernelSize)
            % Render volume with Gaussian blobs

            % Initialize volume
            volume = zeros(Ny, Nx, Nz, 'single'); % Note: Y, X, Z order for MATLAB

            % Convert positions to voxel indices
            px = (data(:,1) - Rx(1)) / voxelSize + 1;
            py = (data(:,2) - Ry(1)) / voxelSize + 1;
            pz = (data(:,3) - Rz(1)) / voxelSize + 1;

            % Filter points within bounds
            validPoints = px >= 1 & px <= Nx & py >= 1 & py <= Ny & pz >= 1 & pz <= Nz;
            px = px(validPoints);
            py = py(validPoints);
            pz = pz(validPoints);

            fprintf('Processing %d valid points with Gaussian rendering...\n', length(px));

            % Add each point with Gaussian distribution
            for i = 1:length(px)
                % Get fractional coordinates
                x_pos = px(i);
                y_pos = py(i);
                z_pos = pz(i);

                % Get integer coordinates for kernel center
                x0 = round(x_pos);
                y0 = round(y_pos);
                z0 = round(z_pos);

                % Define Gaussian kernel bounds with safety checks
                xMin = max(1, x0 - kernelSize);
                xMax = min(Nx, x0 + kernelSize);
                yMin = max(1, y0 - kernelSize);
                yMax = min(Ny, y0 + kernelSize);
                zMin = max(1, z0 - kernelSize);
                zMax = min(Nz, z0 + kernelSize);

                % Skip if bounds are invalid
                if xMin > xMax || yMin > yMax || zMin > zMax
                    continue;
                end

                % Create coordinate ranges
                x_range = xMin:xMax;
                y_range = yMin:yMax;
                z_range = zMin:zMax;

                % Create coordinate grids
                [X, Y, Z] = meshgrid(x_range, y_range, z_range);

                % Calculate Gaussian weights using actual positions
                weights = exp(-((X - x_pos).^2 + (Y - y_pos).^2 + (Z - z_pos).^2) / (2 * sigmaVoxels^2));

                % Ensure dimensions match before adding
                try
                    volume(y_range, x_range, z_range) = ...
                        volume(y_range, x_range, z_range) + weights;
                catch ME
                    % Debug information if there's still an error
                    fprintf('Error at point %d: pos=[%.2f,%.2f,%.2f], ranges=y(%d:%d),x(%d:%d),z(%d:%d), weights_size=[%d,%d,%d], volume_size=[%d,%d,%d]\n', ...
                        i, x_pos, y_pos, z_pos, yMin, yMax, xMin, xMax, zMin, zMax, ...
                        size(weights,1), size(weights,2), size(weights,3), size(volume,1), size(volume,2), size(volume,3));
                    rethrow(ME);
                end
            end
        end

        function volume = renderBinnedVolume(app, data, Rx, Ry, Rz, Nx, Ny, Nz, voxelSize)
            % Simple binning fallback method

            % Initialize volume
            volume = zeros(Ny, Nx, Nz, 'single');

            % Convert positions to voxel indices
            px = round((data(:,1) - Rx(1)) / voxelSize + 1);
            py = round((data(:,2) - Ry(1)) / voxelSize + 1);
            pz = round((data(:,3) - Rz(1)) / voxelSize + 1);

            % Filter points within bounds
            validPoints = px >= 1 & px <= Nx & py >= 1 & py <= Ny & pz >= 1 & pz <= Nz;
            px = px(validPoints);
            py = py(validPoints);
            pz = pz(validPoints);

            fprintf('Processing %d valid points with simple binning...\n', length(px));

            % Add each point to the appropriate voxel
            for i = 1:length(px)
                volume(py(i), px(i), pz(i)) = volume(py(i), px(i), pz(i)) + 1;
            end
        end

        function saveTiffStack(app, volume, filename)
            % Save 3D volume as TIFF stack

            try
                % Delete existing file if it exists
                if exist(filename, 'file')
                    delete(filename);
                end

                [height, width, depth] = size(volume);

                % Create TIFF object
                t = Tiff(filename, 'w');

                % Set up tags for first image
                tagstruct.ImageLength = height;
                tagstruct.ImageWidth = width;
                tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
                tagstruct.BitsPerSample = 16;
                tagstruct.SamplesPerPixel = 1;
                tagstruct.RowsPerStrip = height;
                tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
                tagstruct.Software = 'matFLUX';
                tagstruct.ImageDescription = sprintf('3D MINFLUX data stack, %d slices', depth);

                % Write each slice
                for z = 1:depth
                    t.setTag(tagstruct);
                    t.write(volume(:,:,z));

                    if z < depth
                        t.writeDirectory();
                    end
                end

                t.close();
                fprintf('Successfully saved TIFF stack: %s (%dx%dx%d)\n', filename, width, height, depth);

            catch ME
                error('Failed to save TIFF file %s: %s', filename, ME.message);
            end
        end
        function updateClusterInfo(app)
            % Get unique clusters (excluding noise which is typically 0)
            validClusters = unique(app.Clusters1(app.Clusters1 > 0));

            % Apply exclusion threshold if enabled
            if app.EnableExclusion.Value
                exclusionThreshold = app.ExclNum.Value;
                validClusters = validClusters(arrayfun(@(x) sum(app.Clusters1 == x), validClusters) >= exclusionThreshold);
            end

            numValidClusters = numel(validClusters);

            % Calculate traces if not already done
            app.calculateTraceData();

            % Calculate nearest neighbor distances
            nearestNeighborDistances = app.calculateNearestNeighborDistances();

            tableData = zeros(numValidClusters, 7);  % Now 7 columns to include radius

            % Initialize a cell array to store RDF data for each cluster
            app.ClusterRDFs = cell(numValidClusters, 1);

            for i = 1:numValidClusters
                clusterIndex = validClusters(i);

                % Count localizations
                locCount = sum(app.Clusters1 == clusterIndex);

                % Get cluster points
                clusterPoints = app.Data1(app.Clusters1 == clusterIndex & app.IsNoise1 == 0, :);

                % Calculate radius
                radius = app.calculateClusterRadius(clusterPoints);

                % Calculate volume and surface
                if size(clusterPoints, 1) > 3
                    [K, volume] = convhull(clusterPoints);
                    surface = app.calculateSurfaceArea(clusterPoints, K);
                else
                    volume = NaN;
                    surface = NaN;
                end

                % Count traces
                traceCount = sum(app.trace_clusters1 == clusterIndex);


                % Add all data to the table, including radius
                tableData(i, :) = [clusterIndex, locCount, traceCount, volume, surface, nearestNeighborDistances(i), radius];
            end

            % Update the table
            app.UITable.Data = tableData;
            app.UITable.ColumnName = {'Cluster', 'Localizations', 'Traces', 'Volume (nm³)', 'Surface (nm²)', 'Avg NN Dist (nm)', 'Radius (nm)'};

            % Store the results
            app.ClusterVolumes = tableData(:, 4);
            app.ClusterSurfaces = tableData(:, 5);
            app.ClusterRadii = tableData(:, 7);

            % Print debug information
            disp(['Total localizations: ', num2str(sum(tableData(:,2)))]);
            disp(['Total traces: ', num2str(sum(tableData(:,3)))]);
            disp(['Number of clusters: ', num2str(size(tableData,1))]);

            app.updateMultiChannelClusterInfo();
        end
        
        function averageNearestNeighborDistances = calculateNearestNeighborDistances(app)
            validClusters = unique(app.Clusters1(app.Clusters1 > 0));
            averageNearestNeighborDistances = zeros(length(validClusters), 1);

            for i = 1:length(validClusters)
                clusterIndex = validClusters(i);

                % Get traces for this cluster
                clusterTraces = app.trace_data1(app.trace_clusters1 == clusterIndex, :);

                if size(clusterTraces, 1) > 1
                    % Calculate pairwise distances between all traces in the cluster
                    distances = pdist(clusterTraces);

                    % Reshape distance matrix
                    distMatrix = squareform(distances);

                    % Set diagonal to inf to ignore self-distances
                    distMatrix(logical(eye(size(distMatrix)))) = inf;

                    % Find minimum distance for each trace (nearest neighbor)
                    minDistances = min(distMatrix, [], 2);

                    % Calculate average nearest neighbor distance for this cluster
                    averageNearestNeighborDistances(i) = mean(minDistances);
                else
                    % If cluster has only one trace, set distance to NaN
                    averageNearestNeighborDistances(i) = NaN;
                end
            end
        end

        function surfaceArea = calculateSurfaceArea(app, points, K)
            surfaceArea = 0;
            for i = 1:size(K, 1)
                % Triangle vertices
                pt1 = points(K(i, 1), :);
                pt2 = points(K(i, 2), :);
                pt3 = points(K(i, 3), :);

                % Triangle sides
                side1 = norm(pt1 - pt2);
                side2 = norm(pt2 - pt3);
                side3 = norm(pt3 - pt1);

                % Semi-perimeter
                s = (side1 + side2 + side3) / 2;

                % Area using Heron's formula
                surfaceArea = surfaceArea + sqrt(s * (s - side1) * (s - side2) * (s - side3));
            end
        end


        function radius = calculateClusterRadius(app, points)
            % Calculate the centroid (center of mass) of the cluster
            centroid = mean(points, 1);

            % Calculate squared distances from each point to the centroid
            squared_distances = sum((points - centroid).^2, 2);

            % Calculate the radius of gyration
            radius_of_gyration = sqrt(mean(squared_distances));

            % Calculate the standard deviation of distances
            std_distances = std(sqrt(squared_distances));

            % Calculate the skewness of the distance distribution
            skewness = mean((sqrt(squared_distances) - radius_of_gyration).^3) / std_distances^3;

            % Adjust the radius based on the skewness
            if skewness > 0
                % For positively skewed distributions (elongated clusters),
                % increase the radius slightly
                radius = radius_of_gyration * (1 + 0.1 * skewness);
            else
                % For negatively skewed or symmetric distributions,
                % use the radius of gyration as is
                radius = radius_of_gyration;
            end
        end

        function updateMultiChannelClusterInfo(app)
            % Get unique clusters (excluding noise which is typically 0)
            validClusters = unique(app.Clusters1(app.Clusters1 > 0));

            % Apply exclusion threshold if enabled
            if app.EnableExclusion.Value
                exclusionThreshold = app.ExclNum.Value;
                validClusters = validClusters(arrayfun(@(x) sum(app.Clusters1 == x), validClusters) >= exclusionThreshold);
            end

            numValidClusters = numel(validClusters);

            % Initialize column names and data arrays
            columnNames = {'Cluster', 'Count Ch1', 'Trace Count Ch1'};
            tableData = zeros(numValidClusters, 3);

            % Populate data for Channel 1 (main channel)
            for i = 1:numValidClusters
                clusterIndex = validClusters(i);
                tableData(i, 1) = clusterIndex;
                tableData(i, 2) = sum(app.Clusters1 == clusterIndex);
                tableData(i, 3) = sum(app.trace_clusters1 == clusterIndex);  % Add this line to include trace count for Channel 1
            end

            % Function to add channel data if it exists
            function addChannelData(channelNum, clusters, traceData, traceClusters)
                if ~isempty(clusters) && ~isempty(traceData)
                    columnNames = [columnNames, sprintf('Count Ch%d(Loc)', channelNum), sprintf('Count Ch%d(Trc)', channelNum)];
                    newData = zeros(numValidClusters, 2);
                    for j = 1:numValidClusters
                        clusterIndex = validClusters(j);
                        % Get the bounding box of the cluster in the main channel
                        clusterPoints = app.Data1(app.Clusters1 == clusterIndex, :);
                        if ~isempty(clusterPoints)
                            minBound = min(clusterPoints) - [app.BoxSizeX, app.BoxSizeY, app.BoxSizeZ]/2;
                            maxBound = max(clusterPoints) + [app.BoxSizeX, app.BoxSizeY, app.BoxSizeZ]/2;

                            % Count localizations within the bounding box
                            inBox = all(clusters >= minBound & clusters <= maxBound, 2);
                            newData(j, 1) = sum(inBox);

                            % Count traces within the bounding box
                            traceInBox = all(traceData >= minBound & traceData <= maxBound, 2);
                            newData(j, 2) = sum(traceInBox);
                        end
                    end
                    tableData = [tableData, newData];
                end
            end

            % Add data for additional channels if they exist
            addChannelData(2, app.Data2_off, app.trace_data2, app.trace_clusters2);
            addChannelData(3, app.Data3_off, app.trace_data3, app.trace_clusters3);
            addChannelData(4, app.Data4_off, app.trace_data4, app.trace_clusters4);
            addChannelData(5, app.Data5_off, app.trace_data5, app.trace_clusters5);

            % Update the table
            app.UITableMulti.Data = tableData;
            app.UITableMulti.ColumnName = columnNames;
        end


        function columnNames = generateColumnNames(app)
            columnNames = {'Cluster'};

            % Main channel
            mainName = 'Main';
            if ~isempty(app.FileNameLabel1.Text)
                mainName = app.extractFilename(app.FileNameLabel1.Text);
            end
            columnNames = [columnNames, {[mainName '(Loc)'], [mainName '(Trc)']}];

            % Other channels
            for i = 2:5
                labelField = ['FileNameLabel', num2str(i)];
                if isprop(app, labelField) && ~isempty(app.(labelField).Text)
                    channelName = app.extractFilename(app.(labelField).Text);
                    columnNames = [columnNames, {[channelName '(Loc)'], [channelName '(Trc)']}];
                end
            end
        end

        function name = extractFilename(app, labelText)
            if startsWith(labelText, 'File:')
                [~, name, ~] = fileparts(labelText(7:end));
            elseif contains(labelText, '(') && contains(labelText, ')')
                name = extractBetween(labelText, '(', ')');
                name = name{1};
            else
                name = labelText;
            end
        end


        function updateChannelDetails(app, displayChannelName)
            channelNumber = str2double(regexp(displayChannelName, '\d+', 'match'));
            channelName = ['Channel_', num2str(channelNumber)];

            app.ChannelNameLabel.Text = displayChannelName;

            if isfield(app.ChannelFileNames, channelName)
                app.ChannelFileLabel.Text = app.ChannelFileNames.(channelName);
            else
                app.ChannelFileLabel.Text = '';
            end

            if channelNumber == 1
                app.ChannelEnableCheckbox.Value = true;
                app.ChannelEnableCheckbox.Enable = 'off';
            else
                if isfield(app.ChannelEnableStates, channelName)
                    app.ChannelEnableCheckbox.Value = app.ChannelEnableStates.(channelName);
                else
                    app.ChannelEnableCheckbox.Value = true;  % Set to true by default for new channels
                end
                app.ChannelEnableCheckbox.Enable = 'on';
            end

            % Update correction fields with the stored values for the selected channel
            if isfield(app.ChannelCorrections, channelName)
                app.XCorrectionField.Value = app.ChannelCorrections.(channelName).X;
                app.YCorrectionField.Value = app.ChannelCorrections.(channelName).Y;
                app.ZCorrectionField.Value = app.ChannelCorrections.(channelName).Z;
            else
                app.XCorrectionField.Value = 0;
                app.YCorrectionField.Value = 0;
                app.ZCorrectionField.Value = 0;
            end

            % Enable correction fields for editing
            app.XCorrectionField.Enable = 'on';
            app.YCorrectionField.Enable = 'on';
            app.ZCorrectionField.Enable = 'on';
        end

        function UpdateCorrection(app, correctionType, newValue)
            selectedChannel = app.ChannelListBox.Value;
            channelNumber = str2double(regexp(selectedChannel, '\d+', 'match'));
            channelName = ['Channel_', num2str(channelNumber)];

            % Ensure newValue is a number
            if isnan(newValue)
                warning('Invalid correction value. Please enter a number.');
                return;
            end

            % Update the stored correction value for the selected channel
            if ~isfield(app.ChannelCorrections, channelName)
                app.ChannelCorrections.(channelName) = struct('X', 0, 'Y', 0, 'Z', 0);
            end
            app.ChannelCorrections.(channelName).(correctionType) = newValue;

            % Apply the correction to the data
            dataField = ['Data', num2str(channelNumber)];
            dataFieldOff = [dataField, '_off'];

            if ~isempty(app.(dataField))
                app.(dataFieldOff) = app.(dataField);
                app.(dataFieldOff)(:,1) = app.(dataFieldOff)(:,1) + app.ChannelCorrections.(channelName).X;
                app.(dataFieldOff)(:,2) = app.(dataFieldOff)(:,2) + app.ChannelCorrections.(channelName).Y;
                app.(dataFieldOff)(:,3) = app.(dataFieldOff)(:,3) + app.ChannelCorrections.(channelName).Z;
            end

            % Update the visualization
            app.updatePlots2();
            app.updateAlignment();


        end

        function suffix = getChannelSuffix(app, channelNumber)
            suffixes = {'Sec', 'Trd', 'Qrt', 'Qnt'};
            suffix = suffixes{channelNumber - 1};
        end

        function selectColor(app, colorType)
            color = uisetcolor();
            if length(color) == 3  % Check if a color was selected
                switch colorType
                    case {'Channel1', 'Channel2', 'Channel3', 'Channel4', 'Channel5'}
                        channelIndex = str2double(colorType(end));
                        app.ChannelColorButtons{channelIndex}.BackgroundColor = color;
                end
            end
        end

        function applyColors(app)
            % Update channel colors
            for i = 1:min(5, length(app.ChannelColors))
                if i <= length(app.ChannelColorButtons) && isvalid(app.ChannelColorButtons{i})
                    app.ChannelColors{i} = app.ChannelColorButtons{i}.BackgroundColor;
                end
            end

            % Set the flag to use custom colors
            app.UseCustomColors = true;

            % Update the plots with new colors
            try
                app.updatePlots();
                app.updatePlots2();
                app.plotSelectedClusters();
                app.plotSelectedClusters2();
            catch e
                disp(['Error updating plots: ' e.message]);
            end

            % Refresh the app to ensure all changes are visible
            drawnow;

            % Close the color preferences window if it's open
            if isvalid(app.DBSCAN)
                colorFig = findobj(allchild(app.DBSCAN), 'Type', 'Figure', 'Name', 'Channel Color Preferences');
                if ~isempty(colorFig)
                    close(colorFig);
                end
            end
        end

        function resetColors(app)
            % Reset to default colors
            app.UseCustomColors = false;

            % Reset the ChannelColors to their default values
            app.ChannelColors = {'k', 'cyan', 'red', 'magenta', 'green'};
            app.currentcolormap = 'parula';

            % Reset coloring mode to Default
            updateColoringMode(app, 'Default');

            % Clear stored color data
            app.ZColors = [];
            app.LocalizationColors = [];
            app.TraceColors = [];

            % Update the color buttons in the color preferences window
            if isvalid(app.DBSCAN)
                colorFig = findobj(allchild(app.DBSCAN), 'Type', 'Figure', 'Name', 'Channel Color Preferences');
                if ~isempty(colorFig) && isvalid(colorFig)
                    % Update the coloring mode dropdown
                    coloringModeDropdown = findobj(colorFig, 'Type', 'uidropdown');
                    for i = 1:length(coloringModeDropdown)
                        if length(coloringModeDropdown(i).Items) == 4 % The coloring mode dropdown has 4 items
                            coloringModeDropdown(i).Value = 'Default';
                            break;
                        end
                    end

                    % Update color buttons
                    for i = 1:length(app.ChannelColorButtons)
                        if isvalid(app.ChannelColorButtons{i})
                            app.ChannelColorButtons{i}.BackgroundColor = app.getColor(app.ChannelColors{i});
                        end
                    end
                end
            end

            % Update all plots
            app.updatePlots();
            app.updatePlots2();
            try
                app.plotSelectedClusters();
                app.plotSelectedClusters2();
            catch
                % These might fail if no clusters are selected
            end

            % Refresh the app to ensure all changes are visible
            drawnow;
        end

        function updateColormap(app, newColormap)
            % Validate and set the current colormap
            if ischar(newColormap) && exist(newColormap, 'file') == 2
                app.currentcolormap = newColormap;
            else
                % Fallback if an unknown name slips in
                app.currentcolormap = 'parula';
            end

            % If we're in Z-position mode, recompute Z-based colors with the NEW map
            if strcmp(getColoringMode(app), 'Z-Position')
                app.ZColors = [];              % drop any stale mapping
                updatePlotsWithZColoring(app); % regenerates using app.currentcolormap
            end

            % Apply colorbars to all relevant axes based on current mode
            mode = getColoringMode(app);
            switch mode
                case 'Z-Position'
                    app.setColorScaleAndColorbar(app.UIAxes2,  'Z (nm)', app.ZMin, app.ZMax);
                    app.setColorScaleAndColorbar(app.UIAxes5,  'Z (nm)', app.ZMin, app.ZMax);
                    app.setColorScaleAndColorbar(app.UIAxes5_2,'Z (nm)', app.ZMin, app.ZMax);
                case 'Localizations'
                    app.setColorScaleAndColorbar(app.UIAxes2,  'Localizations', app.LocMin, app.LocMax);
                    app.setColorScaleAndColorbar(app.UIAxes5,  'Localizations', app.LocMin, app.LocMax);
                    app.setColorScaleAndColorbar(app.UIAxes5_2,'Localizations', app.LocMin, app.LocMax);
                case 'Traces'
                    app.setColorScaleAndColorbar(app.UIAxes2,  'Traces', app.TrcMin, app.TrcMax);
                    app.setColorScaleAndColorbar(app.UIAxes5,  'Traces', app.TrcMin, app.TrcMax);
                    app.setColorScaleAndColorbar(app.UIAxes5_2,'Traces', app.TrcMin, app.TrcMax);
                otherwise
                    % Default → hide all colorbars
                    app.hideColorbar(app.UIAxes2);
                    app.hideColorbar(app.UIAxes5);
                    app.hideColorbar(app.UIAxes5_2);
            end

            % Refresh all plots
            app.updatePlots();
            app.updatePlots2();
            app.plotSelectedClusters();
            app.plotSelectedClusters2();
        end


        function resetAppState(app)
            % Clear all data
            app.Data1 = [];
           app.Data2_off = [];
           app.Data3_off = [];
           app.Data4_off = [];
            app.Data5_off = [];

            % Reset clusters and noise
            app.Clusters1 = [];
            app.IsNoise1 = [];

            % Clear trace data
            app.trace_data1 = [];
            app.trace_data2 = [];
            app.trace_data3 = [];
            app.trace_data4 = [];
            app.trace_data5 = [];
            app.trace_clusters1 = [];
            app.trace_clusters2 = [];
            app.trace_clusters3 = [];
            app.trace_clusters4 = [];
            app.trace_clusters5 = [];

            % Reset file names and paths
            app.filename1 = '';
            app.filepath1 = '';
            app.filename2 = '';
            app.filename3 = '';
            app.filename4 = '';
            app.filename5 = '';

            % Clear channel information
            app.ChannelFileNames = struct();
            app.ChannelEnableStates = struct();
            app.ChannelCorrections = struct();


            % Reset bead data and related properties
            app.Zarr = [];
            app.validatedBeads = [];
            app.BeadAlignmentData = [];
            app.CorrectionData = [];
            app.ReferenceStainingDropDown.Items = {'Channel 1'};

            app.ReferenceStainingDropDown.Value = 'Channel 1';

            % Reset UI elements
            app.FileNameLabel1.Text = '';
            app.ListBox.Items = {};
            app.ListBox_2.Items = {};
            app.ListBox2.Items = {};
            app.ListBox2_2.Items = {};
            app.RMSE_alignment.Text = '';

            % Clear all axes
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes5);
            cla(app.UIAxes5_2);
            cla(app.UIAxes6);
            cla(app.UIAxes7);
            cla(app.UIAxes3);
            cla(app.UIAxes3_2);
            cla(app.DeviationAxes);
            cla(app.LocalizationAxes);

            % Reset crop-related properties
            app.CropEnabled = false;
            app.CropLimits = struct('XMin', -inf, 'XMax', inf, 'YMin', -inf, 'YMax', inf, 'ZMin', -inf, 'ZMax', inf);

            % Clear original data storage
            app.OriginalData1 = [];
            app.OriginalData2 = [];
            app.OriginalData3 = [];
            app.OriginalData4 = [];
            app.OriginalData5 = [];
            app.Originaltid1 = [];
            app.Originaltid2 = [];
            app.Originaltid3 = [];
            app.Originaltid4 = [];
            app.Originaltid5 = [];
            app.Originaltim1 = [];
            app.Originaltim2 = [];
            app.Originaltim3 = [];
            app.Originaltim4 = [];
            app.Originaltim5 = [];

            % Reset tables
            app.UITable.Data = {};
            app.UITableMulti.Data = {};

            % Reset other properties
            app.allCenteredPoints = [];
            app.ClusterVolumes = [];
            app.ClusterSurfaces = [];
            app.ClusterRDFs = {};

            % Clear DBSCAN cache
            app.DBSCANCache = struct();

            % Reset ChannelListBox
            app.ChannelListBox.Items = {'Channel 1', 'Add Channel'};
            app.ChannelListBox.Value = 'Channel 1';

            % Update channel details
            updateChannelDetails(app, 'Channel 1');

            % Reset checkbox visibility
            updateCheckboxVisibility(app);
        end
        function updateColoringMode(app, coloringMode)
            % Store the selected coloring mode in multiple locations for redundancy
            try
                if ~isprop(app, 'ColoringMode')
                    addprop(app, 'ColoringMode');
                end
                app.ColoringMode = coloringMode;
            catch
                % If dynamic property addition fails, use UserData
            end

            try
                if ~isprop(app, 'CurrentColoringType')
                    addprop(app, 'CurrentColoringType');
                end
                app.CurrentColoringType = coloringMode;
            catch
                % If dynamic property addition fails, use UserData
            end

            % Always store in UserData as backup
            if isempty(app.DBSCAN.UserData)
                app.DBSCAN.UserData = struct();
            end
            app.DBSCAN.UserData.ColoringMode = coloringMode;
            app.DBSCAN.UserData.CurrentColoringType = coloringMode;

            % Update plots based on the selected coloring mode
            updatePlotsWithColoringMode(app);
        end

        % New function to update plots with the selected coloring mode
        function updatePlotsWithColoringMode(app)
            % Get the coloring mode from wherever it's stored
            coloringMode = getColoringMode(app);

            if isempty(coloringMode)
                coloringMode = 'Default';
            end

            switch coloringMode
                case 'Default'
                    app.UseCustomColors = false;
                    app.updatePlots();
                    app.updatePlots2();
                    app.plotSelectedClusters();
                    app.plotSelectedClusters2();

                    % Hide colorbars in Default mode
                    app.hideColorbar(app.UIAxes2);
                    app.hideColorbar(app.UIAxes5);
                    app.hideColorbar(app.UIAxes5_2);
                case 'Z-Position'
                    % Color by Z-position
                    app.UseCustomColors = true;
                    updatePlotsWithZColoring(app);

                case 'Localizations'
                    % Color by number of localizations per cluster
                    app.UseCustomColors = true;
                    updatePlotsWithLocalizationColoring(app);

                case 'Traces'
                    % Color by number of traces per cluster
                    app.UseCustomColors = true;
                    updatePlotsWithTraceColoring(app);

            end
        end

        % Helper function to get the coloring mode
        function rgb = getColor(app, colorInput)
            % Handle different types of color input
            if isempty(colorInput)
                rgb = [0 0 0]; % Default to black
                return;
            end

            % If it's already an RGB triplet, normalize and return
            if isnumeric(colorInput) && numel(colorInput) == 3
                rgb = double(colorInput(:)).';        % row vector
                rgb = max(0, min(1, rgb));            % clamp to [0,1]
                return;
            end

            % If it's a string/char, map it to RGB
            if isstring(colorInput) || ischar(colorInput)
                c = lower(char(colorInput));
                switch c
                    case {'k','black'},   rgb = [0 0 0];
                    case {'w','white'},   rgb = [1 1 1];
                    case {'r','red'},     rgb = [1 0 0];
                    case {'g','green'},   rgb = [0 1 0];
                    case {'b','blue'},    rgb = [0 0 1];
                    case {'c','cyan'},    rgb = [0 1 1];
                    case {'m','magenta'}, rgb = [1 0 1];
                    case {'y','yellow'},  rgb = [1 1 0];
                    otherwise
                        % Try MATLAB's built-in color interpretation
                        try
                            rgb = validatecolor(c);
                        catch
                            rgb = [0 0 0]; % Safe fallback
                        end
                end
                return;
            end

            % Fallback to black
            rgb = [0 0 0];
        end

        % Function to color plots by Z-position
        function updatePlotsWithZColoring(app)
            if isempty(app.Data1)
                return;
            end

            % Get Z-coordinates and normalize them for coloring
            zCoords = app.Data1(:, 3);
            minZ = min(zCoords);
            maxZ = max(zCoords);

            if maxZ == minZ
                normalizedZ = ones(size(zCoords));
            else
                normalizedZ = (zCoords - minZ) / (maxZ - minZ);
            end

            % Store normalized Z values instead of RGB colors
            app.ZColors = normalizedZ;  % Store as indices, not RGB

            app.ZMin = minZ;
            app.ZMax = maxZ;

            % Apply colormap to axes that use point-wise colors
            colormap(app.UIAxes2, feval(app.currentcolormap, 256));
            colormap(app.UIAxes5, feval(app.currentcolormap, 256));
            colormap(app.UIAxes5_2, feval(app.currentcolormap, 256));

            % Show colorbars with correct labels
            app.setColorScaleAndColorbar(app.UIAxes2,  'Z (nm)', app.ZMin, app.ZMax);
            app.setColorScaleAndColorbar(app.UIAxes5,  'Z (nm)', app.ZMin, app.ZMax);
            app.setColorScaleAndColorbar(app.UIAxes5_2,'Z (nm)', app.ZMin, app.ZMax);

            % Update plots with Z-coloring
            updatePlotsWithCustomColors(app, app.ZColors, 'Z-Position');
        end

        % Function to color plots by localization count
        function updatePlotsWithLocalizationColoring(app)
            if isempty(app.Clusters1)
                return;
            end

            % Clear any existing stored colors to force regeneration
            app.LocalizationColors = [];

            % Count localizations per cluster
            validClusters = unique(app.Clusters1(app.Clusters1 > 0));
            if isempty(validClusters)
                return;
            end

            locCounts = arrayfun(@(x) sum(app.Clusters1 == x), validClusters);

            % Normalize counts for coloring
            minCount = min(locCounts);
            maxCount = max(locCounts);

            if maxCount == minCount
                normalizedCounts = ones(size(locCounts));
            else
                normalizedCounts = (locCounts - minCount) / (maxCount - minCount);
            end

            app.LocMin = minCount;
            app.LocMax = maxCount;

            % Apply colormap to axes
            colormap(app.UIAxes2, feval(app.currentcolormap, 256));
            colormap(app.UIAxes5, feval(app.currentcolormap, 256));
            colormap(app.UIAxes5_2, feval(app.currentcolormap, 256));

            % Set colorbars
            app.setColorScaleAndColorbar(app.UIAxes2,  'Localizations', app.LocMin, app.LocMax);
            app.setColorScaleAndColorbar(app.UIAxes5,  'Localizations', app.LocMin, app.LocMax);
            app.setColorScaleAndColorbar(app.UIAxes5_2,'Localizations', app.LocMin, app.LocMax);

            % Create normalized color indices for all data points based on their cluster
            pointColors = zeros(size(app.Data1, 1), 1);  % Single column for colormap indices
            for i = 1:length(validClusters)
                clusterIdx = validClusters(i);
                pointIndices = app.Clusters1 == clusterIdx;
                pointColors(pointIndices) = normalizedCounts(i);  % Assign normalized count as index
            end

            % Store normalized indices instead of RGB
            app.LocalizationColors = pointColors;

            % Update plots with localization-based coloring
            updatePlotsWithCustomColors(app, pointColors, 'Localizations');
        end

        % Function to color plots by trace count
        function updatePlotsWithTraceColoring(app)
            % Ensure trace data is calculated
            if isempty(app.trace_clusters1)
                app.calculateTraceData(); % Calculate trace data if not available
            end
            if isempty(app.trace_clusters1)
                return;
            end

            % Clear any existing stored colors to force regeneration
            app.TraceColors = [];

            % Count traces per cluster (positive cluster IDs)
            validClusters = unique(app.trace_clusters1(app.trace_clusters1 > 0));
            if isempty(validClusters)
                return;
            end

            traceCounts = arrayfun(@(x) sum(app.trace_clusters1 == x), validClusters);

            % Normalize counts for coloring
            minCount = min(traceCounts);
            maxCount = max(traceCounts);
            if maxCount == minCount
                normalizedCounts = ones(size(traceCounts));     % all same color
            else
                normalizedCounts = (traceCounts - minCount) / (maxCount - minCount);
            end

            % Expose range for colorbar and set on relevant axes
            app.TrcMin = double(minCount);
            app.TrcMax = double(maxCount);

            % Apply colormap to axes
            colormap(app.UIAxes2,  feval(app.currentcolormap, 256));
            colormap(app.UIAxes5,  feval(app.currentcolormap, 256));
            colormap(app.UIAxes5_2, feval(app.currentcolormap, 256));

            % Set colorbars
            if maxCount == minCount
                app.setColorScaleAndColorbar(app.UIAxes2,  'Traces', 0, 1);
                app.setColorScaleAndColorbar(app.UIAxes5,  'Traces', 0, 1);
                app.setColorScaleAndColorbar(app.UIAxes5_2,'Traces', 0, 1);
            else
                app.setColorScaleAndColorbar(app.UIAxes2,  'Traces', app.TrcMin, app.TrcMax);
                app.setColorScaleAndColorbar(app.UIAxes5,  'Traces', app.TrcMin, app.TrcMax);
                app.setColorScaleAndColorbar(app.UIAxes5_2,'Traces', app.TrcMin, app.TrcMax);
            end

            % Map cluster -> normalized index, then paint each point by its cluster's index
            pointColors = zeros(size(app.Data1, 1), 1);  % Single column for colormap indices

            % For each localization point, find which cluster it belongs to and assign the
            % normalized trace count for that cluster
            for i = 1:numel(validClusters)
                clusterIdx = validClusters(i);
                % Find all localization points that belong to this cluster
                pointIndices = (app.Clusters1 == clusterIdx);
                if any(pointIndices)
                    pointColors(pointIndices) = normalizedCounts(i);  % Assign normalized trace count as index
                end
            end

            % Store normalized indices instead of RGB
            app.TraceColors = pointColors;
            updatePlotsWithCustomColors(app, pointColors, 'Traces');
        end


        % New unified function to update plots with custom colors
        function updatePlotsWithCustomColors(app, customColors, coloringType)
            % Store the coloring type for reference in plotting functions
            try
                app.DBSCAN.UserData.CurrentColoringType = coloringType;
                app.DBSCAN.UserData.CustomColors = customColors;
            catch
                % Fallback storage
            end

            % Set flag to use custom colors
            app.UseCustomColors = true;

            % Update all relevant plots
            try
                app.updatePlots();
            catch ME
                fprintf('Error updating main plots: %s\n', ME.message);
            end

            try
                app.updatePlots2();
            catch ME
                fprintf('Error updating multi-channel plots: %s\n', ME.message);
            end

            try
                app.plotSelectedClusters();
            catch ME
                fprintf('Error updating single cluster plots: %s\n', ME.message);
            end

            try
                app.plotSelectedClusters2();
            catch ME
                fprintf('Error updating multi-cluster plots: %s\n', ME.message);
            end
        end

        function processBeadDataFile(app, fullPath)
            % Load the MAT file
            loadedData = load(fullPath);

            if ~isfield(loadedData, 'data')
                uialert(app.DBSCAN, 'No "data" field found in file', 'File Error');
                return;
            end

            % Update the app properties with the loaded data
            [~, fileName, ext] = fileparts(fullPath);
            app.Zarr = loadedData.data;
            app.ZarrLabel.Text = ['File: ', fileName, ext];

            % Validate beads and store bead alignment data
            app.validatedBeads = app.validateBeads(app.Zarr);
            app.BeadAlignmentData = app.storeBeadAlignmentData(app.Zarr, app.validatedBeads);

            % Update the dropdown menu with staining names.
            stainingFields = fieldnames(app.Zarr);
            if numel(stainingFields) > 1
                [indx, tf] = listdlg('ListString', stainingFields, ...
                    'SelectionMode', 'single', ...
                    'PromptString', 'Select the reference staining:');
                if tf == 0
                    referenceStaining = stainingFields{1};
                else
                    referenceStaining = stainingFields{indx};
                end
            else
                referenceStaining = stainingFields{1};
            end
            % Reorder so that the reference staining appears first
            stainingFields = [referenceStaining; stainingFields(~strcmp(stainingFields, referenceStaining))];
            app.ReferenceStainingDropDown.Items = stainingFields;
            app.ReferenceStainingDropDown.Value = referenceStaining;

            % Perform corrections and update related visualizations
            app.calculateCorrections();
            app.updateMulticolorTabFromCorrections();
            app.updateAlignment();
            app.visualizeBeadLocalizations();
            app.updateChannelListBox();
        end

        function closeWelcomeDialog(app, dialogHandle, dontShowChk)
            % Update the property based on checkbox state
            app.ShowWelcomeMessage = ~get(dontShowChk, 'Value');
            close(dialogHandle);
        end

        function isValid = isValidZarrFolder(app, folderPath)
            try
                % Get all subdirectories in the parent folder (staining folders)
                stainingFolders = dir(fullfile(folderPath, '*'));
                stainingFolders = stainingFolders([stainingFolders.isdir]);
                % Remove '.' and '..' entries
                names = {stainingFolders.name};
                stainingFolders = stainingFolders(~ismember(names, {'.', '..'}));

                if isempty(stainingFolders)
                    disp('No staining folders found in the parent directory.');
                    isValid = false;
                    return;
                end

                % Check at least one staining folder for expected structure
                foundValidStructure = false;
                for i = 1:length(stainingFolders)
                    stainingPath = fullfile(folderPath, stainingFolders(i).name);

                    % Get all subdirectories in the staining folder (bead folders)
                    beadFolders = dir(fullfile(stainingPath, '*'));
                    beadFolders = beadFolders([beadFolders.isdir]);
                    % Remove '.' and '..' entries
                    names = {beadFolders.name};
                    beadFolders = beadFolders(~ismember(names, {'.', '..'}));

                    if isempty(beadFolders)
                        continue; % No bead folders in this staining folder, try next one
                    end

                    % Check at least one bead folder for expected files
                    for j = 1:length(beadFolders)
                        beadPath = fullfile(stainingPath, beadFolders(j).name);

                        % Check for .zattrs and .zarray files (common in Zarr datasets)
                        hasZattrs = exist(fullfile(beadPath, '.zattrs'), 'file') == 2;
                        hasZarray = exist(fullfile(beadPath, '.zarray'), 'file') == 2;

                        if hasZattrs || hasZarray
                            foundValidStructure = true;
                            break; % Found at least one valid bead folder
                        end
                    end

                    if foundValidStructure
                        break; % Found at least one valid staining folder
                    end
                end

                isValid = foundValidStructure;

                if ~isValid
                    disp('No valid Zarr structure found in the selected folder.');
                end
            catch ex
                disp(['Error validating Zarr folder: ' ex.message]);
                isValid = false;
            end
        end
        function scriptPath = findScriptRecursively(baseDir, scriptName, maxDepth, currentDepth)

            if nargin < 4
                currentDepth = 0;
            end

            % Initialize output
            scriptPath = '';

            % Stop if we've reached max depth
            if currentDepth > maxDepth
                return;
            end

            try
                % Check if script exists directly in this directory
                fullPath = fullfile(baseDir, scriptName);
                if exist(fullPath, 'file')
                    scriptPath = fullPath;
                    return;
                end

                % Get all subdirectories
                contents = dir(baseDir);
                dirNames = {contents([contents.isdir]).name};

                % Filter out '.' and '..' directories
                dirNames = dirNames(~strcmp(dirNames, '.') & ~strcmp(dirNames, '..'));

                % Search subdirectories recursively
                for i = 1:length(dirNames)
                    subDir = fullfile(baseDir, dirNames{i});

                    % Skip symbolic links to prevent infinite loops
                    if isSymbolicLink(subDir)
                        continue;
                    end

                    % Recursively search in subdirectory
                    foundPath = findScriptRecursively(subDir, scriptName, maxDepth, currentDepth + 1);
                    if ~isempty(foundPath)
                        scriptPath = foundPath;
                        return;
                    end
                end
            catch ME
                % Log error and continue search in other directories
                warning(['Error searching in directory "' baseDir '": ' ME.message]);
            end
        end

        function setColorScaleAndColorbar(app, ax, labelText, vmin, vmax)
            % remove any existing colorbar attached to this axes
            cbs = findall(ax.Parent, 'Type', 'ColorBar');
            for i = 1:numel(cbs)
                if isequal(cbs(i).Axes, ax)
                    delete(cbs(i));
                end
            end

            % set color limits (with a safe fallback if degenerate)
            if ~isfinite(vmin) || ~isfinite(vmax) || vmin == vmax
                vmin = 0; vmax = 1;
            end
            clim(ax, [vmin vmax]);

            % create colorbar and set its label
            cb = colorbar(ax, 'Location', 'east');
            cb.Label.String = labelText;
            cb.Label.Rotation = 90;             % horizontal text
            cb.Label.VerticalAlignment = 'middle'; % vertically centered

            % Make the colorbar smaller and more compact
            cb.Label.FontSize = 10;           % Smaller font for label
            cb.FontSize = 8;                  % Smaller font for tick labels
            cb.Label.FontWeight = 'normal';   % Normal weight instead of bold
            cb.Label.Interpreter = 'none';    % Prevent interpretation of special characters

            % Adjust colorbar width to make it more compact
            cb.Position(3) = 0.015;  % Make colorbar thinner (width = 1.5% of figure)
            cb.Position(4) = 0.4;  % Make colorbar thinner (width = 1.5% of figure)
            cb.Position(1) = 0.03; %left-right offset
            cb.Position(2) = 0.06;
            % Optional: Reduce the number of ticks for cleaner appearance
            if vmax - vmin > 10
                numTicks = 5;  % Use 5 ticks for large ranges
            else
                numTicks = 3;  % Use 3 ticks for small ranges
            end

            tickValues = linspace(vmin, vmax, numTicks);
            cb.Ticks = tickValues;

            % Format tick labels to avoid too many decimal places
            if all(mod(tickValues, 1) == 0)
                % Integer values
                cb.TickLabels = arrayfun(@(x) sprintf('%.0f', x), tickValues, 'UniformOutput', false);
            else
                % Decimal values - limit to 1 decimal place
                cb.TickLabels = arrayfun(@(x) sprintf('%.1f', x), tickValues, 'UniformOutput', false);
            end
        end


        function hideColorbar(app, ax)
            cbs = findall(ax.Parent, 'Type', 'ColorBar');
            for i = 1:numel(cbs)
                if isequal(cbs(i).Axes, ax)
                    delete(cbs(i));
                end
            end
        end

function applyCroppingToAllData(app)
    % Apply cropping to all loaded datasets
    for i = 1:5
        dataField = ['Data', num2str(i)];
        originalDataField = ['OriginalData', num2str(i)];
        tidField = ['tid', num2str(i)];
        originalTidField = ['Originaltid', num2str(i)];
        timField = ['tim', num2str(i)];
        originalTimField = ['Originaltim', num2str(i)];
        
        if ~isempty(app.(dataField))
            % Store original data if not already stored
            if isempty(app.(originalDataField))
                app.(originalDataField) = app.(dataField);
                if ~isempty(app.(tidField))
                    app.(originalTidField) = app.(tidField);
                end
                if ~isempty(app.(timField))
                    app.(originalTimField) = app.(timField);
                end
            end
            
            % Apply cropping or restore original data
            if app.CropEnabled
                app.cropSingleDataset(i);
            else
                % Restore original data
                app.(dataField) = app.(originalDataField);
                if ~isempty(app.(originalTidField))
                    app.(tidField) = app.(originalTidField);
                end
                if ~isempty(app.(originalTimField))
                    app.(timField) = app.(originalTimField);
                end
            end
            
            % Clear DBSCAN cache for this channel
            app.clearDBSCANCache(i);
        end
    end
end

function cropSingleDataset(app, channelNumber)
    dataField = ['Data', num2str(channelNumber)];
    originalDataField = ['OriginalData', num2str(channelNumber)];
    tidField = ['tid', num2str(channelNumber)];
    originalTidField = ['Originaltid', num2str(channelNumber)];
    timField = ['tim', num2str(channelNumber)];
    originalTimField = ['Originaltim', num2str(channelNumber)];
    
    if isempty(app.(originalDataField))
        return;
    end
    
    originalData = app.(originalDataField);
    
    % Find points within crop limits
    withinLimits = true(size(originalData, 1), 1);
    
    % X limits
    if isfinite(app.CropLimits.XMin)
        withinLimits = withinLimits & (originalData(:,1) >= app.CropLimits.XMin);
    end
    if isfinite(app.CropLimits.XMax)
        withinLimits = withinLimits & (originalData(:,1) <= app.CropLimits.XMax);
    end
    
    % Y limits
    if isfinite(app.CropLimits.YMin)
        withinLimits = withinLimits & (originalData(:,2) >= app.CropLimits.YMin);
    end
    if isfinite(app.CropLimits.YMax)
        withinLimits = withinLimits & (originalData(:,2) <= app.CropLimits.YMax);
    end
    
    % Z limits
    if isfinite(app.CropLimits.ZMin)
        withinLimits = withinLimits & (originalData(:,3) >= app.CropLimits.ZMin);
    end
    if isfinite(app.CropLimits.ZMax)
        withinLimits = withinLimits & (originalData(:,3) <= app.CropLimits.ZMax);
    end
    
    % Apply cropping
    app.(dataField) = originalData(withinLimits, :);
    
    % Crop corresponding tid and tim arrays
    if ~isempty(app.(originalTidField))
        app.(tidField) = app.(originalTidField)(withinLimits);
    end
    if ~isempty(app.(originalTimField))
        app.(timField) = app.(originalTimField)(withinLimits);
    end
    
    % Display crop statistics
    originalCount = size(originalData, 1);
    croppedCount = size(app.(dataField), 1);
    fprintf('Channel %d: Cropped from %d to %d localizations (%.1f%% retained)\n', ...
        channelNumber, originalCount, croppedCount, (croppedCount/originalCount)*100);
end
        function result = isSymbolicLink(path)
            % Helper function to detect symbolic links
            % Implementation differs between platforms

            result = false;
            try
                if ispc
                    % Windows: check if it's a junction or symlink using dir command
                    [status, output] = system(['dir /AL "' path '" | find "<SYMLINK"']);
                    result = (status == 0) && ~isempty(output);
                else
                    % Unix/Mac: use ls -la and look for the -> symbol
                    [status, output] = system(['ls -la "' path '" | grep "\->"']);
                    result = (status == 0) && ~isempty(output);
                end
            catch
                % If error occurs, assume it's not a symlink to be safe
                result = false;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ClusterRDFs = {};
            app.figureHandle3D = [];
            app.figureHandle2D = [];
            app.ChannelCorrections = struct();
            app.ChannelFileNames = struct();
            app.ChannelEnableStates = struct();

            % Initialize ChannelColors as a cell array
            app.ChannelColors = {'k', 'cyan', 'red', 'magenta', 'green'};

            % Initialize ChannelColorButtons as a cell array
            app.ChannelColorButtons = cell(1, 5);

            % Check if the welcome message should be shown
            if app.ShowWelcomeMessage && ~isdeployed
                try
                    % Create figure with basic properties
                    d = figure('Name', 'Welcome to matFLUX 1.0!', 'Position', [100 100 400 300], ...
                        'NumberTitle', 'off', 'MenuBar', 'none', 'ToolBar', 'none');

                    % Welcome message text with manual line breaks
                    welcomeMsg = sprintf(['Steps to begin:\n' ...
                        '1. Click "Load/New" button to load your MINFLUX file.\n' ...
                        '2. Adjust DBSCAN (Epsilon, MinPts) as needed.\n' ...
                        '3. Inspect single clusters.\n' ...
                        '4. Load and align additional channels.\n' ...
                        '5. Explore tabs for further analyses.\n\n' ...
                        'Use the camera icon to save plots.\n' ...
                        'For detailed help, click the Help menu.']);

                    % Create text control instead of uilabel
                    uicontrol('Style', 'text', 'String', welcomeMsg, ...
                        'Position', [20 120 360 150], 'HorizontalAlignment', 'left');

                    % Create checkbox with standard uicontrol
                    dontShowChk = uicontrol('Style', 'checkbox', ...
                        'String', 'Don''t show this message again', ...
                        'Position', [20 80 200 22], 'Value', 0);

                    % Create OK button
                    uicontrol('Style', 'pushbutton', 'String', 'OK', ...
                        'Position', [160 30 80 30], ...
                        'Callback', @(src,event) closeWelcomeDialog(app, d, dontShowChk));

                    % Make figure visible and modal-like
                    set(d, 'Visible', 'on');
                    uiwait(d); % Pause execution until the dialog is closed
                catch
                    % If there's any error in creating the welcome dialog, just continue
                    disp('Welcome dialog could not be displayed');
                end
            end

        end

        % Value changed function: EpsilonSpinner
        function EpsilonSpinnerValueChanged(app, event)
    % Clear cache when parameters change
    app.DBSCANCache = struct(); % Simple cache clear
    
    % Only update if data exists
    if ~isempty(app.Data1)
        updatePlots(app);
        app.updateClusterInfo();
        plotSelectedClusters(app);
    end

        end

        % Value changed function: MinPtsSpinner
        function MinPtsSpinnerValueChanged(app, event)
    % Clear cache when parameters change  
    app.DBSCANCache = struct(); % Simple cache clear
    
    % Only update if data exists
    if ~isempty(app.Data1)
        updatePlots(app);
        app.updateClusterInfo();
        plotSelectedClusters(app);
    end

        end

        % Button pushed function: LoadNewButton
        function LoadNewButtonPushed(app, event)
    % Check if DBSCAN function is available
    if ~exist('dbscan', 'file')
        errordlg(['DBSCAN function not found. This application requires the Statistics and Machine Learning Toolbox. ' ...
            'Please install this toolbox.'], 'Missing Dependency');
        return;
    end

    % Load the dataset FIRST - don't reset until we have a file
    [file, path] = uigetfile('*.mat', 'Select a MATLAB data file');
    if isequal(file, 0)
        return;  % User cancelled - don't do anything
    end

    fullPath = fullfile(path, file);
    
    % Try to load the file first to make sure it's valid
    try
        loadedData = load(fullPath);
    catch ME
        errordlg(['Failed to load file: ' ME.message], 'Load Error');
        return;
    end
    
    % Detect data structure format and extract data
    [data, tid, tim, isNewFormat] = app.extractDataFromStructure(loadedData);

    if isempty(data)
        errordlg(['The loaded file does not contain the expected data structure. Please check the file: ', file], 'Data Structure Error');
        return;
    end
    
    % NOW that we know the file is valid, store settings and reset
    currentColors = app.ChannelColors;
    currentUseCustomColors = app.UseCustomColors;
    currentBoxSizeX = app.BoxSizeX;
    currentBoxSizeY = app.BoxSizeY;
    currentBoxSizeZ = app.BoxSizeZ;
    currentColormap = app.currentcolormap;
    currentMaxAnalysisDistance = app.MaxAnalysisDistance;

    % Reset the app state
    resetAppState(app);

    % Restore settings
    app.ChannelColors = currentColors;
    app.UseCustomColors = currentUseCustomColors;
    app.BoxSizeX = currentBoxSizeX;
    app.BoxSizeY = currentBoxSizeY;
    app.BoxSizeZ = currentBoxSizeZ;
    app.currentcolormap = currentColormap;
    app.MaxAnalysisDistance = currentMaxAnalysisDistance;

    app.clearDBSCANCache(1);  % Clear cache for Channel_1

    % Extract filename
    [~, filename, ~] = fileparts(file);

    % Update filename property
    app.filename1 = filename;
    app.filepath1 = path;

    % Use the already-loaded data
    app.LoadedData1 = loadedData;

    % Apply coordinate transformations
    data(:,2) = 1 * (data(:,2));
    data(:,3) = 1 * (data(:,3) * 0.7);  % Apply z-correction factor

    app.Data1 = data;

    % Store tid and tim data
    app.tid1 = tid;
    app.tim1 = tim;

    % Initialize channel structures
    channelName = 'Channel_1';
    app.ChannelFileNames.(channelName) = filename;
    app.ChannelEnableStates.(channelName) = true;  % Channel 1 is always enabled
    app.ChannelCorrections.(channelName) = struct('X', 0, 'Y', 0, 'Z', 0);

    % Update ChannelListBox
    app.ChannelListBox.Items = {'Channel 1', 'Add Channel'};
    app.ChannelListBox.Value = 'Channel 1';

    % Update channel details
    updateChannelDetails(app, 'Channel 1');

    % Update plots
    updatePlots(app);

    % Ensure 'Add Channel' is in the list
    updateChannelListBox(app);

    app.calculateTraceData();  % Calculate trace data

    % Display format information
    if isNewFormat
        disp(['Loaded new MINFLUX data format from: ', filename]);
        app.FileNameLabel1.Text = ['File: ', filename, ' (New Format)'];
    else
        disp(['Loaded legacy MINFLUX data format from: ', filename]);
        app.FileNameLabel1.Text = ['File: ', filename, ' (Old Format)'];
    end


        end

        % Value changed function: ShowClustersOnlyCheckbox
        function ShowClustersOnlyCheckboxValueChanged(app, event)

            updatePlots(app);

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            plotSelectedClusters(app);
        end

        % Value changed function: EllipsoidFitCheckBox
        function EllipsoidFitCheckBoxValueChanged(app, event)
            plotSelectedClusters(app);
        end

        % Value changed function: ListBox2
        function ListBox_2ValueChanged(app, event)
            Measurement(app);
        end

        % Button pushed function: LoadChannelButton
        function LoadChannelButtonPushed(app, event)
    if nargin < 2
        event = [];
    end
    
    if isempty(event) || strcmp(app.ChannelListBox.Value, 'New Channel') || strcmp(app.ChannelListBox.Value, 'Add Channel')
        % Find the first empty data slot
        for channelNumber = 1:5
            if isempty(app.(['Data', num2str(channelNumber)]))
                break;
            end
        end
        
        % If all slots are filled, show an error message
        if channelNumber > 5
            uialert(app.DBSCAN, 'All channels are already loaded. Please clear a channel before loading new data.', 'Channel Limit Reached');
            return;
        end
        
        app.clearDBSCANCache(channelNumber); % Clear cache for the new channel
        app.loadDataset(channelNumber);
        
        % Update the ChannelListBox without duplicating entries
        updateChannelListBox(app);
        
        % Select the newly added channel - ensure the value exists in Items first
        newChannelName = ['Channel ', num2str(channelNumber)];
        if ismember(newChannelName, app.ChannelListBox.Items)
            app.ChannelListBox.Value = newChannelName;
        else
            % Fallback: try to find and select the channel, or use first available
            channelItems = app.ChannelListBox.Items;
            channelPattern = ['Channel ', num2str(channelNumber)];
            matchingItems = channelItems(contains(channelItems, ['Channel ', num2str(channelNumber)]));
            if ~isempty(matchingItems)
                app.ChannelListBox.Value = matchingItems{1};
            elseif ~isempty(channelItems) && ~strcmp(channelItems{1}, 'Add Channel')
                app.ChannelListBox.Value = channelItems{1};
            end
        end
        
        % Ensure the new channel is set to ON
        channelName = ['Channel_', num2str(channelNumber)];
        app.ChannelEnableStates.(channelName) = true;
    else
        % Refresh existing channel
        channelNumber = str2double(regexp(app.ChannelListBox.Value, '\d+', 'match'));
        if isempty(channelNumber)
            % If we can't extract channel number, default to 1
            channelNumber = 1;
        end
        app.clearDBSCANCache(channelNumber); % Clear cache for the selected channel
        app.loadDataset(channelNumber);
    end
    
    % Update channel details - use current ListBox value
    currentValue = app.ChannelListBox.Value;
    if ~isempty(currentValue) && ~strcmp(currentValue, 'Add Channel')
        updateChannelDetails(app, currentValue);
    end
    
    % Ensure correction factors are applied
    channelName = ['Channel_', num2str(channelNumber)];
    dataField = ['Data', num2str(channelNumber)];
    dataFieldOff = ['Data', num2str(channelNumber), '_off'];
    
    % Check if both corrections exist AND data exists before applying
    if isfield(app.ChannelCorrections, channelName) && ~isempty(app.(dataField))
        corrections = app.ChannelCorrections.(channelName);
        
        % Update the UI fields
        app.XCorrectionField.Value = corrections.X;
        app.YCorrectionField.Value = corrections.Y;
        app.ZCorrectionField.Value = corrections.Z;
        
        % Apply corrections to the data (only if data exists)
        app.(dataFieldOff) = app.(dataField);
        app.(dataFieldOff)(:,1) = app.(dataFieldOff)(:,1) + corrections.X;
        app.(dataFieldOff)(:,2) = app.(dataFieldOff)(:,2) + corrections.Y;
        app.(dataFieldOff)(:,3) = app.(dataFieldOff)(:,3) + corrections.Z;
    elseif isfield(app.ChannelCorrections, channelName)
        % Corrections exist but no data - just update UI fields
        corrections = app.ChannelCorrections.(channelName);
        app.XCorrectionField.Value = corrections.X;
        app.YCorrectionField.Value = corrections.Y;
        app.ZCorrectionField.Value = corrections.Z;
    else
        % No corrections available - reset UI fields to zero
        app.XCorrectionField.Value = 0;
        app.YCorrectionField.Value = 0;
        app.ZCorrectionField.Value = 0;
    end
    
    % Update visualization
    app.updatePlots2();
    
    % Update checkbox visibility
    app.updateCheckboxVisibility();
    
    app.calculateTraceData();


        end

        % Value changed function: ChannelEnableCheckbox
        function ChannelEnableCheckboxValueChanged(app, event)
            selectedChannel = app.ChannelListBox.Value;
            channelNumber = str2double(regexp(selectedChannel, '\d+', 'match'));
            channelName = ['Channel_', num2str(channelNumber)];
            app.ChannelEnableStates.(channelName) = event.Value;
            app.updatePlots2();
        end

        % Value changed function: EnableExclusion
        function EnableExclusionValueChanged(app, event)
            updatePlots(app);
            updatePlots2(app);
            app.updateClusterInfo();
            plotSelectedClusters(app)


        end

        % Value changed function: ExclNum
        function ExclNumValueChanged2(app, event)
            if app.ExclNum.Value > 0
                app.EnableExclusion.Value = true;
            end
            updatePlots(app);
            updatePlots2(app);
            app.updateClusterInfo();
            plotSelectedClusters(app);


        end

        % Button pushed function: ShowHeatMap
        function ShowHeatMapPushed(app, event)
            % Close any existing heatmap figures
            if ~isempty(app.figureHandle3D) && isvalid(app.figureHandle3D)
                close(app.figureHandle3D);
            end
            if ~isempty(app.figureHandle2D) && isvalid(app.figureHandle2D)
                close(app.figureHandle2D);
            end

            % Create new heatmap visualizations
            plot3DHeatMap(app, app.allCenteredPoints);
        end

        % Value changed function: XCorrectionField
        function XCorrectionFieldValueChanged(app, event)

            UpdateCorrection(app, 'X', app.XCorrectionField.Value);

        end

        % Value changed function: YCorrectionField
        function YCorrectionFieldValueChanged(app, event)

            UpdateCorrection(app, 'Y', app.XCorrectionField.Value);

        end

        % Value changed function: ZCorrectionField
        function ZCorrectionFieldValueChanged(app, event)

            UpdateCorrection(app, 'Z', app.XCorrectionField.Value);

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
    % Get the currently selected tab
    currentTab = app.TabGroup.SelectedTab;

    % Initialize variables
    axesToSave = [];
    defaultFileName = '';

    % Special handling for Polar Plot tab
    if strcmp(currentTab.Title, 'Polar')
        % Check if the Panel contains a polaraxes
        if ~isempty(app.Panel.Children)
            % Find polaraxes in the Panel
            polarAxes = findobj(app.Panel.Children, 'Type', 'polaraxes');
            if ~isempty(polarAxes)
                axesToSave = polarAxes(1); % Use the first polaraxes found
                defaultFileName = 'Polar_Plot.pdf';
            else
                msgbox('No polar plot to save in the current tab.', 'No Graphic', 'warn');
                return;
            end
        else
            msgbox('No polar plot to save in the current tab.', 'No Graphic', 'warn');
            return;
        end
    else
        % For all other tabs, find regular axes objects
        axesObjects = findobj(currentTab, 'Type', 'axes');
        
        if ~isempty(axesObjects)
            % If multiple axes, let user choose which one to save
            if length(axesObjects) > 1
                % Create more descriptive names for the axes
                axesNames = cell(length(axesObjects), 1);
                for i = 1:length(axesObjects)
                    ax = axesObjects(i);
                    % Try to get a descriptive name from the title or position
                    titleObj = get(ax, 'Title');
                    titleText = get(titleObj, 'String');
                    if ~isempty(titleText) && ~ischar(titleText)
                        titleText = titleText{1}; % Handle cell arrays
                    end
                    if ~isempty(titleText) && ~strcmp(titleText, 'Title')
                        axesNames{i} = sprintf('Plot %d: %s', i, titleText);
                    else
                        axesNames{i} = sprintf('Plot %d', i);
                    end
                end
                
                [indx, tf] = listdlg('ListString', axesNames, ...
                    'SelectionMode', 'single', ...
                    'PromptString', 'Select a plot to save:', ...
                    'ListSize', [300, 200]);
                if tf == 0
                    return;  % User cancelled
                end
                axesToSave = axesObjects(indx);
            else
                axesToSave = axesObjects(1);
            end
            defaultFileName = [currentTab.Title '_Plot.pdf'];
        else
            msgbox('No graphic to save in the current tab.', 'No Graphic', 'warn');
            return;
        end
    end

    % Ask user for save location and filename
    [fileName, pathName] = uiputfile({...
        '*.pdf','PDF Files (*.pdf)'; ...
        '*.png','PNG Files (*.png)'; ...
        '*.eps','EPS Files (*.eps)'; ...
        '*.fig','MATLAB Figure Files (*.fig)'}, ...
        'Save Current Graphic As', defaultFileName);
    
    if isequal(fileName, 0) || isequal(pathName, 0)
        return;  % User cancelled
    end

    fullFilePath = fullfile(pathName, fileName);
    [~, ~, ext] = fileparts(fileName);

    % Save the graphic with appropriate method based on file type
    try
        switch lower(ext)
            case '.fig'
                % For .fig files, create a new figure and copy the axes
                newFig = figure('Visible', 'off');
                copyobj(axesToSave, newFig);
                savefig(newFig, fullFilePath);
                close(newFig);
                
            case '.png'
                % For PNG, use higher resolution
                exportgraphics(axesToSave, fullFilePath, 'Resolution', 300);
                
            case {'.pdf', '.eps'}
                % For vector formats
                exportgraphics(axesToSave, fullFilePath, 'ContentType', 'vector');
                
            otherwise
                % Default to exportgraphics
                exportgraphics(axesToSave, fullFilePath);
        end
        
        msgbox(['Graphic saved successfully as ' fullFilePath], 'Save Complete');
        
    catch e
        % If exportgraphics fails (older MATLAB versions), try alternative methods
        try
            if strcmp(get(axesToSave, 'Type'), 'polaraxes')
                % Special handling for polar axes in older MATLAB versions
                % Create a temporary figure and copy the polar axes
                tempFig = figure('Visible', 'off');
                copyobj(axesToSave, tempFig);
                
                % Use print function for older MATLAB compatibility
                switch lower(ext)
                    case '.png'
                        print(tempFig, fullFilePath, '-dpng', '-r300');
                    case '.pdf'
                        print(tempFig, fullFilePath, '-dpdf');
                    case '.eps'
                        print(tempFig, fullFilePath, '-depsc');
                    otherwise
                        print(tempFig, fullFilePath, '-dpng', '-r300');
                end
                
                close(tempFig);
            else
                % For regular axes, use print function
                switch lower(ext)
                    case '.png'
                        print(gcf, fullFilePath, '-dpng', '-r300');
                    case '.pdf'
                        print(gcf, fullFilePath, '-dpdf');
                    case '.eps'
                        print(gcf, fullFilePath, '-depsc');
                    otherwise
                        print(gcf, fullFilePath, '-dpng', '-r300');
                end
            end
            
            msgbox(['Graphic saved successfully as ' fullFilePath], 'Save Complete');
            
        catch e2
            errordlg(['Failed to save graphic: ' e.message ' | Alternative method: ' e2.message], 'Save Error');
        end
    end

        end

        % Value changed function: ListBox2_2
        function ListBox2_2ValueChanged(app, event)
            PolarMeasurement(app);
        end

        % Button down function: AcqParametersTab
        function AcqParametersTabButtonDown(app, event)
           % Button down function: AcqParametersTab
    if ~isempty(app.Data1)
        PRM = app.LoadedData1;
        PRM_tid = PRM.tid;
        PRM_tim = PRM.tim;
        PRM_tid = double(PRM_tid)';
        PRM_traces = [app.Data1(:,1), app.Data1(:,2), app.Data1(:,3), PRM_tid(:,1)];

        % Handle CFR (Count to Fluorescence Ratio) calculation based on data format
        CFR = [];
        if isfield(PRM, 'itr') && isfield(PRM.itr, 'efc') && isfield(PRM.itr, 'efo')
            % Old format - has itr structure with efc and efo fields
            CFR = PRM.itr.efc ./ PRM.itr.efo;
        elseif isfield(PRM, 'efc') && isfield(PRM, 'efo')
            % New format - efc and efo are direct fields
            CFR = PRM.efc ./ PRM.efo;
        else
            % No CFR data available - skip CFR-related calculations
            fprintf('Note: CFR (Count to Fluorescence Ratio) data not available in this dataset\n');
        end

        PRM_traces_filt = PRM_traces;

        loc_per_trace_threshold = 3;
        % Find unique trace identifiers and their indices in PRM_traces
        [unique_trace_ids, ~, idx] = unique(PRM_traces(:,4));

        % Count the number of occurrences of each trace ID
        trace_counts = accumarray(idx, 1);

        % Find trace IDs that meet the threshold
        valid_trace_ids = unique_trace_ids(trace_counts >= loc_per_trace_threshold);

        % Filter the traces based on the valid trace IDs
        PRM_traces_filt = PRM_traces(ismember(PRM_traces(:,4), valid_trace_ids), :);

        [uv_tid, ~, id_tid] = unique(PRM_traces(:,4));
        n_tid = histcounts(id_tid, "BinWidth", 1);

        average_localizations_per_trace = mean(n_tid);
        app.Label_2.Text = ['Average localizations per trace: ', num2str(average_localizations_per_trace)];

        PRM_traces_filt = PRM_traces_filt(ismember(PRM_traces_filt(:,4), uv_tid(n_tid > loc_per_trace_threshold)), :);
        app.PRM_traces_filt = PRM_traces_filt;

        % Plot histogram of localizations per trace
        h = histogram(app.UIAxes3_2, id_tid, 'BinWidth', 1);
        counts = h.Values;
        max_counts = max(counts);
        new_ylim = max_counts + 0.1 * max_counts;
        xlabel(app.UIAxes3_2, "Trace ID");
        ylabel(app.UIAxes3_2, "Counts");
        title(app.UIAxes3_2, 'Counts per Trace ID');
        ylim(app.UIAxes3_2, [0 new_ylim]);
        xlim(app.UIAxes3_2, [0 inf]);

        % Normalize timestamps so the minimum is zero
        PRM_tim_norm = PRM_tim - min(PRM_tim);

        % Generate bin edges from start to end, one minute apart
        bin_edges = 0:60:max(PRM_tim_norm) + 60;  % Ensure it starts at 0 and covers the last bin

        % Calculate histogram counts
        [counts, edges] = histcounts(PRM_tim_norm, bin_edges);

        % Plotting the histogram
        bar(app.UIAxes3, edges(1:end-1)/60, counts, 'histc');  % Use 'histc' to ensure alignment with edges
        xlabel(app.UIAxes3, 'Time (minutes)');
        ylabel(app.UIAxes3, 'Number of Localizations');
        title(app.UIAxes3, 'Localizations Per Minute');
        xticks(app.UIAxes3, edges(1:5:end)/60);  % Set ticks at every 5 bins, converted to minutes
        % Adjust visual settings if necessary
        set(app.UIAxes3, 'FontSize', 12);  % Enhance font size for readability
        
        % Display format information
        if isfield(PRM, 'itr')
            fprintf('Using old MINFLUX data format for acquisition parameters\n');
        else
            fprintf('Using new MINFLUX data format for acquisition parameters\n');
        end
        
    else
        % No data loaded - could display a message or leave plots empty
        cla(app.UIAxes3);
        cla(app.UIAxes3_2);
        app.Label_2.Text = 'No data loaded';
    end

        end

        % Value changed function: DBSCANCheckBox
        function DBSCANCheckBoxValueChanged(app, event)

            updatePlots2(app);

        end

        % Value changed function: ColorCheck
        function ColorCheckValueChanged(app, event)

            PolarMeasurement(app);

        end

        % Callback function
        function LoadBeadDataButtonPushed(app, event)
            if isempty(app.Data1)
                uialert(app.DBSCAN, 'Please load your primary data first.', 'Data Missing');
                return;
            end
            [file, path] = uigetfile('*.mat', 'Select a MATLAB data file');
            if isequal(file, 0)
                app.ZarrLabel.Text = 'No file selected';
                return;
            end
            fullPath = fullfile(path, file);
            processBeadDataFile(app, fullPath);

        end

        % Button down function: FieldofViewMultiTab
        function FieldofViewMultiTabButtonDown(app, event)
            % Check if there's any data loaded
            if isempty(app.Data1)
                % Display a message to the user
                uialert(app.DBSCAN, 'Please load data for Channel 1 first.', 'No Data Loaded');
                return;
            end

            % Initialize the channel list if it's empty
            if isempty(app.ChannelListBox.Items)
                app.ChannelListBox.Items = {'Channel 1', 'Add Channel'};
                updateChannelDetails(app, 'Channel 1');
            end

            % Update the plots
            app.updatePlots2();


            % Update checkbox visibility
            app.updateCheckboxVisibility();

        end

        % Value changed function: ReferenceStainingDropDown
        function ReferenceStainingDropDownValueChanged(app, event)
            app.calculateCorrections();
            app.updateMulticolorTabFromCorrections();
            app.updateAlignment();
            app.visualizeBeadLocalizations();

        end

        % Button pushed function: ExportasmattifButton
        function ExportasmattifButtonPushed(app, event)
            ExportButtonPushed(app);

        end

        % Button down function: LocalizationPrecisionTab
        function LocalizationPrecisionTabButtonDown(app, event)
            % Check if there's any data loaded
            if isempty(app.Data1)
                % Display a message to the user
                uialert(app.DBSCAN, 'Please load data for Channel 1 first.', 'No Data Loaded');
                return;
            end

            app.updateLocalizationPrecisionTab();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.PolarMeasurement(app);

        end

        % Selection changed function: ButtonGroup1
        function ButtonGroup1SelectionChanged(app, event)
            app.Measurement();
        end

        % Button down function: NumbersTab
        function NumbersTabButtonDown(app, event)
            app.updateClusterInfo();

        end

        % Button down function: NumbersmultiTab
        function NumbersmultiTabButtonDown(app, event)
            updateClusterInfo(app);
            updateMultiChannelClusterInfo(app);

        end

        % Value changed function: NormalizeIndividuallyCheckBox
        function NormalizeIndividuallyCheckBoxValueChanged(app, event)
            Measurement(app);
        end

        % Button pushed function: SaveLocPrecPDFButton
        function SaveLocPrecPDFButtonPushed2(app, event)
            % Get all axes in the LocPrecTab
            axesHandles = findobj(app.LocalizationPrecisionTab, 'Type', 'axes');

            if isempty(axesHandles)
                uialert(app.DBSCAN, 'No plots to save.', 'No Data');
                return;
            end

            % Ask user for save location
            saveDir = uigetdir(pwd, 'Select Directory to Save PNG Files');
            if isequal(saveDir, 0)
                return;
            end

            % Loop through each axes and save as PNG
            for i = 1:length(axesHandles)
                ax = axesHandles(i);

                % Create a new figure with a specific size
                fig = figure('Visible', 'off', 'Units', 'pixels', 'Position', [0, 0, 600, 450]);

                % Copy the axes to the new figure and adjust its position
                newAx = copyobj(ax, fig);
                set(newAx, 'Units', 'normalized', 'Position', [0.1, 0.1, 0.8, 0.8]);

                % Ensure all elements are within the axes
                axis(newAx, 'tight');

                % Get the title of the plot
                titleObj = get(newAx, 'Title');
                titleText = get(titleObj, 'String');
                if iscell(titleText)
                    titleText = strjoin(titleText, '_');
                end

                % Remove any characters that are not suitable for filenames
                titleText = regexprep(titleText, '[^\w\d]+', '_');

                % Create filename
                filename = fullfile(saveDir, sprintf('LocPrec_Plot_%d_%s.png', i, titleText));

                % Save the figure as PNG
                print(fig, filename, '-dpng', '-r300');  % 300 dpi resolution

                % Close the temporary figure
                close(fig);
            end

            % Inform user that saving is complete
            uialert(app.DBSCAN, sprintf('Localization precision plots have been saved as PNG files in:\n%s', saveDir), 'Save Complete', 'Icon', 'success');

        end

        % Button pushed function: SaveNumMultCSVButton
        function SaveNumMultCSVButtonPushed(app, event)
            % Check if the table has data
            if isempty(app.UITableMulti.Data)
                uialert(app.DBSCAN, 'No data to save.', 'Empty Table');
                return;
            end

            % Ask user for save location
            [fileName, pathName] = uiputfile('*.csv', 'Save NumMult Table as CSV');
            if isequal(fileName, 0) || isequal(pathName, 0)
                return;
            end

            fullPath = fullfile(pathName, fileName);

            % Get table data and column names
            data = app.UITableMulti.Data;
            columnNames = app.UITableMulti.ColumnName;

            % Create a table with column names
            T = array2table(data, 'VariableNames', columnNames);

            % Write the table to a CSV file
            writetable(T, fullPath);

            % Inform user that saving is complete
            uialert(app.DBSCAN, sprintf('NumMult table has been saved as CSV:\n%s', fullPath), 'Save Complete', 'Icon', 'success');

        end

        % Value changed function: AlphaShapeCheckBox
        function AlphaShapeCheckBoxValueChanged(app, event)
            app.AlphaRadiusSlider.Visible = app.AlphaShapeCheckBox.Value;
            app.AlphaRadiusLabel.Visible = app.AlphaShapeCheckBox.Value;
            plotSelectedClusters(app);
        end

        % Value changed function: AlphaRadiusSlider
        function AlphaRadiusSliderValueChanged(app, event)
            app.AlphaRadiusLabel.Text = sprintf('Alpha Radius: %.2f', event.Value);
            plotSelectedClusters(app);
        end

        % Value changed function: ChannelListBox
        function ChannelListBoxValueChanged(app, event)
            selectedChannel = app.ChannelListBox.Value;
            if strcmp(selectedChannel, 'New Channel') || strcmp(selectedChannel, 'Add Channel')
                % Trigger the load function
                app.LoadChannelButtonPushed();
            else
                updateChannelDetails(app, selectedChannel);
            end
        end

        % Menu selected function: ColorsMenu
        function ColorsMenuSelected(app, event)
    % Create a new figure for color preferences with better size
    colorFig = uifigure('Name', 'Color Preferences', 'Position', [100 100 450 550]);

    % Initialize UserData if it doesn't exist
    if isempty(app.DBSCAN.UserData)
        app.DBSCAN.UserData = struct();
    end

    % -------------------------------
    % SECTION 1: Coloring Mode
    % -------------------------------
    yPos = 510;
    
    % Title for coloring mode section
    uilabel(colorFig, 'Text', '1. Cluster Coloring Mode', ...
        'Position', [20 yPos 200 25], ...
        'FontSize', 12, 'FontWeight', 'bold');
    
    yPos = yPos - 30;
    
    % Explanation text
    helpText1 = uilabel(colorFig, ...
        'Text', 'How should clusters be colored?', ...
        'Position', [20 yPos 400 20], ...
        'FontSize', 10, 'FontColor', [0.4 0.4 0.4]);
    
    yPos = yPos - 30;
    
    % Define a list of available coloring modes
    coloringModes = {'Default', 'Z-Position', 'Localizations', 'Traces'};
    
    % Get the current coloring mode
    currentColoringMode = getColoringMode(app);
    if isempty(currentColoringMode) || ~ismember(currentColoringMode, coloringModes)
        currentColoringMode = 'Default';
    end
    
    % Coloring mode dropdown
    uilabel(colorFig, 'Text', 'Mode:', 'Position', [30 yPos 50 22]);
    coloringModeDropdown = uidropdown(colorFig, 'Items', coloringModes, ...
        'Position', [90 yPos 150 22], ...
        'Value', currentColoringMode, ...
        'Tooltip', 'Choose how to color your data points');
    
    yPos = yPos - 30;
    
    % Mode descriptions
    modeDescText = uilabel(colorFig, ...
        'Text', '', ...
        'Position', [30 yPos-60 390 60], ...
        'FontSize', 9, 'FontColor', [0.3 0.3 0.5], ...
        'WordWrap', 'on');
    
    % Update description based on mode
    function updateModeDescription(mode)
        switch mode
            case 'Default'
                desc = 'Uses individual channel colors (set below). Each cluster gets its own color from the selected palette.';
            case 'Z-Position'
                desc = 'Colors points by their Z-coordinate using the selected colormap. Lower Z = one color, higher Z = another color.';
            case 'Localizations'
                desc = 'Colors clusters by number of localizations using the selected colormap. More localizations = different color.';
            case 'Traces'
                desc = 'Colors clusters by number of traces using the selected colormap. More traces = different color.';
            otherwise
                desc = '';
        end
        modeDescText.Text = desc;
    end
    
    % Set initial description
    updateModeDescription(currentColoringMode);
    
    % Update description when mode changes
    coloringModeDropdown.ValueChangedFcn = @(src, event) updateModeDescriptionAndMode(src.Value);
    
    function updateModeDescriptionAndMode(mode)
        updateModeDescription(mode);
        updateColoringMode(app, mode);
        % Update panel visibility
        updatePanelVisibility(mode);
    end
    
    yPos = yPos - 90;
    
    % -------------------------------
    % SECTION 2: Colormap (for data-driven modes)
    % -------------------------------
    colormapPanel = uipanel(colorFig, 'Title', '2. Colormap Selection (for Z/Loc/Trace modes)', ...
        'Position', [10 yPos-80 430 90], ...
        'FontSize', 11, 'FontWeight', 'bold');
    
    % Explanation
    uilabel(colormapPanel, ...
        'Text', 'Choose a colormap for gradient-based coloring:', ...
        'Position', [10 45 300 20], ...
        'FontSize', 10, 'FontColor', [0.4 0.4 0.4]);
    
    % Define a list of available colormaps
    colormapList = {'parula','jet','hsv','hot','cool','spring','summer','autumn','winter','gray','turbo','viridis'};
    
    % Colormap dropdown
    uilabel(colormapPanel, 'Text', 'Colormap:', 'Position', [10 15 70 22]);
    colormapDropdown = uidropdown(colormapPanel, 'Items', colormapList, ...
        'Position', [90 15 120 22], ...
        'Value', app.currentcolormap, ...
        'Tooltip', 'Select a colormap for gradient coloring');
    colormapDropdown.ValueChangedFcn = @(src, event) updateColormap(app, src.Value);
    
    yPos = yPos - 100;
    
    % -------------------------------
    % SECTION 3: Individual Channel Colors (for Default mode)
    % -------------------------------
    channelPanel = uipanel(colorFig, 'Title', '3. Channel Colors (for Default mode)', ...
        'Position', [10 yPos-220 430 230], ...
        'FontSize', 11, 'FontWeight', 'bold');
    
    % Explanation
    uilabel(channelPanel, ...
        'Text', 'Set individual colors for each channel:', ...
        'Position', [10 180 300 20], ...
        'FontSize', 10, 'FontColor', [0.4 0.4 0.4]);
    
    % Define channel names and initialize buttons array
    channelNames = {'Channel 1', 'Channel 2', 'Channel 3', 'Channel 4', 'Channel 5'};
    app.ChannelColorButtons = cell(1, 5);
    
    % Create a button for each channel
    for i = 1:5
        labelY = 160 - (i-1)*30;
        uilabel(channelPanel, 'Text', [channelNames{i} ':'], ...
            'Position', [20 labelY 80 22]);
        
        % Color preview box
        colorBox = uipanel(channelPanel, ...
            'Position', [110 labelY 60 22], ...
            'BackgroundColor', app.getColor(app.ChannelColors{i}), ...
            'BorderType', 'line');
        
        % Select button
        app.ChannelColorButtons{i} = uibutton(channelPanel, 'push', ...
            'Text', 'Change', ...
            'Position', [180 labelY 70 22], ...
            'ButtonPushedFcn', @(btn,event) selectChannelColor(i, colorBox), ...
            'Tooltip', ['Click to change color for ' channelNames{i}]);
        
        % Store reference to color box for updates
        app.ChannelColorButtons{i}.UserData = colorBox;
    end
    
    % Nested function for color selection
    function selectChannelColor(channelIdx, colorBox)
        color = uisetcolor(colorBox.BackgroundColor, ['Select Color for Channel ' num2str(channelIdx)]);
        if length(color) == 3  % Valid color selected
            colorBox.BackgroundColor = color;
            app.ChannelColorButtons{channelIdx}.BackgroundColor = color;
        end
    end
    
    yPos = yPos - 240;
    
    % -------------------------------
    % Control panel visibility based on mode
    % -------------------------------
    function updatePanelVisibility(mode)
        if strcmp(mode, 'Default')
            channelPanel.Visible = 'on';
            colormapPanel.Visible = 'off';
        else
            channelPanel.Visible = 'off';
            colormapPanel.Visible = 'on';
        end
    end
    
    % Set initial visibility
    updatePanelVisibility(currentColoringMode);
    
    % -------------------------------
    % Action Buttons
    % -------------------------------
    uibutton(colorFig, 'push', 'Text', 'Apply Changes', ...
        'Position', [50 20 120 30], ...
        'FontSize', 11, 'FontWeight', 'bold', ...
        'ButtonPushedFcn', @(btn,event) applyAllColors());
    
    uibutton(colorFig, 'push', 'Text', 'Reset to Defaults', ...
        'Position', [180 20 120 30], ...
        'FontSize', 11, ...
        'ButtonPushedFcn', @(btn,event) resetColors(app));
    
    uibutton(colorFig, 'push', 'Text', 'Close', ...
        'Position', [310 20 100 30], ...
        'FontSize', 11, ...
        'ButtonPushedFcn', @(btn,event) close(colorFig));
    
    % Nested function to apply all color changes
    function applyAllColors()
        % Update channel colors from color boxes
        for i = 1:5
            if i <= length(app.ChannelColorButtons) && isvalid(app.ChannelColorButtons{i})
                colorBox = app.ChannelColorButtons{i}.UserData;
                app.ChannelColors{i} = colorBox.BackgroundColor;
            end
        end
        
        % Apply the colors
        applyColors(app);
        
        % Show confirmation
        uialert(colorFig, 'Color preferences applied successfully!', 'Success', 'Icon', 'success');
    end
    

        end

        % Menu selected function: CustomSizeMenu
        function CustomSizeMenuSelected(app, event)
    % Create comprehensive dialog for all size-related settings
    prompt = {
        'Analysis Box Size X (nm):', 
        'Analysis Box Size Y (nm):', 
        'Analysis Box Size Z (nm):', 
        'Max Analysis Distance (nm):'
    };
    
    dlgtitle = 'Analysis Size Parameters';
    dims = [1 40];  % Make input fields wider
    
    % Current values as defaults
    definput = {
        num2str(app.BoxSizeX), 
        num2str(app.BoxSizeY), 
        num2str(app.BoxSizeZ),
        num2str(app.MaxAnalysisDistance)
    };
    
    % Create the input dialog
    answer = inputdlg(prompt, dlgtitle, dims, definput);
    
    if ~isempty(answer)
        try
            % Parse inputs
            newBoxX = str2double(answer{1});
            newBoxY = str2double(answer{2});
            newBoxZ = str2double(answer{3});
            newMaxDist = str2double(answer{4});
            
            % Validate inputs
            if any([newBoxX, newBoxY, newBoxZ, newMaxDist] <= 0) || ...
               any(isnan([newBoxX, newBoxY, newBoxZ, newMaxDist]))
                error('All values must be positive numbers');
            end
            
            % Additional validation
            if newMaxDist > 1000
                warning('Analysis distance > 1000 nm may affect performance');
            end
            
            % Store old values for confirmation message
            oldBoxX = app.BoxSizeX;
            oldBoxY = app.BoxSizeY;
            oldBoxZ = app.BoxSizeZ;
            oldMaxDist = app.MaxAnalysisDistance;
            
            % Update app properties
            app.BoxSizeX = newBoxX;
            app.BoxSizeY = newBoxY;
            app.BoxSizeZ = newBoxZ;
            app.MaxAnalysisDistance = newMaxDist;
            
            % Refresh relevant analyses and plots
            app.plotSelectedClusters();
            app.plotSelectedClusters2();
            app.updateMultiChannelClusterInfo();
            
            % Update any existing line profile if clusters are selected
            try
                if ~isempty(app.ListBox2.Value) && ~strcmp(app.ListBox2.Value{1}, 'Item 1')
                    app.Measurement();
                end
            catch
                % Measurement might fail if no valid clusters selected
            end
            
            % Update any existing polar plot if clusters are selected
            try
                if ~isempty(app.ListBox2_2.Value) && ~strcmp(app.ListBox2_2.Value{1}, 'Item 1')
                    app.PolarMeasurement();
                end
            catch
                % PolarMeasurement might fail if no valid clusters selected
            end
            
            % Show confirmation with changes
            changeMsg = sprintf([...
                'Settings updated successfully!\n\n', ...
                'Changes:\n', ...
                '• Box Size: %dx%dx%d → %dx%dx%d nm\n', ...
                '• Max Distance: %d → %d nm\n\n', ...
                'Line profiles and polar plots will use the new range.'], ...
                oldBoxX, oldBoxY, oldBoxZ, newBoxX, newBoxY, newBoxZ, ...
                oldMaxDist, newMaxDist);
            
            msgbox(changeMsg, 'Settings Updated', 'help');
            
        catch ME
            errordlg(['Invalid input: ' ME.message], 'Input Error');
        end
    end

        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged2(app, event)
            plotSelectedClusters2(app);

        end

        % Value changed function: EllipsoidFitCheckBox_2
        function EllipsoidFitCheckBox_2ValueChanged(app, event)
            plotSelectedClusters2(app);

        end

        % Value changed function: AlphaShapeCheckBox_2
        function AlphaShapeCheckBox_2ValueChanged(app, event)
            value = app.AlphaShapeCheckBox_2.Value;
            app.AlphaRadiusSlider_2.Visible = value; % Changed from app.AlphaShapeCheckBox.Value
            app.AlphaRadiusLabel_2.Visible = value;  % Changed from app.AlphaShapeCheckBox.Value
            plotSelectedClusters2(app);
        end

        % Button down function: ClusterAnalysisMultiTab
        function ClusterAnalysisMultiTabButtonDown(app, event)

            % Check if there's any data loaded
            if isempty(app.Data1)
                % Display a message to the user
                uialert(app.DBSCAN, 'Please load data for Channel 1 first.', 'No Data Loaded');
                return;
            end
            plotSelectedClusters2(app);
            updateCheckboxVisibility(app);
        end

        % Value changed function: Ch5CheckBox
        function Ch5CheckBoxValueChanged(app, event)
            plotSelectedClusters2(app);
        end

        % Value changed function: Ch4CheckBox
        function Ch4CheckBoxValueChanged(app, event)
            plotSelectedClusters2(app);
        end

        % Value changed function: Ch3CheckBox
        function Ch3CheckBoxValueChanged(app, event)
            plotSelectedClusters2(app);
        end

        % Value changed function: Ch2CheckBox
        function Ch2CheckBoxValueChanged(app, event)
            plotSelectedClusters2(app);
        end

        % Callback function
        function Ch1CheckBoxValueChanged(app, event)
            plotSelectedClusters2(app);
        end

        % Callback function
        function BoxSizeMenuSelected(app, event)
            % Get current box size
            currentSize = sprintf('%dx%dx%d', app.BoxSizeX, app.BoxSizeY, app.BoxSizeZ);

            % Prompt user for new box size
            newSize = inputdlg('Enter analysis box size in nm (XxYxZ):', 'Set Analysis Box Size', 1, {currentSize});

            if ~isempty(newSize)
                sizes = sscanf(newSize{1}, '%dx%dx%d');
                if length(sizes) == 3 && all(sizes > 0)
                    app.BoxSizeX = sizes(1);
                    app.BoxSizeY = sizes(2);
                    app.BoxSizeZ = sizes(3);

                    % Update the multi-channel analysis
                    app.updateMultiChannelClusterInfo();

                    % Optionally, update any visualizations that use the box size
                    app.updatePlots2();
                else
                    errordlg('Please enter a valid size in the format XxYxZ (e.g., 500x500x500).', 'Invalid Input');
                end
            end
        end

        % Button pushed function: LoadBeadDataButton
        function Zarr_extractPushed(app, event)
            % Ensure primary data is loaded
            if isempty(app.Data1)
                uialert(app.DBSCAN, 'Please load your primary data first.', 'Data Missing');
                return;
            end

            % Ask user for the folder containing Zarr data
            zarrFolder = uigetdir(pwd, 'Select the folder containing Zarr data');
            if isequal(zarrFolder, 0)
                uialert(app.DBSCAN, 'No folder selected for Zarr data.', 'Selection Cancelled');
                return;
            end

            % Check if the selected folder has the expected Zarr structure
            if ~app.isValidZarrFolder(zarrFolder)
                uialert(app.DBSCAN, ['The selected folder does not appear to be a valid Zarr dataset.' ...
                    newline newline 'Expected structure: A parent folder containing one or more staining folders,' ...
                    ' each containing one or more bead folders with .zattrs and .zarray files.'], ...
                    'Invalid Zarr Structure');
                return;
            end

            % Create a temporary directory in the user's documents folder
            tempDir = fullfile(userpath, 'matFLUX_Data');
            if ~exist(tempDir, 'dir')
                [status, msg] = mkdir(tempDir);
                if ~status
                    uialert(app.DBSCAN, ['Could not create directory: ' msg], 'Directory Error');
                    return;
                end
            end

            % Specify the full path for the output file
            outputFile = fullfile(tempDir, 'correction.mat');

            % Determine which zarr_extract.py to use (custom override or bundled)
            if isprop(app, 'CustomZarrScriptPath') && ...
                    ~isempty(app.CustomZarrScriptPath) && isfile(app.CustomZarrScriptPath)
                zarrScript = app.CustomZarrScriptPath;
                scriptFound = true;
            else
                scriptFound = false;

                % Initialize list of potential locations to search
                possiblePaths = {};

                % First check standard locations
                if isdeployed
                    % For deployed applications
                    appRoot = fileparts(ctfroot);
                    possiblePaths{end+1} = fullfile(appRoot, 'resources', 'zarr_extract.py');
                    possiblePaths{end+1} = fullfile(appRoot, 'zarr_extract.py');
                    possiblePaths{end+1} = fullfile(ctfroot, 'resources', 'zarr_extract.py');
                else
                    % For development in MATLAB
                    appDir = fileparts(mfilename('fullpath'));
                    possiblePaths{end+1} = fullfile(appDir, 'resources', 'zarr_extract.py');
                    possiblePaths{end+1} = fullfile(appDir, 'zarr_extract.py');
                end

                % Add other common locations
                possiblePaths{end+1} = fullfile(pwd, 'resources', 'zarr_extract.py');
                possiblePaths{end+1} = fullfile(pwd, 'zarr_extract.py');

                % Add system-specific locations
                if ~ispc  % Unix-like systems (Linux/macOS)
                    possiblePaths{end+1} = fullfile('/usr/share/matFLUX/resources', 'zarr_extract.py');
                    possiblePaths{end+1} = fullfile('/usr/local/share/matFLUX/resources', 'zarr_extract.py');
                    possiblePaths{end+1} = fullfile(getenv('HOME'), 'matFLUX', 'resources', 'zarr_extract.py');
                else  % Windows
                    possiblePaths{end+1} = fullfile(getenv('APPDATA'), 'matFLUX', 'resources', 'zarr_extract.py');
                    possiblePaths{end+1} = fullfile('C:', 'Program Files', 'matFLUX', 'resources', 'zarr_extract.py');
                    possiblePaths{end+1} = fullfile('C:', 'Program Files (x86)', 'matFLUX', 'resources', 'zarr_extract.py');
                end

                % First check all standard paths
                for i = 1:length(possiblePaths)
                    if exist(possiblePaths{i}, 'file')
                        zarrScript = possiblePaths{i};
                        scriptFound = true;
                        disp(['Found zarr_extract.py at: ' zarrScript]);
                        break;
                    end
                end

                % If not found, perform a recursive search in app folders
                if ~scriptFound
                    disp('Script not found in standard locations. Performing recursive search...');

                    % Define base directories to search recursively
                    searchDirs = {};
                    if isdeployed
                        searchDirs{end+1} = fileparts(ctfroot);
                    else
                        searchDirs{end+1} = fileparts(mfilename('fullpath'));
                    end
                    searchDirs{end+1} = pwd;
                    if ispc
                        searchDirs{end+1} = fullfile('C:', 'Program Files', 'matFLUX');
                        searchDirs{end+1} = fullfile('C:', 'Program Files (x86)', 'matFLUX');
                        searchDirs{end+1} = fullfile(getenv('APPDATA'), 'matFLUX');
                    else
                        searchDirs{end+1} = fullfile('/usr/share/matFLUX');
                        searchDirs{end+1} = fullfile('/usr/local/share/matFLUX');
                        searchDirs{end+1} = fullfile(getenv('HOME'), 'matFLUX');
                    end

                    % Set a limit on search depth to prevent excessive recursion
                    maxDepth = 3;

                    % Search each directory recursively
                    for i = 1:length(searchDirs)
                        if exist(searchDirs{i}, 'dir')
                            scriptPath = findScriptRecursively(searchDirs{i}, 'zarr_extract.py', maxDepth);
                            if ~isempty(scriptPath)
                                zarrScript = scriptPath;
                                scriptFound = true;
                                disp(['Found zarr_extract.py via recursive search at: ' zarrScript]);
                                break;
                            end
                        end
                    end
                end
            end

            % If script still not found, alert the user
            if ~scriptFound
                % Improved error message directing user to the Settings menu
                errMsg = ['The required script "zarr_extract.py" could not be found after extensive searching.' newline newline ...
                    'Please use the Settings → Script Location menu to manually locate the script file.' newline newline ...
                    'If you don''t have this script, please download it from the matFLUX website or contact the developer.'];
                uialert(app.DBSCAN, errMsg, 'Script Not Found', 'Icon', 'warning');

                % Log the searched locations for debugging purposes
                disp('Script not found after extensive searching. Check paths above for details.');
                return;
            end

            % Remove existing correction file to ensure fresh extraction
            if isfile(outputFile)
                try
                    delete(outputFile);
                    disp('Deleted existing correction.mat to force regeneration.');
                catch
                    warning('Could not delete existing correction.mat; it may be in use.');
                end
            end

            % Show progress dialog
            d = uiprogressdlg(app.DBSCAN, 'Title', 'Extracting Zarr Data', ...
                'Message', 'Running zarr_extract.py, please wait...', 'Indeterminate', 'on');
            try
                % Determine the Python executable (platform-specific)
                if ispc  % Windows
                    [~, raw] = system('where python');
                else    % macOS/Linux
                    [~, raw] = system('which python');
                    if contains(raw, 'not found')
                        [~, raw] = system('which python3');
                    end
                end
                % Split on any newline and only use the first non-empty line
                parts = regexp(raw, '\r\n|\n|\r', 'split');
                pythonPath = '';
                for i=1:numel(parts)
                    line = strtrim(parts{i});
                    if ~isempty(line)
                        pythonPath = line;
                        break;
                    end
                end


                % Log script location
                disp(['Using zarr_extract.py script from: ' zarrScript]);

                % Run the external Python script
                cmd = ['"' pythonPath '" "' zarrScript '" "' zarrFolder '" "' outputFile '"'];
                disp(['Running command: ' cmd]);
                [status, cmdout] = system(cmd);

                if status ~= 0
                    uialert(app.DBSCAN, ['Error running zarr_extract.py:' newline, cmdout], 'Execution Error');
                    close(d);
                    return;
                end

                % After successful execution, process the generated file
                if isfile(outputFile)
                    try
                        % Attempt to process the bead data file
                        processBeadDataFile(app, outputFile);
                        uialert(app.DBSCAN, 'Correction data loaded successfully.', 'Success', 'Icon', 'info');
                    catch ME
                        % Handle errors during processing of the correction file
                        errMsg = ['Error processing correction data:' newline, ME.message, newline newline, ...
                            'This may indicate a problem with the Zarr data format or extraction process.', newline, ...
                            'Make sure your Zarr data is valid and try again.', newline newline, ...
                            'If the problem persists, you can:' newline, ...
                            '1. Check that the script path is correct in Settings → Script Location' newline, ...
                            '2. Try loading a pre-extracted correction.mat file directly' newline, ...
                            '3. Contact the developer for assistance'];

                        uialert(app.DBSCAN, errMsg, 'Processing Error', 'Icon', 'error');
                        disp(['Error details: ' getReport(ME)]);
                    end
                else
                    % Handle case where correction.mat was not created at all
                    errMsg = ['The correction file was not created at: ' outputFile newline newline ...
                        'Possible causes:' newline ...
                        '1. The Python script encountered an error' newline ...
                        '2. The zarr_extract.py script may be corrupted or incompatible' newline ...
                        '3. The selected Zarr folder structure is not as expected' newline newline ...
                        'Troubleshooting steps:' newline ...
                        '1. Check the script output below for specific errors' newline ...
                        '2. Verify that your Python environment has the necessary libraries (numpy, zarr)' newline ...
                        '3. Set a custom script location via Settings → Script Location' newline newline ...
                        'Script output:' newline cmdout];

                    uialert(app.DBSCAN, errMsg, 'Correction File Not Found', 'Icon', 'error');
                end
            catch ME
                uialert(app.DBSCAN, ['An error occurred:' newline, ME.message], 'Error');
                disp(['Error details: ' getReport(ME)]);
            end
            close(d);

        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            aboutText = sprintf(['matFLUX 1.0\n' ...
                'Developed by Moritz Hacke\n' ...
                'This application processes MINFLUX localization data and performs alignment, clustering, and visualization.\n' ...
                'Please visit: matflux.mhke.de']);
            % Display a uialert with an info icon
            uialert(app.DBSCAN, aboutText, 'About matFLUX 1.0', 'Icon', 'info');

        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            % Create a basic help window using standard figure
            helpFig = figure('Name', 'Help - matFLUX 1.0', 'Position', [200 200 600 500], ...
                'NumberTitle', 'off', 'MenuBar', 'none', 'ToolBar', 'none');

            % Combine all the help text into one FAQ-style content
            helpText = sprintf([...
                'matFLUX 1.0 - Help Menu\n\n', ...
                'OVERVIEW:\n', ...
                'matFLUX is a MATLAB-based application for processing MINFLUX localization data. ', ...
                'It integrates advanced clustering (using DBSCAN), alignment, multi-channel visualization, ', ...
                'and quantitative analysis to help you extract spatial and temporal information from super-resolution microscopy datasets.\n\n', ...
                'SYSTEM REQUIREMENTS:\n', ...
                '- MATLAB (with App Designer support).\n', ...
                '- Statistics and Machine Learning Toolbox (for DBSCAN clustering).\n', ...
                '- Python installed (for optional Zarr data extraction).\n', ...
                '- MINFLUX .mat files containing required fields (e.g., ''itr.loc'', ''tid'', ''tim'').\n\n', ...
                'GETTING STARTED:\n', ...
                '1. Launch the application. A welcome dialog will guide you through the basic workflow.\n', ...
                '2. Click the "Load/New" button to load your primary MINFLUX .mat file. Ensure that your file contains the expected structure.\n', ...
                '3. Once loaded, your primary data will appear in the "Field of View" tab.\n', ...
                '4. Use the "Load Channel" button to add additional channels for multi-channel analysis.\n\n', ...
                'DATA LOADING & CHANNEL MANAGEMENT:\n', ...
                '- Primary Channel: Loaded via "Load/New" (contains the main localization data).\n', ...
                '- Additional Channels: Load using "Load Channel". Each channel can be individually enabled/disabled and corrected for drift.\n\n', ...
                'ANALYSIS & VISUALIZATION PANELS:\n', ...
                '• Field of View Tab: Displays the overall localization field after DBSCAN clustering. ', ...
                'Use the "Show Noise" toggle to filter out noise points.\n', ...
                '• Single Cluster Analysis Tab: Focus on individual clusters. Options include fitting alpha shapes and ellipsoids for spatial characterization.\n', ...
                '• Multi-Channel Overview Tab: Align and compare data from multiple channels. Adjust x, y, and z shifts as needed.\n', ...
                '• Multi Cluster Analysis Tab: Compare clusters across channels and generate statistical summaries.\n', ...
                '• Line Profile & Polar Plot Tabs: Generate distance histograms, line profiles, and polar scatter plots to study radial distributions from cluster centroids.\n', ...
                '• Numbers & Quality Control Tabs: View summary tables of cluster statistics (e.g., volume, surface area, trace counts) and assess data quality over time.\n', ...
                '• Localization Precision Tab: Visualize histograms and error estimates for localization precision from your data.\n\n', ...
                'CLUSTERING & PARAMETER ADJUSTMENT:\n', ...
                '- Adjust DBSCAN parameters (Epsilon and MinPts) using the provided spinners. ', ...
                'Activate the exclusion option to filter out clusters with too few points.\n\n', ...
                'ALIGNMENT & CORRECTIONS:\n', ...
                '- Load bead data with the "Load Bead Data" button to perform drift correction.\n', ...
                '- Select a reference staining in the dropdown menu, and matFLUX will calculate alignment corrections for additional channels.\n', ...
                '- Correction factors (x, y, z) can be manually fine-tuned in the Multi-Channel Overview tab.\n\n', ...
                'EXPORTING DATA:\n', ...
                '- Use the "Export as .mat/.tif" button to save your processed data. ', ...
                'Choose to export corrected data as MATLAB files or multi-channel images as TIFF files.\n\n', ...
                'CUSTOMIZATION:\n', ...
                '- Colors: Change channel colors via the "Colors" menu. A dedicated color preferences window allows custom selection and resetting to defaults.\n', ...
                '- Box Size: Adjust the analysis region dimensions via the "Size" menu (format: XxYxZ in nm).\n', ...
                '- Colormap: Select a different colormap for your plots using the dropdown in the color preferences.\n\n', ...
                'TROUBLESHOOTING & FAQ:\n', ...
                'Q: What should I do if my data file does not load?\n', ...
                'A: Verify that the .mat file contains the required fields (e.g., ''itr.loc'', ''tid'', ''tim''). Ensure the file follows the expected structure.\n\n', ...
                'Q: How do I adjust the clustering parameters?\n', ...
                'A: Use the Epsilon and MinPts spinners to change the DBSCAN settings. Use the exclusion option to remove clusters with insufficient points.\n\n', ...
                'Q: My alignment seems off. What can I do?\n', ...
                'A: Make sure you have loaded the bead data correctly and selected the appropriate reference channel. Check that the bead data contains valid localization and timing information.\n\n', ...
                'Q: Where can I get further support or report issues?\n', ...
                'A: Visit our website or contact the developer using the information below.\n\n', ...
                'CONTACT & SUPPORT:\n', ...
                'Developer: Moritz Hacke\n', ...
                'Website: https://matflux.mhke.de/\n', ...
                'Email: support@matflux.mhke.de\n\n', ...
                'DISCLAIMER:\n', ...
                'This software is provided as-is without warranties. Please back up your data before processing. Use at your own risk.\n\n', ...
                'Version: 1.0\n' ...
                ]);


            % Create a scrollable text area to display all the help content
            helpTextArea = uicontrol(helpFig, 'Style', 'edit', 'String', helpText, ...
                'Position', [20 60 560 420], 'FontSize', 11, ...
                'HorizontalAlignment', 'left', 'Max', 50, ...
                'Min', 0, 'Enable', 'inactive', ...
                'BackgroundColor', [1 1 1]);

            % Add a button to open the website
            uicontrol(helpFig, 'Style', 'pushbutton', 'String', 'Open Online Documentation', ...
                'Position', [20 20 200 30], ...
                'Callback', @(btn,event) web('https://matflux.mhke.de/', '-browser'));

            % Add a close button
            uicontrol(helpFig, 'Style', 'pushbutton', 'String', 'Close', ...
                'Position', [490 20 90 30], ...
                'Callback', @(btn,event) close(helpFig));
        end

        % Menu selected function: ScriptLocation
        function ScriptLocationMenuSelected(app, event)
            % Let user choose the Python script location
            [file, path] = uigetfile({'*.py', 'Python Files (*.py)'}, 'Select zarr_extract.py Script');

            if isequal(file, 0) || isequal(path, 0)
                % User cancelled
                return;
            end

            fullPath = fullfile(path, file);

            % Verify that it's the correct file
            if ~strcmp(file, 'zarr_extract.py')
                response = questdlg(['Selected file is "' file '" not "zarr_extract.py". Do you want to use it anyway?'], ...
                    'Script Verification', 'Yes', 'No', 'No');
                if strcmp(response, 'No')
                    return;
                end
            end

            % Store the path as an application property
            if ~isprop(app, 'CustomZarrScriptPath')
                % Dynamically add the property if it doesn't exist
                addprop(app, 'CustomZarrScriptPath');
            end
            app.CustomZarrScriptPath = fullPath;

            % Provide feedback to the user
            msg = ['zarr_extract.py script location set to:' newline fullPath];
            uialert(app.DBSCAN, msg, 'Script Location Updated', 'Icon', 'success');
        end

        % Value changed function: AlphaRadiusSlider_2
        function AlphaRadiusSlider_2ValueChanged(app, event)
            app.AlphaRadiusLabel_2.Text = sprintf('Alpha Radius: %.2f', event.Value);
            plotSelectedClusters2(app);
        end

        % Menu selected function: CropDataMenu
        function CropDataMenuSelected(app, event)
    % Create a more compact figure with better proportions
    cropFig = uifigure('Name', 'Data Crop Settings', 'Position', [100 100 550 550]);
    
    % Current data bounds for reference
    if ~isempty(app.Data1)
        currentBounds = [min(app.Data1); max(app.Data1)];
        xRange = currentBounds(2,1) - currentBounds(1,1);
        yRange = currentBounds(2,2) - currentBounds(1,2);
        zRange = currentBounds(2,3) - currentBounds(1,3);
        totalPoints = size(app.Data1, 1);
    else
        currentBounds = [0 0 0; 1000 1000 1000];
        xRange = 1000; yRange = 1000; zRange = 1000;
        totalPoints = 0;
    end
    
    % -------------------------------
    % HEADER SECTION
    % -------------------------------
    yPos = 520;
    
    % Title and enable checkbox on same line
    uilabel(cropFig, 'Text', 'Crop Data to Region of Interest', ...
        'Position', [20 yPos 250 25], ...
        'FontSize', 13, 'FontWeight', 'bold');
    
    enableCropCheckbox = uicheckbox(cropFig, 'Text', 'Enable Cropping', ...
        'Position', [360 yPos 150 25], ...
        'Value', app.CropEnabled, ...
        'FontSize', 11, 'FontWeight', 'bold');
    
    yPos = yPos - 30;
    
    % Explanation text - more compact
    uilabel(cropFig, ...
        'Text', 'Focus on a specific spatial region. Points outside limits will be excluded.', ...
        'Position', [20 yPos 510 20], ...
        'FontSize', 10, 'FontColor', [0.4 0.4 0.4]);
    
    yPos = yPos - 30;
    
    % -------------------------------
    % CURRENT DATA INFO - More compact
    % -------------------------------
    infoPanel = uipanel(cropFig, 'Title', 'Current Data Range', ...
        'Position', [10 yPos-85 530 90], ...
        'FontSize', 10, 'FontWeight', 'bold');
    
    if ~isempty(app.Data1)
        % Data statistics - single line
        uilabel(infoPanel, ...
            'Text', sprintf('Total Points: %s', num2str(totalPoints, '%d')), ...
            'Position', [10 55 200 18], ...
            'FontSize', 10, 'FontWeight', 'bold');
        
        % Compact 3-column layout for ranges
        % X range
        uilabel(infoPanel, 'Text', 'X:', ...
            'Position', [10 35 15 18], 'FontWeight', 'bold', 'FontSize', 10);
        uilabel(infoPanel, ...
            'Text', sprintf('%.0f to %.0f nm', currentBounds(1,1), currentBounds(2,1)), ...
            'Position', [30 35 130 18], 'FontSize', 9);
        uilabel(infoPanel, ...
            'Text', sprintf('(%.0f nm)', xRange), ...
            'Position', [165 35 80 18], 'FontSize', 9, 'FontColor', [0.5 0.5 0.5]);
        
        % Y range
        uilabel(infoPanel, 'Text', 'Y:', ...
            'Position', [10 15 15 18], 'FontWeight', 'bold', 'FontSize', 10);
        uilabel(infoPanel, ...
            'Text', sprintf('%.0f to %.0f nm', currentBounds(1,2), currentBounds(2,2)), ...
            'Position', [30 15 130 18], 'FontSize', 9);
        uilabel(infoPanel, ...
            'Text', sprintf('(%.0f nm)', yRange), ...
            'Position', [165 15 80 18], 'FontSize', 9, 'FontColor', [0.5 0.5 0.5]);
        
        % Z range
        uilabel(infoPanel, 'Text', 'Z:', ...
            'Position', [270 35 15 18], 'FontWeight', 'bold', 'FontSize', 10);
        uilabel(infoPanel, ...
            'Text', sprintf('%.0f to %.0f nm', currentBounds(1,3), currentBounds(2,3)), ...
            'Position', [290 35 130 18], 'FontSize', 9);
        uilabel(infoPanel, ...
            'Text', sprintf('(%.0f nm)', zRange), ...
            'Position', [425 35 80 18], 'FontSize', 9, 'FontColor', [0.5 0.5 0.5]);
    else
        uilabel(infoPanel, ...
            'Text', 'No data loaded. Load data first to enable cropping.', ...
            'Position', [10 30 510 18], ...
            'FontSize', 10, 'FontColor', [0.6 0.6 0.6]);
    end
    
    yPos = yPos - 100;
    
    % -------------------------------
    % CROP LIMITS - Compact grid layout
    % -------------------------------
    limitsPanel = uipanel(cropFig, 'Title', 'Set Crop Limits (nm)', ...
        'Position', [10 yPos-155 530 160], ...
        'FontSize', 10, 'FontWeight', 'bold');
    
    % Determine initial values
    if isinf(app.CropLimits.XMin) && ~isempty(app.Data1)
        initialXMin = round(currentBounds(1,1));
    else
        initialXMin = round(app.CropLimits.XMin);
    end
    
    if isinf(app.CropLimits.XMax) && ~isempty(app.Data1)
        initialXMax = round(currentBounds(2,1));
    else
        initialXMax = round(app.CropLimits.XMax);
    end
    
    if isinf(app.CropLimits.YMin) && ~isempty(app.Data1)
        initialYMin = round(currentBounds(1,2));
    else
        initialYMin = round(app.CropLimits.YMin);
    end
    
    if isinf(app.CropLimits.YMax) && ~isempty(app.Data1)
        initialYMax = round(currentBounds(2,2));
    else
        initialYMax = round(app.CropLimits.YMax);
    end
    
    if isinf(app.CropLimits.ZMin) && ~isempty(app.Data1)
        initialZMin = round(currentBounds(1,3));
    else
        initialZMin = round(app.CropLimits.ZMin);
    end
    
    if isinf(app.CropLimits.ZMax) && ~isempty(app.Data1)
        initialZMax = round(currentBounds(2,3));
    else
        initialZMax = round(app.CropLimits.ZMax);
    end
    
    % Compact grid layout - all three axes visible at once
    yPosInPanel = 115;
    
    % Headers
    uilabel(limitsPanel, 'Text', 'Axis', ...
        'Position', [15 yPosInPanel 60 20], ...
        'FontWeight', 'bold', 'FontSize', 10);
    uilabel(limitsPanel, 'Text', 'Min', ...
        'Position', [90 yPosInPanel 80 20], ...
        'FontWeight', 'bold', 'FontSize', 10, 'HorizontalAlignment', 'center');
    uilabel(limitsPanel, 'Text', 'Max', ...
        'Position', [200 yPosInPanel 80 20], ...
        'FontWeight', 'bold', 'FontSize', 10, 'HorizontalAlignment', 'center');
    uilabel(limitsPanel, 'Text', 'Span', ...
        'Position', [310 yPosInPanel 100 20], ...
        'FontWeight', 'bold', 'FontSize', 10, 'HorizontalAlignment', 'center');
    
    yPosInPanel = yPosInPanel - 35;
    
    % X-axis row
    uilabel(limitsPanel, 'Text', 'X', ...
        'Position', [15 yPosInPanel 60 22], ...
        'FontSize', 10, 'FontWeight', 'bold');
    xMinField = uieditfield(limitsPanel, 'numeric', ...
        'Position', [80 yPosInPanel 90 22], ...
        'Value', initialXMin, ...
        'RoundFractionalValues', 'on', ...
        'ValueDisplayFormat', '%.0f');
    xMaxField = uieditfield(limitsPanel, 'numeric', ...
        'Position', [190 yPosInPanel 90 22], ...
        'Value', initialXMax, ...
        'RoundFractionalValues', 'on', ...
        'ValueDisplayFormat', '%.0f');
    xSpanLabel = uilabel(limitsPanel, ...
        'Text', sprintf('%.0f nm', initialXMax - initialXMin), ...
        'Position', [300 yPosInPanel 100 22], ...
        'FontSize', 9, 'FontColor', [0.4 0.4 0.4], ...
        'HorizontalAlignment', 'center');
    
    yPosInPanel = yPosInPanel - 30;
    
    % Y-axis row
    uilabel(limitsPanel, 'Text', 'Y', ...
        'Position', [15 yPosInPanel 60 22], ...
        'FontSize', 10, 'FontWeight', 'bold');
    yMinField = uieditfield(limitsPanel, 'numeric', ...
        'Position', [80 yPosInPanel 90 22], ...
        'Value', initialYMin, ...
        'RoundFractionalValues', 'on', ...
        'ValueDisplayFormat', '%.0f');
    yMaxField = uieditfield(limitsPanel, 'numeric', ...
        'Position', [190 yPosInPanel 90 22], ...
        'Value', initialYMax, ...
        'RoundFractionalValues', 'on', ...
        'ValueDisplayFormat', '%.0f');
    ySpanLabel = uilabel(limitsPanel, ...
        'Text', sprintf('%.0f nm', initialYMax - initialYMin), ...
        'Position', [300 yPosInPanel 100 22], ...
        'FontSize', 9, 'FontColor', [0.4 0.4 0.4], ...
        'HorizontalAlignment', 'center');
    
    yPosInPanel = yPosInPanel - 30;
    
    % Z-axis row
    uilabel(limitsPanel, 'Text', 'Z', ...
        'Position', [15 yPosInPanel 60 22], ...
        'FontSize', 10, 'FontWeight', 'bold');
    zMinField = uieditfield(limitsPanel, 'numeric', ...
        'Position', [80 yPosInPanel 90 22], ...
        'Value', initialZMin, ...
        'RoundFractionalValues', 'on', ...
        'ValueDisplayFormat', '%.0f');
    zMaxField = uieditfield(limitsPanel, 'numeric', ...
        'Position', [190 yPosInPanel 90 22], ...
        'Value', initialZMax, ...
        'RoundFractionalValues', 'on', ...
        'ValueDisplayFormat', '%.0f');
    zSpanLabel = uilabel(limitsPanel, ...
        'Text', sprintf('%.0f nm', initialZMax - initialZMin), ...
        'Position', [300 yPosInPanel 100 22], ...
        'FontSize', 9, 'FontColor', [0.4 0.4 0.4], ...
        'HorizontalAlignment', 'center');
    
    % Add value changed callbacks
    xMinField.ValueChangedFcn = @(src,event) updateSpans();
    xMaxField.ValueChangedFcn = @(src,event) updateSpans();
    yMinField.ValueChangedFcn = @(src,event) updateSpans();
    yMaxField.ValueChangedFcn = @(src,event) updateSpans();
    zMinField.ValueChangedFcn = @(src,event) updateSpans();
    zMaxField.ValueChangedFcn = @(src,event) updateSpans();
    
    function updateSpans()
        xSpanLabel.Text = sprintf('%.0f nm', xMaxField.Value - xMinField.Value);
        ySpanLabel.Text = sprintf('%.0f nm', yMaxField.Value - yMinField.Value);
        zSpanLabel.Text = sprintf('%.0f nm', zMaxField.Value - zMinField.Value);
        updatePreview();
    end
    
    yPos = yPos - 170;
    
    % -------------------------------
    % PREVIEW - Compact single line
    % -------------------------------
    previewPanel = uipanel(cropFig, 'Title', 'Preview', ...
        'Position', [10 yPos-50 530 55], ...
        'FontSize', 10, 'FontWeight', 'bold', ...
        'BackgroundColor', [0.96 0.96 0.98]);
    
    previewLabel = uilabel(previewPanel, ...
        'Text', 'Calculating...', ...
        'Position', [10 8 510 30], ...
        'FontSize', 11, ...
        'FontWeight', 'bold', ...
        'HorizontalAlignment', 'center');
    
    function updatePreview()
        if ~isempty(app.Data1)
            withinX = (app.Data1(:,1) >= xMinField.Value) & (app.Data1(:,1) <= xMaxField.Value);
            withinY = (app.Data1(:,2) >= yMinField.Value) & (app.Data1(:,2) <= yMaxField.Value);
            withinZ = (app.Data1(:,3) >= zMinField.Value) & (app.Data1(:,3) <= zMaxField.Value);
            withinAll = withinX & withinY & withinZ;
            
            retainedPoints = sum(withinAll);
            percentRetained = (retainedPoints / totalPoints) * 100;
            
            if percentRetained >= 95
                color = [0 0.5 0];
                icon = '✓';
            elseif percentRetained >= 50
                color = [0.8 0.5 0];
                icon = '⚠';
            else
                color = [0.8 0 0];
                icon = '⚠';
            end
            
            previewLabel.Text = sprintf('%s  Will retain %d of %d points (%.1f%%)', ...
                icon, retainedPoints, totalPoints, percentRetained);
            previewLabel.FontColor = color;
        else
            previewLabel.Text = 'Load data to see preview';
            previewLabel.FontColor = [0.5 0.5 0.5];
        end
    end
    
    updatePreview();
    
    yPos = yPos - 65;
    
    % -------------------------------
    % QUICK PRESETS & BUTTONS - Combined row
    % -------------------------------
    buttonY = 15;
    
    % Preset buttons - smaller, in a row
    uibutton(cropFig, 'push', 'Text', 'Full Range', ...
        'Position', [20 buttonY+35 100 28], ...
        'FontSize', 10, ...
        'ButtonPushedFcn', @(btn,event) autoFitCrop());
    
    uibutton(cropFig, 'push', 'Text', 'Center 80%', ...
        'Position', [130 buttonY+35 100 28], ...
        'FontSize', 10, ...
        'ButtonPushedFcn', @(btn,event) centerCrop());
    
    % Main action buttons
    uibutton(cropFig, 'push', 'Text', 'Apply Crop', ...
        'Position', [20 buttonY 150 30], ...
        'FontSize', 11, 'FontWeight', 'bold', ...
        'BackgroundColor', [0.2 0.6 0.2], ...
        'FontColor', 'white', ...
        'ButtonPushedFcn', @(btn,event) applyCrop());
    
    uibutton(cropFig, 'push', 'Text', 'Cancel', ...
        'Position', [180 buttonY 90 30], ...
        'FontSize', 10, ...
        'ButtonPushedFcn', @(btn,event) close(cropFig));
    
    uibutton(cropFig, 'push', 'Text', 'Help', ...
        'Position', [280 buttonY 90 30], ...
        'FontSize', 10, ...
        'ButtonPushedFcn', @(btn,event) showCropHelp());
    
    % -------------------------------
    % HELPER FUNCTIONS
    % -------------------------------
    
    function autoFitCrop()
        if ~isempty(app.Data1)
            xMinField.Value = round(currentBounds(1,1));
            xMaxField.Value = round(currentBounds(2,1));
            yMinField.Value = round(currentBounds(1,2));
            yMaxField.Value = round(currentBounds(2,2));
            zMinField.Value = round(currentBounds(1,3));
            zMaxField.Value = round(currentBounds(2,3));
            updateSpans();
        end
    end
    
    function resetCrop()
        choice = uiconfirm(cropFig, ...
            'Remove all cropping limits and show all data points?', ...
            'Remove All Limits', ...
            'Options', {'Yes', 'No'}, ...
            'DefaultOption', 2);
        
        if strcmp(choice, 'Yes')
            xMinField.Value = -inf;
            xMaxField.Value = inf;
            yMinField.Value = -inf;
            yMaxField.Value = inf;
            zMinField.Value = -inf;
            zMaxField.Value = inf;
            xSpanLabel.Text = 'Unlimited';
            ySpanLabel.Text = 'Unlimited';
            zSpanLabel.Text = 'Unlimited';
            updatePreview();
        end
    end
    
    function centerCrop()
        if ~isempty(app.Data1)
            xPadding = xRange * 0.1;
            yPadding = yRange * 0.1;
            zPadding = zRange * 0.1;
            
            xMinField.Value = round(currentBounds(1,1) + xPadding);
            xMaxField.Value = round(currentBounds(2,1) - xPadding);
            yMinField.Value = round(currentBounds(1,2) + yPadding);
            yMaxField.Value = round(currentBounds(2,2) - yPadding);
            zMinField.Value = round(currentBounds(1,3) + zPadding);
            zMaxField.Value = round(currentBounds(2,3) - zPadding);
            updateSpans();
        end
    end
    
    function applyCrop()
        app.CropEnabled = enableCropCheckbox.Value;
        app.CropLimits.XMin = xMinField.Value;
        app.CropLimits.XMax = xMaxField.Value;
        app.CropLimits.YMin = yMinField.Value;
        app.CropLimits.YMax = yMaxField.Value;
        app.CropLimits.ZMin = zMinField.Value;
        app.CropLimits.ZMax = zMaxField.Value;
        
        app.applyCroppingToAllData();
        app.updatePlots();
        app.updatePlots2();
        
        if app.CropEnabled && ~isempty(app.Data1)
            withinX = (app.OriginalData1(:,1) >= app.CropLimits.XMin) & (app.OriginalData1(:,1) <= app.CropLimits.XMax);
            withinY = (app.OriginalData1(:,2) >= app.CropLimits.YMin) & (app.OriginalData1(:,2) <= app.CropLimits.YMax);
            withinZ = (app.OriginalData1(:,3) >= app.CropLimits.ZMin) & (app.OriginalData1(:,3) <= app.CropLimits.ZMax);
            retained = sum(withinX & withinY & withinZ);
            original = size(app.OriginalData1, 1);
            
            cropInfo = sprintf('Crop applied! Retained %d of %d points (%.1f%%)', ...
                retained, original, (retained/original)*100);
            uialert(cropFig, cropInfo, 'Success', 'Icon', 'success');
        else
            uialert(cropFig, 'Cropping disabled. Original data restored.', ...
                'Disabled', 'Icon', 'info');
        end
        
    end
    
    function showCropHelp()
        helpText = sprintf(['DATA CROPPING HELP\n\n' ...
            'What: Limit your data to a specific 3D region\n\n' ...
            'When to use:\n' ...
            '  • Focus on region of interest\n' ...
            '  • Remove edge artifacts\n' ...
            '  • Exclude background areas\n\n' ...
            'How: Enter min/max values for X, Y, Z axes.\n' ...
            'Only points within ALL ranges are kept.\n\n' ...
            'Presets:\n' ...
            '  • Full Range: Use complete data\n' ...
            '  • Center 80%%: Remove 10%% from edges\n' ...
            '  • No Limits: Remove all restrictions\n\n' ...
            'Note: Original data is preserved.']);
        
        uialert(cropFig, helpText, 'Help', 'Icon', 'info');
    end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DBSCAN = uifigure('Visible', 'off');
            app.DBSCAN.Name = 'matFLUX 1.0';
            app.SettingsMenu = uimenu(app.DBSCAN);
            app.SettingsMenu.Text = 'Settings';
            app.CropDataMenu = uimenu(app.SettingsMenu);
            app.CropDataMenu.MenuSelectedFcn = createCallbackFcn(app, @CropDataMenuSelected, true);
            app.CropDataMenu.Text = 'Crop Data';
            app.ColorsMenu = uimenu(app.SettingsMenu);
            app.ColorsMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorsMenuSelected, true);
            app.ColorsMenu.Text = 'Colors';
            app.CustomSizeMenu = uimenu(app.SettingsMenu);
            app.CustomSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @CustomSizeMenuSelected, true);
            app.CustomSizeMenu.Text = 'Particle Size';
            app.ScriptLocation = uimenu(app.SettingsMenu);
            app.ScriptLocation.MenuSelectedFcn = createCallbackFcn(app, @ScriptLocationMenuSelected, true);
            app.ScriptLocation.Text = 'Script Location';
            app.HelpMenu = uimenu(app.DBSCAN);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Tooltip = {'Help Menu'};
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.DBSCAN);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Tooltip = {'Pop-up About'};
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.DBSCAN);
            title(app.UIAxes, 'Cluster Overview')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8];
            app.UIAxes.YScale = 'log';
            app.UIAxes.ZTick = [];
            app.LoadNewButton = uibutton(app.DBSCAN, 'push');
            app.LoadNewButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewButtonPushed, true);
            app.LoadNewButton.Tooltip = {'Click here to Load your first MINFLUX .mat file'};
            app.LoadNewButton.Text = 'Load/New';
            app.EpsilonLabel = uilabel(app.DBSCAN);
            app.EpsilonLabel.Text = 'Epsilon';
            app.EpsilonSpinner = uispinner(app.DBSCAN);
            app.EpsilonSpinner.Step = 0.5;
            app.EpsilonSpinner.ValueChangedFcn = createCallbackFcn(app, @EpsilonSpinnerValueChanged, true);
            app.EpsilonSpinner.Tooltip = {'Adjust DBSCAN Epsilon Value'};
            app.EpsilonSpinner.Value = 45;
            app.MinPtsLabel = uilabel(app.DBSCAN);
            app.MinPtsLabel.Text = 'MinPts';
            app.MinPtsSpinner = uispinner(app.DBSCAN);
            app.MinPtsSpinner.ValueChangedFcn = createCallbackFcn(app, @MinPtsSpinnerValueChanged, true);
            app.MinPtsSpinner.Tooltip = {'DBSCAN MinPts Value'};
            app.MinPtsSpinner.Value = 5;
            app.FileNameLabel1 = uilabel(app.DBSCAN);
            app.FileNameLabel1.Text = '';
            app.TabGroup = uitabgroup(app.DBSCAN);
            app.FieldofViewTab = uitab(app.TabGroup);
            app.FieldofViewTab.Tooltip = {'FIELD OF FIEW after DBSCAN. Adjust parameters to refine results.'};
            app.FieldofViewTab.Title = 'Field of View';
            app.UIAxes2 = uiaxes(app.FieldofViewTab);
            app.ShowClustersOnlyCheckbox = uibutton(app.FieldofViewTab, 'state');
            app.ShowClustersOnlyCheckbox.ValueChangedFcn = createCallbackFcn(app, @ShowClustersOnlyCheckboxValueChanged, true);
            app.ShowClustersOnlyCheckbox.Text = {'Remove'; 'Noise'};
            app.MainChannelFieldofViewLabel = uilabel(app.FieldofViewTab);
            app.MainChannelFieldofViewLabel.Text = 'Main Channel: Field of View';
            app.SingleClusterAnalysisTab = uitab(app.TabGroup);
            app.SingleClusterAnalysisTab.Tooltip = {'Inspect and analyze SINGLE CLUSTERS from one channel. Optionally fit ellipsoids and view heatmaps.'};
            app.SingleClusterAnalysisTab.Title = 'Single Cluster Analysis';
            app.UIAxes5 = uiaxes(app.SingleClusterAnalysisTab);
            app.ListBox = uilistbox(app.SingleClusterAnalysisTab);
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Item 1'};
            app.EllipsoidFitCheckBox = uicheckbox(app.SingleClusterAnalysisTab);
            app.EllipsoidFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @EllipsoidFitCheckBoxValueChanged, true);
            app.EllipsoidFitCheckBox.Tooltip = {'Fits Ellipsoid over the Point Cloud'};
            app.EllipsoidFitCheckBox.Text = 'Ellipsoid Fit';
            app.ShowHeatMap = uibutton(app.SingleClusterAnalysisTab, 'push');
            app.ShowHeatMap.ButtonPushedFcn = createCallbackFcn(app, @ShowHeatMapPushed, true);
            app.ShowHeatMap.Text = 'Heatmap';
            app.AlphaShapeCheckBox = uicheckbox(app.SingleClusterAnalysisTab);
            app.AlphaShapeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlphaShapeCheckBoxValueChanged, true);
            app.AlphaShapeCheckBox.Tooltip = {'Fits alphsShape over Point Cloud'};
            app.AlphaShapeCheckBox.Text = 'Alpha Shape';
            app.AlphaRadiusSlider = uislider(app.SingleClusterAnalysisTab);
            app.AlphaRadiusSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaRadiusSliderValueChanged, true);
            app.AlphaRadiusSlider.Visible = 'off';
            app.AlphaRadiusSlider.Value = 50;
            app.AlphaRadiusLabel = uilabel(app.SingleClusterAnalysisTab);
            app.AlphaRadiusLabel.Visible = 'off';
            app.AlphaRadiusLabel.Text = '';
            app.SingleAnalysisofClustersfromMainChannelLabel = uilabel(app.SingleClusterAnalysisTab);
            app.SingleAnalysisofClustersfromMainChannelLabel.Text = 'Single Analysis of Clusters from Main Channel';
            app.AlignmentTab = uitab(app.TabGroup);
            app.AlignmentTab.Tooltip = {'Automatically align data and correct drift using bead correction data (from MINFLUX system).'};
            app.AlignmentTab.Title = 'Alignment';
            app.LocalizationAxes = uiaxes(app.AlignmentTab);
            title(app.LocalizationAxes, 'Title')
            xlabel(app.LocalizationAxes, 'X')
            ylabel(app.LocalizationAxes, 'Y')
            zlabel(app.LocalizationAxes, 'Z')
            app.DeviationAxes = uiaxes(app.AlignmentTab);
            title(app.DeviationAxes, 'Title')
            xlabel(app.DeviationAxes, 'X')
            ylabel(app.DeviationAxes, 'Y')
            zlabel(app.DeviationAxes, 'Z')
            app.LoadBeadDataButton = uibutton(app.AlignmentTab, 'push');
            app.LoadBeadDataButton.ButtonPushedFcn = createCallbackFcn(app, @Zarr_extractPushed, true);
            app.LoadBeadDataButton.Tooltip = {'Select the Folder containing Zarr Files - The Data is then processed, loaded into the app, and stored as correction.mat'};
            app.LoadBeadDataButton.Text = 'Load Bead Data';
            app.ReferenceStainingDropDownLabel = uilabel(app.AlignmentTab);
            app.ReferenceStainingDropDownLabel.Text = 'ReferenceStaining';
            app.ReferenceStainingDropDown = uidropdown(app.AlignmentTab);
            app.ReferenceStainingDropDown.Items = {'Channel 1'};
            app.ReferenceStainingDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceStainingDropDownValueChanged, true);
            app.ReferenceStainingDropDown.Tooltip = {'Select the Reference Channel for the multi-channel Alignment'};
            app.ReferenceStainingDropDown.Value = 'Channel 1';
            app.AlignmentandCorrectionFactorLabel = uilabel(app.AlignmentTab);
            app.AlignmentandCorrectionFactorLabel.Text = 'Alignment and Correction Factor';
            app.ZarrLabel = uilabel(app.AlignmentTab);
            app.ZarrLabel.Text = '';
            app.SelecttheParentFolderofZarrFileFoldersLabel = uilabel(app.AlignmentTab);
            app.SelecttheParentFolderofZarrFileFoldersLabel.Text = 'Select the Parent Folder of Zarr File Folders';
            app.RMSE_alignment = uilabel(app.AlignmentTab);
            app.RMSE_alignment.Text = '(RMSE Values)';
            app.FieldofViewMultiTab = uitab(app.TabGroup);
            app.FieldofViewMultiTab.Tooltip = {'View and adjust MULTI-CHANNEL data. Apply x/y/z shifts and color corrections as needed.'};
            app.FieldofViewMultiTab.Title = 'Field of View (Multi)';
            app.FieldofViewMultiTab.ButtonDownFcn = createCallbackFcn(app, @FieldofViewMultiTabButtonDown, true);
            app.UIAxes7 = uiaxes(app.FieldofViewMultiTab);
            app.ExportasmattifButton = uibutton(app.FieldofViewMultiTab, 'push');
            app.ExportasmattifButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasmattifButtonPushed, true);
            app.ExportasmattifButton.Tooltip = {'Export the corrected multi-channel file as .mat data or .tif image file'};
            app.ExportasmattifButton.Text = 'Export as .mat/.tif';
            app.ChannelListBox = uilistbox(app.FieldofViewMultiTab);
            app.ChannelListBox.Items = {'Channel 1', 'Add Channel'};
            app.ChannelListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelListBoxValueChanged, true);
            app.ChannelListBox.Tooltip = {'Select Channel for further adjustments'};
            app.ChannelListBox.Value = 'Channel 1';
            app.ChannelDetailsPanel = uipanel(app.FieldofViewMultiTab);
            app.ZCorrectionField = uieditfield(app.ChannelDetailsPanel, 'numeric');
            app.ZCorrectionField.ValueChangedFcn = createCallbackFcn(app, @ZCorrectionFieldValueChanged, true);
            app.ZCorrectionField.Tooltip = {'Add Shift manually'};
            app.YCorrectionField = uieditfield(app.ChannelDetailsPanel, 'numeric');
            app.YCorrectionField.ValueChangedFcn = createCallbackFcn(app, @YCorrectionFieldValueChanged, true);
            app.YCorrectionField.Tooltip = {'Add Shift manually'};
            app.XCorrectionField = uieditfield(app.ChannelDetailsPanel, 'numeric');
            app.XCorrectionField.ValueChangedFcn = createCallbackFcn(app, @XCorrectionFieldValueChanged, true);
            app.XCorrectionField.Tooltip = {'Add Shift manually'};
            app.xLabel = uilabel(app.ChannelDetailsPanel);
            app.xLabel.Tooltip = {'Add Shift manually'};
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.ChannelDetailsPanel);
            app.yLabel.Tooltip = {'Add Shift manually'};
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.ChannelDetailsPanel);
            app.zLabel.Tooltip = {'Add Shift manually'};
            app.zLabel.Text = 'z';
            app.ChannelEnableCheckbox = uicheckbox(app.ChannelDetailsPanel);
            app.ChannelEnableCheckbox.ValueChangedFcn = createCallbackFcn(app, @ChannelEnableCheckboxValueChanged, true);
            app.ChannelEnableCheckbox.Tooltip = {'Turn selected Channel on/off'};
            app.ChannelEnableCheckbox.Text = 'On/Off';
            app.DBSCANCheckBox = uicheckbox(app.ChannelDetailsPanel);
            app.DBSCANCheckBox.ValueChangedFcn = createCallbackFcn(app, @DBSCANCheckBoxValueChanged, true);
            app.DBSCANCheckBox.Tooltip = {'Turn DBSCAN on/off for selected Data'};
            app.DBSCANCheckBox.Text = 'DBSCAN';
            app.DBSCANCheckBox.Value = true;
            app.ChannelFileLabel = uilabel(app.ChannelDetailsPanel);
            app.ChannelFileLabel.Text = '';
            app.ChannelNameLabel = uilabel(app.ChannelDetailsPanel);
            app.ChannelNameLabel.Text = '';
            app.LoadChannelButton = uibutton(app.FieldofViewMultiTab, 'push');
            app.LoadChannelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadChannelButtonPushed, true);
            app.LoadChannelButton.Tooltip = {'Add additional Channels or Reload an existing Channel'};
            app.LoadChannelButton.Text = 'Add/Reload Channel';
            app.FieldofViewforMainDataandAdditionalChannelsLabel = uilabel(app.FieldofViewMultiTab);
            app.FieldofViewforMainDataandAdditionalChannelsLabel.Text = 'Field of View for Main Data and Additional Channels';
            app.DBSCANparametersareappliedtoallchannelsLabel = uilabel(app.FieldofViewMultiTab);
            app.DBSCANparametersareappliedtoallchannelsLabel.Text = '(DBSCAN parameters are applied to all channels)';
            app.ClusterAnalysisMultiTab = uitab(app.TabGroup);
            app.ClusterAnalysisMultiTab.Tooltip = {'Compare and analyze SINGLE CLUSTER data across multiple channels.'};
            app.ClusterAnalysisMultiTab.Title = 'Cluster Analysis (Multi)';
            app.ClusterAnalysisMultiTab.ButtonDownFcn = createCallbackFcn(app, @ClusterAnalysisMultiTabButtonDown, true);
            app.UIAxes5_2 = uiaxes(app.ClusterAnalysisMultiTab);
            app.ListBox_2 = uilistbox(app.ClusterAnalysisMultiTab);
            app.ListBox_2.Items = {'Item 1', 'Item 2', 'Item 3'};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged2, true);
            app.ListBox_2.Tooltip = {'Select multiple Clusters together to generate Overlay'};
            app.ListBox_2.Value = {'Item 1'};
            app.AlphaShapeCheckBox_2 = uicheckbox(app.ClusterAnalysisMultiTab);
            app.AlphaShapeCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AlphaShapeCheckBox_2ValueChanged, true);
            app.AlphaShapeCheckBox_2.Tooltip = {'Click here for Fitting the alphaShape function over your Point Cloud'};
            app.AlphaShapeCheckBox_2.Text = 'Alpha Shape';
            app.EllipsoidFitCheckBox_2 = uicheckbox(app.ClusterAnalysisMultiTab);
            app.EllipsoidFitCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EllipsoidFitCheckBox_2ValueChanged, true);
            app.EllipsoidFitCheckBox_2.Tooltip = {'Click here for Fitting an Ellipsoid over the Point Cloud'};
            app.EllipsoidFitCheckBox_2.Text = 'Ellipsoid Fit';
            app.AlphaRadiusSlider_2 = uislider(app.ClusterAnalysisMultiTab);
            app.AlphaRadiusSlider_2.ValueChangedFcn = createCallbackFcn(app, @AlphaRadiusSlider_2ValueChanged, true);
            app.AlphaRadiusSlider_2.Visible = 'off';
            app.AlphaRadiusSlider_2.Tooltip = {'Adjust AlphaRadius'};
            app.AlphaRadiusSlider_2.Value = 50;
            app.AlphaRadiusLabel_2 = uilabel(app.ClusterAnalysisMultiTab);
            app.AlphaRadiusLabel_2.Visible = 'off';
            app.AlphaRadiusLabel_2.Text = '';
            app.Ch2CheckBox = uicheckbox(app.ClusterAnalysisMultiTab);
            app.Ch2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch2CheckBoxValueChanged, true);
            app.Ch2CheckBox.Visible = 'off';
            app.Ch2CheckBox.Text = 'Ch. 2';
            app.Ch3CheckBox = uicheckbox(app.ClusterAnalysisMultiTab);
            app.Ch3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch3CheckBoxValueChanged, true);
            app.Ch3CheckBox.Visible = 'off';
            app.Ch3CheckBox.Text = 'Ch. 3';
            app.Ch4CheckBox = uicheckbox(app.ClusterAnalysisMultiTab);
            app.Ch4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch4CheckBoxValueChanged, true);
            app.Ch4CheckBox.Visible = 'off';
            app.Ch4CheckBox.Text = 'Ch. 4';
            app.Ch5CheckBox = uicheckbox(app.ClusterAnalysisMultiTab);
            app.Ch5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch5CheckBoxValueChanged, true);
            app.Ch5CheckBox.Visible = 'off';
            app.Ch5CheckBox.Text = 'Ch. 5';
            app.SingleClusterAnalysisMultiChannelLabel = uilabel(app.ClusterAnalysisMultiTab);
            app.SingleClusterAnalysisMultiChannelLabel.Text = 'Single Cluster Analysis (Multi-Channel)';
            app.AnalyzesingleclustersinmultiplecolorsLabel = uilabel(app.ClusterAnalysisMultiTab);
            app.AnalyzesingleclustersinmultiplecolorsLabel.Text = 'Analyze single clusters in multiple colors';
            app.ChannelsonoffLabel = uilabel(app.ClusterAnalysisMultiTab);
            app.ChannelsonoffLabel.Text = {'Channels'; 'on/off:'};
            app.ConcentricalAnalysisTab = uitab(app.TabGroup);
            app.ConcentricalAnalysisTab.Tooltip = {'Generate line profiles and distance histograms to analyze spatial distribution.'};
            app.ConcentricalAnalysisTab.Title = 'Concentrical Analysis';
            app.UIAxes6 = uiaxes(app.ConcentricalAnalysisTab);
            app.MeanDistLabel = uilabel(app.ConcentricalAnalysisTab);
            app.MeanDistLabel.Text = '';
            app.ButtonGroup1 = uibuttongroup(app.ConcentricalAnalysisTab);
            app.ButtonGroup1.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup1SelectionChanged, true);
            app.locButton1 = uitogglebutton(app.ButtonGroup1);
            app.locButton1.Tooltip = {'Switch to Localizations'};
            app.locButton1.Text = 'Loc';
            app.locButton1.Value = true;
            app.trcButton1 = uitogglebutton(app.ButtonGroup1);
            app.trcButton1.Tooltip = {'Switch to Traces'};
            app.trcButton1.Text = 'Trc';
            app.ListBox2 = uilistbox(app.ConcentricalAnalysisTab);
            app.ListBox2.Multiselect = 'on';
            app.ListBox2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox2.Value = {'Item 1'};
            app.NormalizeIndividuallyCheckBox = uicheckbox(app.ConcentricalAnalysisTab);
            app.NormalizeIndividuallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeIndividuallyCheckBoxValueChanged, true);
            app.NormalizeIndividuallyCheckBox.Tooltip = {'Normalize Intensity to the Highest Signal'};
            app.NormalizeIndividuallyCheckBox.Text = 'Channel Norm';
            app.ConcentricalAnalysisLabel = uilabel(app.ConcentricalAnalysisTab);
            app.ConcentricalAnalysisLabel.Text = 'Concentrical Analysis';
            app.HistogramofMainDataandLineProfilesfromaddChannelsLabel = uilabel(app.ConcentricalAnalysisTab);
            app.HistogramofMainDataandLineProfilesfromaddChannelsLabel.Text = 'Histogram of Main Data and Line Profiles from add. Channels';
            app.PolarPlotTab = uitab(app.TabGroup);
            app.PolarPlotTab.Tooltip = {'Visualize polar plots to measure radial distribution from cluster centroids.'};
            app.PolarPlotTab.Title = 'Polar Plot';
            app.ButtonGroup = uibuttongroup(app.PolarPlotTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.locButton = uitogglebutton(app.ButtonGroup);
            app.locButton.Tooltip = {'Switch to Localizations'};
            app.locButton.Text = 'Loc';
            app.locButton.Value = true;
            app.trcButton = uitogglebutton(app.ButtonGroup);
            app.trcButton.Tooltip = {'Switch to Traces'};
            app.trcButton.Text = 'Trc';
            app.ListBox2_2 = uilistbox(app.PolarPlotTab);
            app.ListBox2_2.Multiselect = 'on';
            app.ListBox2_2.ValueChangedFcn = createCallbackFcn(app, @ListBox2_2ValueChanged, true);
            app.ListBox2_2.Value = {'Item 1'};
            app.Panel = uipanel(app.PolarPlotTab);
            app.ColorCheck = uicheckbox(app.PolarPlotTab);
            app.ColorCheck.ValueChangedFcn = createCallbackFcn(app, @ColorCheckValueChanged, true);
            app.ColorCheck.Tooltip = {'Adjust for a better Contrast'};
            app.ColorCheck.Text = 'Contrast';
            app.PolarPlotLabel = uilabel(app.PolarPlotTab);
            app.PolarPlotLabel.Text = 'Polar Plot';
            app.MeanDistLabel2 = uilabel(app.PolarPlotTab);
            app.MeanDistLabel2.Text = '';
            app.PolarPlotofMainDataandAdditionalChannelsLabel = uilabel(app.PolarPlotTab);
            app.PolarPlotofMainDataandAdditionalChannelsLabel.Text = 'Polar Plot of Main Data and Additional Channels';
            app.NumbersTab = uitab(app.TabGroup);
            app.NumbersTab.Tooltip = {'View numerical summaries of clusters (volume, surface area, etc.).'};
            app.NumbersTab.Title = 'Numbers';
            app.NumbersTab.ButtonDownFcn = createCallbackFcn(app, @NumbersTabButtonDown, true);
            app.UITable = uitable(app.NumbersTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'};
            app.UITable.RowName = {};
            app.SingleClustersinNumbersLabel = uilabel(app.NumbersTab);
            app.SingleClustersinNumbersLabel.Text = 'Single Clusters in Numbers';
            app.NumbersmultiTab = uitab(app.TabGroup);
            app.NumbersmultiTab.Tooltip = {'Summarize localization counts and statistics across channels.'};
            app.NumbersmultiTab.Title = 'Numbers (multi)';
            app.NumbersmultiTab.ButtonDownFcn = createCallbackFcn(app, @NumbersmultiTabButtonDown, true);
            app.UITableMulti = uitable(app.NumbersmultiTab);
            app.UITableMulti.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'};
            app.UITableMulti.RowName = {};
            app.SaveNumMultCSVButton = uibutton(app.NumbersmultiTab, 'push');
            app.SaveNumMultCSVButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNumMultCSVButtonPushed, true);
            app.SaveNumMultCSVButton.Tooltip = {'Table is saved as .csv'};
            app.SaveNumMultCSVButton.Text = 'Save Table';
            app.SingleClustersinNumbersmultichannelLabel = uilabel(app.NumbersmultiTab);
            app.SingleClustersinNumbersmultichannelLabel.Text = 'Single Clusters in Numbers (multi-channel)';
            app.AcqParametersTab = uitab(app.TabGroup);
            app.AcqParametersTab.Tooltip = {'Assess data quality with trace counts and temporal localization histograms.'};
            app.AcqParametersTab.Title = 'Acq. Parameters';
            app.AcqParametersTab.ButtonDownFcn = createCallbackFcn(app, @AcqParametersTabButtonDown, true);
            app.UIAxes3 = uiaxes(app.AcqParametersTab);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.AcqParametersTab);
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.Label_2 = uilabel(app.AcqParametersTab);
            app.Label_2.Text = '';
            app.LocalizationsperTraceLabel = uilabel(app.AcqParametersTab);
            app.LocalizationsperTraceLabel.Text = {'Localizations '; 'per Trace'};
            app.LocalizationsovertimeLabel = uilabel(app.AcqParametersTab);
            app.LocalizationsovertimeLabel.Text = {'Localizations '; 'over time'};
            app.MINFLUXAcquisitionParametersLabel = uilabel(app.AcqParametersTab);
            app.MINFLUXAcquisitionParametersLabel.Text = 'MINFLUX Acquisition Parameters';
            app.LocalizationPrecisionTab = uitab(app.TabGroup);
            app.LocalizationPrecisionTab.Tooltip = {'Calculate localization precision via standard deviations and error estimates.'};
            app.LocalizationPrecisionTab.Title = 'Localization Precision';
            app.LocalizationPrecisionTab.ButtonDownFcn = createCallbackFcn(app, @LocalizationPrecisionTabButtonDown, true);
            app.SaveLocPrecPDFButton = uibutton(app.LocalizationPrecisionTab, 'push');
            app.SaveLocPrecPDFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLocPrecPDFButtonPushed2, true);
            app.SaveLocPrecPDFButton.Tooltip = {'All displayed plots are saved as .png'};
            app.SaveLocPrecPDFButton.Text = 'Save as .png';
            app.LocalizationPrecisionofLoadedDataLabel = uilabel(app.LocalizationPrecisionTab);
            app.LocalizationPrecisionofLoadedDataLabel.Text = 'Localization Precision of Loaded Data';
            app.EnableExclusion = uicheckbox(app.DBSCAN);
            app.EnableExclusion.ValueChangedFcn = createCallbackFcn(app, @EnableExclusionValueChanged, true);
            app.EnableExclusion.Text = '';
            app.ExclNum = uispinner(app.DBSCAN);
            app.ExclNum.ValueChangedFcn = createCallbackFcn(app, @ExclNumValueChanged2, true);
            app.ExclNum.Tooltip = {'If active, only Clusters above this Value are displayed'};
            app.Label = uilabel(app.DBSCAN);
            app.Label.Text = '>=';
            app.Button = uibutton(app.DBSCAN, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'camera.png');
            app.Button.Tooltip = {'Camera Button allows you to save every generated Plot as PNG or PDF'};
            app.Button.Text = '';
            % Show the figure after all components are created
            app.DBSCAN.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matFLUX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DBSCAN)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DBSCAN)
        end
    end
end